classdef ZmapSettings < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ZMAPPreferencesUIFigure         matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        GeneralTab                      matlab.ui.container.Tab
        DirectoriesPanel                matlab.ui.container.Panel
        outputEditFieldLabel            matlab.ui.control.Label
        outputEditField                 matlab.ui.control.EditField
        dataEditFieldLabel              matlab.ui.control.Label
        dataEditField                   matlab.ui.control.EditField
        workingEditFieldLabel           matlab.ui.control.Label
        workingEditField                matlab.ui.control.EditField
        BrowseOutputdirButton           matlab.ui.control.Button
        BrowseDatadirButton             matlab.ui.control.Button
        BrowseWorkingdirButton          matlab.ui.control.Button
        ZMAPEditFieldLabel              matlab.ui.control.Label
        ZMAPdirEditField                matlab.ui.control.EditField
        ParallelProcessingPanel         matlab.ui.container.Panel
        EnableParallelProcessingCheckBox  matlab.ui.control.CheckBox
        ParallelProcessingThreshholdEditFieldLabel  matlab.ui.control.Label
        ParallelProcessingThreshholdEditField  matlab.ui.control.NumericEditField
        GeneralDefaultsLabel            matlab.ui.control.Label
        DebuggingPanel                  matlab.ui.container.Panel
        DebugModeCheckBox               matlab.ui.control.CheckBox
        LevelSpinnerLabel               matlab.ui.control.Label
        DebugLevelSpinner               matlab.ui.control.Spinner
        CrossSectionsTab                matlab.ui.container.Tab
        CrossSectionDefaultsLabel       matlab.ui.control.Label
        CrossSectionsPanel              matlab.ui.container.Panel
        XsectionWidthkmEditFieldLabel   matlab.ui.control.Label
        XsecWidthEditField              matlab.ui.control.NumericEditField
        ColorDropDownLabel              matlab.ui.control.Label
        XsecColorDropDown               matlab.ui.control.DropDown
        LineWidthEditFieldLabel         matlab.ui.control.Label
        XsecLineWidthEditField          matlab.ui.control.NumericEditField
        LineStyleDropDownLabel          matlab.ui.control.Label
        XsecLineStyleDropDown           matlab.ui.control.DropDown
        EndMarkerDropDownLabel          matlab.ui.control.Label
        XsecEndMarkerDropDown           matlab.ui.control.DropDown
        MarkerSizeEditFieldLabel        matlab.ui.control.Label
        XsecMarkerSizeEditField         matlab.ui.control.NumericEditField
        LabelsPanel                     matlab.ui.container.Panel
        FontSizeEditFieldLabel          matlab.ui.control.Label
        XsecLabelFontSizeEditField      matlab.ui.control.NumericEditField
        BackgroundColorDropDownLabel    matlab.ui.control.Label
        XsecLabelBackgroundColorDropDown  matlab.ui.control.DropDown
        XsecLabelOutlineCheckBox        matlab.ui.control.CheckBox
        XsecLabelBoldCheckBox           matlab.ui.control.CheckBox
        BorderPanel                     matlab.ui.container.Panel
        LineStyleDropDown_2Label        matlab.ui.control.Label
        XsecBorderLineStyleDropDown     matlab.ui.control.DropDown
        LineWidthEditField_2Label       matlab.ui.control.Label
        XsecBorderLineWidthEditField    matlab.ui.control.NumericEditField
        TabPanel                        matlab.ui.container.Panel
        ColorDropDown_2Label            matlab.ui.control.Label
        XsecTabColorDropDown            matlab.ui.control.DropDown
        CatalogsTab                     matlab.ui.container.Tab
        ReopenLastCatalogCheckBox       matlab.ui.control.CheckBox
        LastCatalogFilenameEditFieldLabel  matlab.ui.control.Label
        LastCatalogFilenameEditField    matlab.ui.control.EditField
        relativetoworkingdirectoryLabel  matlab.ui.control.Label
        BigEventCatalogPanel            matlab.ui.container.Panel
        BigEventThreshholdPerCatalogCheckBox  matlab.ui.control.CheckBox
        MinMagnitudeThreshholdEditFieldLabel  matlab.ui.control.Label
        BigEventMinMagThreshholdEditField  matlab.ui.control.NumericEditField
        GridTab                         matlab.ui.container.Tab
        GridAnchorPointPanel            matlab.ui.container.Panel
        XorLonEditFieldLabel            matlab.ui.control.Label
        GridXAnchor                     matlab.ui.control.NumericEditField
        YorLatEditFieldLabel            matlab.ui.control.Label
        GridYAnchor                     matlab.ui.control.NumericEditField
        ZdownEditFieldLabel             matlab.ui.control.Label
        GridZAnchor                     matlab.ui.control.NumericEditField
        GridSeparationPanel             matlab.ui.container.Panel
        dxEditFieldLabel                matlab.ui.control.Label
        Griddx                          matlab.ui.control.NumericEditField
        dyEditFieldLabel                matlab.ui.control.Label
        Griddy                          matlab.ui.control.NumericEditField
        dzkmLabel                       matlab.ui.control.Label
        Griddz                          matlab.ui.control.NumericEditField
        UnitsDropDownLabel              matlab.ui.control.Label
        GridUnitsDropDown               matlab.ui.control.DropDown
        GridFollowMeridiansCheckBox     matlab.ui.control.CheckBox
        GridOKButton                    matlab.ui.control.Button
        GridLoadButton                  matlab.ui.control.Button
        GridSaveOKButton                matlab.ui.control.Button
        GridNameEditFieldLabel          matlab.ui.control.Label
        GridNameEditField               matlab.ui.control.EditField
        GridDefaultsLabel               matlab.ui.control.Label
        GridAppearencePanel             matlab.ui.container.Panel
        ColorDropDownLabel_2            matlab.ui.control.Label
        GridColorDropDown               matlab.ui.control.DropDown
        MarkerLabel                     matlab.ui.control.Label
        GridMarkerDropDown              matlab.ui.control.DropDown
        LineWidthEditFieldLabel_2       matlab.ui.control.Label
        GridLineWidthEditField          matlab.ui.control.NumericEditField
        MarkerSizeEditFieldLabel_2      matlab.ui.control.Label
        GridMarkerSizeEditField         matlab.ui.control.NumericEditField
        UseFixedAnchorPointCheckBox     matlab.ui.control.CheckBox
        UseAutogriduponloadingacatalogCheckBox  matlab.ui.control.CheckBox
        MainMapTab                      matlab.ui.container.Tab
        MainTabGroup                    matlab.ui.container.TabGroup
        DisplayOptionsTab               matlab.ui.container.Tab
        MainMapSetAspectRatioByLatitudeCheckBox  matlab.ui.control.CheckBox
        MainMapShowLegendCheckBox       matlab.ui.control.CheckBox
        MainMapShowLatLonGridCheckBox   matlab.ui.control.CheckBox
        MainMapBigEventsPanel           matlab.ui.container.Panel
        MarkerEdgeColorLabel_2          matlab.ui.control.Label
        BigEventMarkerEdgeColorDropDown  matlab.ui.control.DropDown
        MarkerLabel_3                   matlab.ui.control.Label
        BigEventMarkerDropDown          matlab.ui.control.DropDown
        LineWidthEditFieldLabel_3       matlab.ui.control.Label
        BigEventLineWidthEditField      matlab.ui.control.NumericEditField
        MarkerSizeEditFieldLabel_3      matlab.ui.control.Label
        BigEventMarkerSizeEditField     matlab.ui.control.NumericEditField
        MarkerFaceColorLabel_2          matlab.ui.control.Label
        BigEventMarkerFaceColorDropDown  matlab.ui.control.DropDown
        BigEventUseSizeFunctionCheckBox  matlab.ui.control.CheckBox
        MainMapNormalEventsPanel        matlab.ui.container.Panel
        MarkerFaceColorLabel            matlab.ui.control.Label
        EventsMarkerFaceColorDropDown   matlab.ui.control.DropDown
        MarkerLabel_4                   matlab.ui.control.Label
        EventsMarkerDropDown            matlab.ui.control.DropDown
        LineWidthEditFieldLabel_4       matlab.ui.control.Label
        EventsLineWidthEditField        matlab.ui.control.NumericEditField
        MarkerEdgeColorLabel            matlab.ui.control.Label
        EventsMarkerEdgeColorDropDown   matlab.ui.control.DropDown
        ColorByLabel                    matlab.ui.control.Label
        EventsColorByDropDown           matlab.ui.control.DropDown
        EventsDotsForSmallCheckBox      matlab.ui.control.CheckBox
        MagEditFieldLabel               matlab.ui.control.Label
        EventsSmallMagThreshholdEditField  matlab.ui.control.NumericEditField
        MarkerSizeFunctionEditFieldLabel  matlab.ui.control.Label
        EventsMarkerSizeFunctionEditField  matlab.ui.control.EditField
        EventsDotsForTooManyCheckBox    matlab.ui.control.CheckBox
        EventsTooManyForMarkerEditField  matlab.ui.control.NumericEditField
        eventsLabel                     matlab.ui.control.Label
        MainMapEventsUIAxes             matlab.ui.control.UIAxes
        UnselectedEventsPanel           matlab.ui.container.Panel
        MarkerEdgeColorLabel_3          matlab.ui.control.Label
        UnselectedEventsMarkerEdgeColorDropDown  matlab.ui.control.DropDown
        MarkerFaceColorLabel_3          matlab.ui.control.Label
        UnselectedEventsMarkerFaceColorDropDown  matlab.ui.control.DropDown
        MarkerSizeEditFieldLabel_4      matlab.ui.control.Label
        UnselectedEventsMarkerSizeEditField  matlab.ui.control.NumericEditField
        LineWidthEditFieldLabel_5       matlab.ui.control.Label
        UnselectedEventsLineWidthEditField  matlab.ui.control.NumericEditField
        MarkerLabel_5                   matlab.ui.control.Label
        UnselectedEventsMarkerDropDown  matlab.ui.control.DropDown
        FeaturesTab                     matlab.ui.container.Tab
        FeaturesListBoxLabel            matlab.ui.control.Label
        FeaturesListBox                 matlab.ui.control.ListBox
        Panel                           matlab.ui.container.Panel
        FeatureUseCheckBox              matlab.ui.control.CheckBox
        FeatureIDLabel                  matlab.ui.control.Label
        DetailLevelDropDownLabel        matlab.ui.control.Label
        FeatureDetailLevelDropDown      matlab.ui.control.DropDown
        FeatureHitTestCheckBox          matlab.ui.control.CheckBox
        FeatureLinePanel                matlab.ui.container.Panel
        WidthEditFieldLabel             matlab.ui.control.Label
        FeatureLineWidthEditField       matlab.ui.control.NumericEditField
        ColorEditFieldLabel             matlab.ui.control.Label
        FeatureLineColorEditField       matlab.ui.control.EditField
        StyleLabel                      matlab.ui.control.Label
        FeatureLineStyleDropDown        matlab.ui.control.DropDown
        FeatureMarkerPanel              matlab.ui.container.Panel
        MarkerLabel_2                   matlab.ui.control.Label
        FeatureMarkerDropDown           matlab.ui.control.DropDown
        SizeLabel                       matlab.ui.control.Label
        FeatureMarkerSizeEditField      matlab.ui.control.NumericEditField
        FaceColorEditFieldLabel         matlab.ui.control.Label
        FeatureFaceColorEditField       matlab.ui.control.EditField
        EdgeColorEditFieldLabel         matlab.ui.control.Label
        FeatureEdgeColorEditField       matlab.ui.control.EditField
        TagEditFieldLabel               matlab.ui.control.Label
        FeatureTagEditField             matlab.ui.control.EditField
        PickablePartsDropDownLabel      matlab.ui.control.Label
        FeaturePickablePartsDropDown    matlab.ui.control.DropDown
        DisplayNameEditFieldLabel       matlab.ui.control.Label
        FeatureDisplayNameEditField     matlab.ui.control.EditField
        FeatureUIAxes                   matlab.ui.control.UIAxes
        FeaturesthatareUsedTextAreaLabel  matlab.ui.control.Label
        FeaturesthatareUsedTextArea     matlab.ui.control.TextArea
        SaveFeatureButton               matlab.ui.control.Button
        Label                           matlab.ui.control.Label
        ResultsTab                      matlab.ui.container.Tab
        NumberofResultContoursLabel     matlab.ui.control.Label
        ResultContourCountEditField     matlab.ui.control.NumericEditField
        ShowSampleRadiusCheckBox        matlab.ui.control.CheckBox
        SamplingTab                     matlab.ui.container.Tab
        SampleByButtonGroup             matlab.ui.container.ButtonGroup
        Sampling_UseClosestRadioButton  matlab.ui.control.RadioButton
        Sampling_UseRadiusRadioButton   matlab.ui.control.RadioButton
        Sampling_UseBothRadioButton     matlab.ui.control.RadioButton
        ofEventsNEditFieldLabel         matlab.ui.control.Label
        SamplingMaxEventsEditField      matlab.ui.control.NumericEditField
        MaximumRadiuskmLabel            matlab.ui.control.Label
        SampleRadiusEditField           matlab.ui.control.NumericEditField
        SamplingDefaultsLabel           matlab.ui.control.Label
        SamplingWeightByDistanceCheckBox  matlab.ui.control.CheckBox
        SamplingUIAxes                  matlab.ui.control.UIAxes
        SaveAllButton                   matlab.ui.control.Button
        RevertTabButton                 matlab.ui.control.Button
        SaveTabButton                   matlab.ui.control.Button
        CancelButton                    matlab.ui.control.Button
    end

    
    properties (Access = private)
        % DefaultXsec = defaults.readDefaults('cross_section_defaults') % Values for Cross Sections from disk
        ModifiedFieldBackgroundColor = [1.0 1.0 0.9]; % Color for fields that have been changed
        FeatureProperties             containers.Map % holds currently set Feature Properties
        OriginalFeatureProperties     containers.Map % holds unaltered Feature Properties
        FeatureMappings = {... from, to
                'DisplayName', 'FeatureDisplayNameEditField';...
                'Tag', 'FeatureTagEditField';...
                'LineWidth', 'FeatureLineWidthEditField';...
                'LineStyle', 'FeatureLineStyleDropDown';...
                'Color', 'FeatureLineColorEditField';...
                'Marker', 'FeatureMarkerDropDown';...
                'MarkerSize', 'FeatureMarkerSizeEditField';...
                'MarkerFaceColor', 'FeatureFaceColorEditField';...
                'MarkerEdgeColor', 'FeatureEdgeColorEditField';...
                'PickableParts', 'FeaturePickablePartsDropDown';...
                'HitTest', 'FeatureHitTestCheckBox';...
                'UseMe', 'FeatureUseCheckBox'
                };% Maps between the feature field and the property field
    end
    
    methods (Access = private)
        
        function results = saveDefaults(app, name)
            results=struct();
            switch name
                case 'ALL'
                    names = {app.TabGroup.Children.UserData};
                    for n=1:numel(names)
                        saveDefaults(app,names{n});
                    end
                    return;
                case 'catalog_defaults'
                    results.ReopenLastCatalog = app.ReopenLastCatalogCheckBox.Value;
                    results.LastCatalogFilename = app.LastCatalogFilenameEditField.Value;
                    bigev = struct();
                    bigev.ThreshholdChangesWithCatalog = app.BigEventThreshholdPerCatalogCheckBox.Value;
                    bigev.MinMag = app.BigEventMinMagThreshholdEditField.Value;
                    results.BigEvents = bigev;
                    
                case 'cross_section_defaults'
                    results.WidthKm = app.XsecWidthEditField.Value;
                    results.LineProps = struct(...
                        'Marker', app.XsecEndMarkerDropDown.Value,...
                        'LineWidth', app.XsecLineWidthEditField.Value,...
                        'LineStyle', app.XsecLineStyleDropDown.Value,...
                        'Color',app.XsecColorDropDown.Value,...
                        'MarkerSize', app.XsecMarkerSizeEditField.Value...
                        );
                    results.LabelProps = struct('FontWeight',app.checkbox2option(app.XsecLabelBoldCheckBox,'bold','normal'),...
                        'FontSize',app.XsecLabelFontSizeEditField.Value,...
                        'BackgroundColor',app.XsecLabelBackgroundColorDropDown.Value,...
                        'EdgeColor',app.checkbox2option(app.XsecLabelOutlineCheckBox,app.XsecColorDropDown.Value,'none')...
                        );
                    results.TabProps = struct(...
                        'Color',app.XsecTabColorDropDown.Value...
                        );
                    results.BorderProps = struct(...
                        'LineStyle',app.XsecBorderLineStyleDropDown.Value,...
                        'LineWidth',app.XsecBorderLineWidthEditField.Value...
                        );
                    
                case 'general_defaults'
                    % save directories
                    results.Directories = struct(...
                        'output',app.outputEditField.Value,...
                        'data',app.dataEditField.Value,...
                        'working',app.workingEditField.Value...
                        );
                    
                    % save parallel processing options
                    results.ParallelProcessing = struct(...
                    'Enable',app.EnableParallelProcessingCheckBox.Value,...
                    'Threshhold', app.ParallelProcessingThreshholdEditField.Value...
                    );
                
                    % save debug mode
                    results.DebugMode = app.DebugModeCheckBox.Value;
                    results.DebugLevel = app.DebugModeCheckBox.Value;
                    
                    
                case 'grid_defaults'
                    results.LineProps = struct(...
                        'Marker', app.GridMarkerDropDown.Value,...
                        'LineWidth', app.GridLineWidthEditField.Value,...
                        'Color', app.GridColorDropDown.Value,...
                        'MarkerSize', app.GridMarkerSizeEditField.Value);
                    results.SeparationProps = struct(...
                        'AutomaticGridCalculation', app.UseAutogriduponloadingacatalogCheckBox.Value,...
                        'Dx', app.Griddx.Value, 'Dy', app.Griddy.Value, 'Dz', app.Griddz.Value,...
                        'xyunits', app.GridUnitsDropDown.Value,...
                        'FollowMeridians',app.GridFollowMeridiansCheckBox.Value...
                        );
                    results.AnchorPoint = struct(...
                        'UseFixedAnchorPoint',app.UseFixedAnchorPointCheckBox.Value,...
                        'XAnchor', app.GridXAnchor.Value, 'YAnchor', app.GridYAnchor.Value, 'ZAnchor', app.GridZAnchor.Value...
                        );
                    results.Name = app.GridNameEditField.Value;
                    
                case 'mainmap_defaults'
                    % from display options tab
                    results.AspectRatioByLatitude = app.MainMapSetAspectRatioByLatitudeCheckBox.Value;
                    results.ShowLegend = app.MainMapShowLegendCheckBox.Value;
                    results.ShowLatLonGrid = app.MainMapShowLatLonGridCheckBox.Value;
                    
                    mainev = struct();
                    mainev.LineWidth = app.EventsLineWidthEditField.Value;
                    mainev.Marker = app.EventsMarkerDropDown.Value;
                    mainev.MarkerEdgeColor = app.EventsMarkerEdgeColorDropDown.Value;
                    mainev.MarkerFaceColor = app.EventsMarkerFaceColorDropDown.Value;
                    mainev.ColorBy = app.EventsColorByDropDown.Value;
                    mainev.UseDotsForSmallEvents = app.EventsDotsForSmallCheckBox.Value;
                    mainev.SmallEventThreshhold = app.EventsSmallMagThreshholdEditField.Value;
                    mainev.MarkerSizeFcn = app.EventsMarkerSizeFunctionEditField.Value;
                    mainev.UseDotsForTooManyEvents = app.EventsDotsForTooManyCheckBox.Value;
                    mainev.HowManyAreTooMany = app.EventsTooManyForMarkerEditField.Value;
                    results.MainEvents = mainev;
                    
                    
                    unselect = struct();
                    unselect.LineWidth = app.UnselectedEventsLineWidthEditField.Value;
                    unselect.Marker = app.UnselectedEventsMarkerDropDown.Value;
                    unselect.MarkerEdgeColor = app.UnselectedEventsMarkerEdgeColorDropDown.Value;
                    unselect.MarkerFaceColor = app.UnselectedEventsMarkerFaceColorDropDown.Value;
                    unselect.MarkerSize = app.UnselectedEventsMarkerSizeEditField.Value;
                    results.UnselectedEvents = unselect;
                    
                    bigev = struct();
                    bigev.LineWidth = app.BigEventLineWidthEditField.Value;
                    bigev.Marker = app.BigEventMarkerDropDown.Value;
                    bigev.MarkerEdgeColor = app.BigEventMarkerEdgeColorDropDown.Value;
                    bigev.MarkerFaceColor = app.BigEventMarkerFaceColorDropDown.Value;
                    bigev.MarkerSize = app.BigEventMarkerSizeEditField.Value;
                    bigev.UseMainEventSizeFunction = app.BigEventUseSizeFunctionCheckBox.Value;
                    results.BigEvents = bigev;
                    
                    results.FeatureProperties = app.FeatureProperties;
                case 'one_feature'
                    mymap={...
                        'AspectRatioByLatitude','MainMapSetAspectRatioByLatitudeCheckBox';...
                        'ShowLegend','MainMapShowLegendCheckBox';...
                        'ShowLatLonGrid','MainMapShowLatLonGridCheckBox';...
                        'ColorEventsBy','MainMapColorEventsByDropDown'...
                        };
                    % resave the original state.
                    for i=1:length(mymap)
                        if isempty(app.(mymap{i,2}).UserData)
                            results.(mymap{i,1}) = app.(mymap{i,2}).Value;
                        else
                            results.(mymap{i,1}) = app.(mymap{i,2}).UserData;
                        end
                    end
                    results.FeatureProperties = app.FeatureProperties;
                    name = 'mainmap_defaults';
                    
                case 'result_defaults'
                    results.NumContours = app.ResultContourCountEditField.Value;
                    results.ShowSampleRadius = app.ShowSampleRadiusCheckBox.Value;
                    
                case 'sampling_defaults'
                    results.NumClosestEvents   = app.SamplingMaxEventsEditField.Value;
                    results.UseNumNearbyEvents = app.Sampling_UseBothRadioButton.Value || app.Sampling_UseClosestRadioButton.Value;
                    results.MaxSampleRadius    = app.SampleRadiusEditField.Value;
                    results.UseEventsInRadius  = app.Sampling_UseBothRadioButton.Value || app.Sampling_UseRadiusRadioButton.Value;
                    results.WeightByDistance   = app.SamplingWeightByDistanceCheckBox.Value;
                    results.DistanceUnits      = 'kilometer';
                    if app.Sampling_UseBothRadioButton.Value
                        results.MaxSampleRadius    = app.SampleRadiusEditField.Value;
                    else
                        results.MaxSampleRadius    = inf;
                    end
                    
                    
                otherwise
                    error('Missed a tab. Trying to saveDefaults for %s',name);
            end
            try
                defaults.writeDefaults(name,results);
            catch ME
                disp(['error with writing ' name]);
                disp(results)
                rethrow(ME);
            end
        end
        
        function results = setFromDefaults(app,name)
            disp(name)
            if name == "ALL"
                names = {app.TabGroup.Children.UserData};
                for n=1:numel(names)
                    app.setFromDefaults(names{n});
                end
                return;
            end
            try
                results=defaults.readDefaults(name);
            catch ME
                errordlg(ME.message,sprintf('Unable to load defaults for %s',name));
                return
            end
            switch name
                case 'cross_section_defaults'
                    app.XsecWidthEditField.Value = results.WidthKm;
                    
                    app.XsecEndMarkerDropDown.Value = results.LineProps.Marker;
                    app.XsecLineWidthEditField.Value = results.LineProps.LineWidth;
                    app.XsecLineStyleDropDown.Value = results.LineProps.LineStyle;
                    app.XsecColorDropDown.Value = results.LineProps.Color;
                    app.XsecMarkerSizeEditField.Value = results.LineProps.MarkerSize;
                    
                    app.XsecLabelBoldCheckBox.Value = logical(matlab.lang.OnOffSwitchState(results.LabelProps.FontWeight=="bold"));
                    app.XsecLabelFontSizeEditField.Value = results.LabelProps.FontSize;
                    app.XsecLabelBackgroundColorDropDown.Value = results.LabelProps.BackgroundColor;
                    app.XsecLabelOutlineCheckBox.Value = logical(matlab.lang.OnOffSwitchState(results.LabelProps.EdgeColor~="none"));
                    
                    app.XsecTabColorDropDown.Value = results.TabProps.Color;
                    
                    app.XsecBorderLineStyleDropDown.Value = results.BorderProps.LineStyle;
                    app.XsecBorderLineWidthEditField.Value = results.BorderProps.LineWidth;
                    
                case 'general_defaults'
                    
                    % save directories
                    app.outputEditField.Value = results.Directories.output;
                    app.dataEditField.Value = results.Directories.data;
                    app.workingEditField.Value = results.Directories.working;
                    
                    % save parallel processing options
                    app.EnableParallelProcessingCheckBox.Value = results.ParallelProcessing.Enable;
                    app.ParallelProcessingThreshholdEditField.Value = results.ParallelProcessing.Threshhold;
                
                    % save debug mode
                    app.DebugModeCheckBox.Value = results.DebugMode;
                    app.DebugLevelSpinner.Value = double(results.DebugLevel);
                    
                case 'catalog_defaults'
                    app.ReopenLastCatalogCheckBox.Value = results.ReopenLastCatalog;
                    app.LastCatalogFilenameEditField.Value = results.LastCatalogFilename;
                    bigev = results.BigEvents;
                    app.BigEventThreshholdPerCatalogCheckBox.Value = bigev.ThreshholdChangesWithCatalog;
                    app.BigEventMinMagThreshholdEditField.Value = bigev.MinMag;
                    
                case 'grid_defaults'
                    app.GridMarkerDropDown.Value = results.LineProps.Marker;
                    app.GridLineWidthEditField.Value = results.LineProps.LineWidth;
                    gridcolor = [FancyColors.name(results.LineProps.Color), ' [#', FancyColors.hex(results.LineProps.Color), ']'];
                    if ~any(app.GridColorDropDown.Items == string(gridcolor)) 
                        app.GridColorDropDown.Items = {gridcolor, app.GridColorDropDown.Items{:}};
                    end
                    app.GridColorDropDown.Value = gridcolor;
                    app.GridMarkerSizeEditField.Value = results.LineProps.MarkerSize;
                    
                    app.UseAutogriduponloadingacatalogCheckBox.Value = results.SeparationProps.AutomaticGridCalculation;
                    app.Griddx.Value = results.SeparationProps.Dx;
                    app.Griddy.Value = results.SeparationProps.Dy;
                    app.Griddz.Value = results.SeparationProps.Dz;
                    app.GridUnitsDropDown.Value = results.SeparationProps.xyunits;
                    app.GridFollowMeridiansCheckBox.Value = results.SeparationProps.FollowMeridians;
                    
                    app.UseFixedAnchorPointCheckBox.Value= results.AnchorPoint.UseFixedAnchorPoint;
                    app.GridXAnchor.Value = results.AnchorPoint.XAnchor;
                    app.GridYAnchor.Value = results.AnchorPoint.YAnchor;
                    app.GridZAnchor.Value = results.AnchorPoint.ZAnchor;
                    
                    app.GridNameEditField.Value = results.Name;
                    
                case 'mainmap_defaults'
                    
                    % from display options tab
                    app.MainMapSetAspectRatioByLatitudeCheckBox.Value = results.AspectRatioByLatitude;
                    app.MainMapShowLegendCheckBox.Value = results.ShowLegend;
                    app.MainMapShowLatLonGridCheckBox.Value = results.ShowLatLonGrid;
                    % app.EventsColorByDropDown.Value = results.ColorEventsBy;
                    mainev = results.MainEvents;
                    app.EventsLineWidthEditField.Value = mainev.LineWidth;
                    app.EventsMarkerDropDown.Value = mainev.Marker;
                    app.EventsMarkerEdgeColorDropDown.Value = mainev.MarkerEdgeColor;
                    app.EventsMarkerFaceColorDropDown.Value = mainev.MarkerFaceColor;
                    app.EventsColorByDropDown.Value = mainev.ColorBy;
                    app.EventsDotsForSmallCheckBox.Value = mainev.UseDotsForSmallEvents;
                    app.EventsSmallMagThreshholdEditField.Value = mainev.SmallEventThreshhold;
                    app.EventsMarkerSizeFunctionEditField.Value = mainev.MarkerSizeFcn;
                    app.EventsDotsForTooManyCheckBox.Value = mainev.UseDotsForTooManyEvents;
                    app.EventsTooManyForMarkerEditField.Value = mainev.HowManyAreTooMany;
                    
                    unselect = results.UnselectedEvents;
                    app.UnselectedEventsLineWidthEditField.Value = unselect.LineWidth;
                    app.UnselectedEventsMarkerDropDown.Value = unselect.Marker;
                    app.UnselectedEventsMarkerEdgeColorDropDown.Value = unselect.MarkerEdgeColor;
                    app.UnselectedEventsMarkerFaceColorDropDown.Value = unselect.MarkerFaceColor;
                    app.UnselectedEventsMarkerSizeEditField.Value = unselect.MarkerSize;
                    
                    bigev = results.BigEvents;
                    app.BigEventLineWidthEditField.Value = bigev.LineWidth;
                    app.BigEventMarkerDropDown.Value = bigev.Marker;
                    app.BigEventMarkerEdgeColorDropDown.Value = bigev.MarkerEdgeColor;
                    app.BigEventMarkerFaceColorDropDown.Value = bigev.MarkerFaceColor;
                    app.BigEventMarkerSizeEditField.Value = bigev.MarkerSize;
                    app.BigEventUseSizeFunctionCheckBox.Value = bigev.UseMainEventSizeFunction;
                    
                    f=fieldnames(results.FeatureProperties);
                    for i=1:numel(f)
                        s= results.FeatureProperties.(f{i});
                        app.OriginalFeatureProperties(f{i}) = s;
                        app.FeatureProperties(f{i}) = s;
                    end
                    
                case 'result_defaults'
                    app.ResultContourCountEditField.Value = results.NumContours;
                    app.ShowSampleRadiusCheckBox.Value = results.ShowSampleRadius;
                    
                case 'sampling_defaults'
                    if isempty(results.MaxSampleRadius); results.MaxSampleRadius = inf;end
                    if results.DistanceUnits ~= "kilometer" && ~isinf(results.MaxSampleRadius)
                        results.DistanceUnits = standardizeDistanceUnits(results.DistanceUnits);
                        if results.DistanceUnits == "degrees"
                            results.MaxSampleRadius = deg2km(results.MaxSampleRadius);
                        elseif results.DistanceUnits == "radians"
                            results.MaxSampleRadius = rad2km(results.MaxSampleRadius);
                        else
                            results.MaxSampleRadius = unitsratio('kilometer',results.DistanceUnits) * results.MaxSampleRadius;
                        end
                    end
                    if results.UseEventsInRadius && results.UseNumNearbyEvents
                        app.Sampling_UseBothRadioButton.Value = true;
                        app.SampleRadiusEditField.Value = results.MaxSampleRadius;
                    else
                        app.Sampling_UseRadiusRadioButton.Value = results.UseEventsInRadius;
                        app.Sampling_UseClosestRadioButton.Value = results.UseNumNearbyEvents;
                        app.SampleRadiusEditField.Value = results.MaxSampleRadius;
                    end
                    app.SamplingMaxEventsEditField.Value = results.NumClosestEvents;
                    app.SamplingWeightByDistanceCheckBox.Value = results.WeightByDistance;
                    
                otherwise
                    error('Missed a tab. Trying to setFromDefaults for %s',name);
            end
        end
        
        function val=checkbox2option(app,checkcontrol, iftrue, iffalse)
            if checkcontrol.Value
                val=iftrue;
            else
                val=iffalse;
            end
        end
        
        function revertValues(app,targetTab)
            disp("Reverting TAB:[" + targetTab.Title + "] (if the code were in place)");
            modified=[findobj(targetTab,'BackgroundColor',app.ModifiedFieldBackgroundColor) ;...
                findobj(targetTab,'Type','uicheckbox','-and','FontWeight','bold')];
            disp(modified);
            for j=1:numel(modified)
                event.Value=modified(j).UserData;
                event.PreviousValue=modified(j).Value;
                event.Source = modified(j);
                event.Name='ValueChanged';
                modified(j).Value=modified(j).UserData;
                modified(j).ValueChangedFcn(app,event);
            end
        end
        
        function showFeature(app,featurekey)
            % shows a feature from the Global feature list
            app.FeatureIDLabel.Text = featurekey;
            plotdefault=app.FeatureProperties(featurekey);
            origFeature = app.OriginalFeatureProperties(featurekey);
            
            for i=1:length(app.FeatureMappings)
                event.Source = app.(app.FeatureMappings{i,2});
                event.NewValue = plotdefault.(app.FeatureMappings{i,1});
                event.Source.Value = event.NewValue;
                % UserData will hold the default value, so changes can be detected
                event.Source.UserData = origFeature.(app.FeatureMappings{i,1});
                
                app.valueChanged(event)
            end
            h=findobj(app.FeatureUIAxes.Children,'Type','Line');
            flds=fieldnames(plotdefault);
            invalidflds=~ismember(flds,properties(h));
            toset=rmfield(plotdefault,flds(invalidflds));
            toset.HitTest=app.checkbox2option(app.FeatureHitTestCheckBox,'on','off');
            if startsWith(toset.Color,'[')
                toset.Color=str2num(toset.Color);
            end
            set(h,toset);
            
        end
        
        function browseDirectory(~,target,title)
            dirname=uigetdir(target.Value,title);
            if ischar(dirname)
                target.Value=dirname;
            end
        end
        
        function updateSamplingUIPlot(app)
            bev=findobj(app.SamplingUIAxes,'Tag','backgroundevents');
            unselectedColor=[0.5 0.5 0.5];
            unselectedSize=10;
            selectedSize=50;
            selectedColor=[0.5 0 0];
            nSamps=app.SamplingMaxEventsEditField.Value;
            maxRad=app.SampleRadiusEditField.Value;
            if isempty(bev)
                bev=scatter(app.SamplingUIAxes,rand(1000,1)*100-50,rand(1000,1)*100-50,...
                    repmat(unselectedSize,1000,1), unselectedColor,...
                    'Marker','.','Tag','backgroundevents');
                app.SamplingUIAxes.NextPlot='add';
                scatter(app.SamplingUIAxes,0,0,25,[0 0 1],'Marker','+');
            end
            dists_sqrd=bev.XData .^ 2 + bev.YData .^ 2;
            switch app.SampleByButtonGroup.SelectedObject
                case app.Sampling_UseClosestRadioButton
                    [~,idx]=sort(dists_sqrd);
                    bev.CData(idx(1:nSamps),:)=repmat(selectedColor,nSamps,1);
                    bev.SizeData(idx(1:nSamps))=selectedSize;
                    bev.CData(idx(nSamps+1:end),:)=repmat(unselectedColor,numel(bev.XData)-nSamps,1);
                    bev.SizeData(idx(nSamps+1:end))=unselectedSize;
                case app.Sampling_UseRadiusRadioButton
                    idx=dists_sqrd <= maxRad .^ 2;
                    bev.CData(idx,:)=repmat(selectedColor,sum(idx),1);
                    bev.SizeData(idx)=selectedSize;
                    bev.CData(~idx,:)=repmat(unselectedColor,sum(~idx),1);
                    bev.SizeData(~idx)=unselectedSize;
                    
                case app.Sampling_UseBothRadioButton
                    [~,idx]=sort(dists_sqrd);
                    bev.CData(idx(1:nSamps),:)=repmat(selectedColor,nSamps,1);
                    bev.SizeData(idx(1:nSamps))=selectedSize;
                    bev.CData(idx(nSamps+1:end),:)=repmat(unselectedColor,numel(bev.XData)-nSamps,1);
                    bev.SizeData(idx(nSamps+1:end))=unselectedSize;
                    idx=dists_sqrd > maxRad .^ 2;
                    bev.CData(idx,:)=repmat(unselectedColor,sum(idx),1);
                    bev.SizeData(idx)=unselectedSize;
            end

        end
        
        function results = InitializeMainTabs(app)
            app.GeneralTab.UserData = 'general_defaults';
            app.CrossSectionsTab.UserData = 'cross_section_defaults';
            app.CatalogsTab.UserData = 'catalog_defaults';
            app.GridTab.UserData = 'grid_defaults';
            app.MainMapTab.UserData = 'mainmap_defaults';
            app.ResultsTab.UserData = 'result_defaults';
            app.SamplingTab.UserData = 'sampling_defaults';
        end
        
        function InitializeFeatureProperties(app)
             % shows a feature from the Global feature list
            keys=ZmapGlobal.Data.features.keys;
            app.FeatureProperties=containers.Map;
            for i=1:numel(keys)
                featurekey=keys{i};
                fp=struct();
                
                plotdefault = ZmapGlobal.Data.features(featurekey).PlottingDefaults;
                
                fp.IDLabel = featurekey;
                fp.UseMe = ismember(featurekey, ZmapGlobal.Data.mainmap_features);
                fp.DisplayName = plotdefault.DisplayName;
                fp.Tag = plotdefault.Tag;
                fp.LineWidth = plotdefault.LineWidth;
                fp.LineStyle = plotdefault.LineStyle;
                if ischar(plotdefault.Color)
                    fp.Color = plotdefault.Color;
                else
                    fp.Color = mat2str(plotdefault.Color);
                end
                if ~ismember(plotdefault.Marker,app.FeatureMarkerDropDown.Items)
                    idx=startsWith(app.FeatureMarkerDropDown.Items,plotdefault.Marker);
                    fp.Marker = app.FeatureMarkerDropDown.Items{idx};
                else
                    fp.Marker = plotdefault.Marker;
                end
                fp.MarkerSize = plotdefault.MarkerSize;
                if ischar(plotdefault.MarkerFaceColor)
                    fp.MarkerFaceColor = plotdefault.MarkerFaceColor;
                else
                    fp.MarkerFaceColor = mat2str(plotdefault.MarkerFaceColor);
                end
                if ischar(plotdefault.MarkerEdgeColor)
                    fp.MarkerEdgeColor = plotdefault.MarkerEdgeColor;
                else
                    fp.MarkerEdgeColor = mat2str(plotdefault.MarkerEdgeColor);
                end
                fp.PickableParts = plotdefault.PickableParts;
                fp.HitTest = plotdefault.HitTest=="on";
                app.FeatureProperties(featurekey) = fp; % maps hold copies of non-handle objects, so this is ok.
                % app.OriginalFeatureProperties(featurekey) = fp;
            end
        end

        function InitializeFeaturesThatAreUsed(app)
            keys = app.FeaturesListBox.Items;
            keys=keys(:);
            usedMask = false(numel(keys),1);
            for i=1:numel(keys)
                if app.FeatureProperties(keys{i}).UseMe
                    usedMask(i)=true;
                end
            end
            txt=keys(usedMask);
            app.FeaturesthatareUsedTextArea.Value=txt;
            app.FeaturesthatareUsedTextArea.UserData = usedMask;
        end
        
        function updateMainMapUIPlot(app)
            hMain=findobj(app.MainMapEventsUIAxes,'Tag','MainEventsScatter');
            if isempty(hMain)
                lats = rand(100,1).*10 + 40;
                lons = rand(100,1).*10;
                mags = sort([round(logspace(-1,.91,50).*2)./2,logspace(-1,.6,50)]');
                hMain = scatter(app.MainMapEventsUIAxes,lons, lats,'Tag','MainEventsScatter');
                hMain.UserData = mags; % keep the original magnitudes. They'll be needed.
                app.MainMapEventsUIAxes.NextPlot='add';
                hBig = scatter(app.MainMapEventsUIAxes,nan,nan,'Tag','BigEventsScatter');
                app.MainMapEventsUIAxes.NextPlot='replace';
            end
            % edit the "NORMAL" event appearance
            szfn=str2func(app.EventsMarkerSizeFunctionEditField.Value);
            hMain.SizeData=szfn(hMain.UserData);
            hMain.Marker = app.EventsMarkerDropDown.Value;
            hMain.MarkerEdgeColor = app.safeRGB(app.EventsMarkerEdgeColorDropDown.Value);
            hMain.MarkerFaceColor = app.safeRGB(app.EventsMarkerFaceColorDropDown.Value);
            hMain.LineWidth = app.EventsLineWidthEditField.Value;
            
            % edit the "BIG" event appearance
            hBig = findobj(app.MainMapEventsUIAxes,'Tag','BigEventsScatter');
            bigMask = hMain.UserData >= app.BigEventMinMagThreshholdEditField.Value;
            hBig.XData = hMain.XData(bigMask);
            hBig.YData = hMain.YData(bigMask);
            if app.BigEventUseSizeFunctionCheckBox.Value
                hBig.SizeData = hMain.SizeData(bigMask);
            else
                hBig.SizeData = app.BigEventMarkerSizeEditField.Value;
            end
            hBig.Marker = app.BigEventMarkerDropDown.Value;
            hBig.MarkerEdgeColor = app.safeRGB(app.BigEventMarkerEdgeColorDropDown.Value);
            hBig.MarkerFaceColor = app.safeRGB(app.BigEventMarkerFaceColorDropDown.Value);
            hBig.LineWidth = app.BigEventLineWidthEditField.Value;
            
            grid(app.MainMapEventsUIAxes,char(matlab.lang.OnOffSwitchState(app.MainMapShowLatLonGridCheckBox.Value)));
            if app.MainMapSetAspectRatioByLatitudeCheckBox.Value
            app.MainMapEventsUIAxes.DataAspectRatio = [1 cosd(45) 1];
            else
                app.MainMapEventsUIAxes.DataAspectRatio = [1 1 1];
            end
            legend(app.MainMapEventsUIAxes,app.checkbox2option(app.MainMapShowLegendCheckBox, 'show','hide'));
        end
        
        function results = safeRGB(app,value)
            if ismember(value,{'auto','none','flat'})
                results=value;
            else
                results=FancyColors.rgb(value);
            end
            
        end
        
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.InitializeMainTabs();
            app.setFromDefaults('ALL');
            parallelControlEnabler = matlab.lang.OnOffSwitchState(has_toolbox('Parallel Computing Toolbox'));
            app.EnableParallelProcessingCheckBox.Enable = parallelControlEnabler;
            app.ParallelProcessingThreshholdEditField.Enable = parallelControlEnabler;
            app.ParallelProcessingThreshholdEditFieldLabel.Enable = parallelControlEnabler;
            app.EnableParallelProcessingCheckBox.Value = app.EnableParallelProcessingCheckBox.Value && parallelControlEnabler;
            
            %app.InitializeFeatureProperties();
            app.FeaturesListBox.Items=app.FeatureProperties.keys;
            app.FeaturesListBox.Value=app.FeaturesListBox.Items{1};
            app.InitializeFeaturesThatAreUsed();
            for i=1:length(app.FeatureMappings)
                app.(app.FeatureMappings{i,2}).Tag=app.FeatureMappings{i,1};
            end
            % plot example for feature
            line(app.FeatureUIAxes,[1;2;3;4],[0 .5 .75 .6],'Tag','FeatureExample');
            app.FeatureUIAxes.XLim=[0 5];
            app.FeatureUIAxes.YTickLabel={};
            app.FeatureUIAxes.XTickLabel={};
            app.FeaturesListBoxValueChanged([]); % ensure the features plot is updated
            % plot example for sampling
            app.updateSamplingUIPlot();
            
            % plot example for MainMap
            app.updateMainMapUIPlot();
            
            app.ZMAPdirEditField.Value=strrep(which('zmap'),'zmap.m','');
        end

        % Callback function
        function AddFeatureButtonPushed(app, event)
            n = size(app.FeaturesUITable.Data,1)
            c = numel(app.FeaturesUITable.ColumnName)
            if isempty(app.FeaturesUITable.Data)
                app.FeaturesUITable.Data=cell(1,c);
            end
            app.FeaturesUITable.Data(n+1,1:c)={'-'};
        end

        % Button pushed function: BrowseDatadirButton
        function BrowseDatadirButtonPushed(app, event)
            app.browseDirectory(app.dataEditField,'Choose Data Directory');
        end

        % Button pushed function: BrowseOutputdirButton
        function BrowseOutputdirButtonPushed(app, event)
            app.browseDirectory(app.outputEditField,'Choose Output Directory');
        end

        % Button pushed function: BrowseWorkingdirButton
        function BrowseWorkingdirButtonPushed(app, event)
            app.browseDirectory(app.workingEditField,'Choose Working Directory');
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            delete(app)
        end

        % Value changed function: DebugLevelSpinner, DebugModeCheckBox
        function DebugValueChanged(app, event)
            value = event.Source.Value;
            app.valueChanged(event);
        end

        % Value changed function: FeatureDetailLevelDropDown, 
        % FeatureDisplayNameEditField, FeatureEdgeColorEditField, 
        % FeatureFaceColorEditField, FeatureHitTestCheckBox, 
        % FeatureLineColorEditField, FeatureLineStyleDropDown, 
        % FeatureLineWidthEditField, FeatureMarkerDropDown, 
        % FeatureMarkerSizeEditField, FeaturePickablePartsDropDown
        function FeaturePropertyValueChanged(app, event)
            value = event.Source.Value;
            fld = event.Source.Tag;
            featureName = app.FeatureIDLabel.Text;
            thisFeature = app.FeatureProperties(featureName);
            thisFeature.(fld) = value;
            app.FeatureProperties(featureName)=thisFeature;
            set(app.FeatureUIAxes.Children, fld, value);
            app.valueChanged(event);
        end

        % Value changed function: FeatureUseCheckBox
        function FeatureUseCheckBoxValueChanged(app, event)
            
            value = app.FeatureUseCheckBox.Value;
            key = app.FeatureIDLabel.Text;
            allFeatures = app.FeaturesListBox.Items;
            myidx = allFeatures==string(key);
            CurrentUsedFeatures = ismember(allFeatures,app.FeaturesthatareUsedTextArea.Value);
            CurrentUsedFeatures(myidx)=value;
            if ~isequal(app.FeaturesthatareUsedTextArea.UserData(:), CurrentUsedFeatures(:))
                app.FeaturesthatareUsedTextArea.BackgroundColor = app.ModifiedFieldBackgroundColor;
            else
                app.FeaturesthatareUsedTextArea.BackgroundColor = 'w';
            end
            app.FeaturesthatareUsedTextArea.Value = allFeatures(CurrentUsedFeatures);
        end

        % Value changed function: FeaturesListBox
        function FeaturesListBoxValueChanged(app, event)
            value = app.FeaturesListBox.Value;
            app.showFeature(value);
        end

        % Value changed function: BigEventLineWidthEditField, 
        % BigEventMarkerDropDown, BigEventMarkerEdgeColorDropDown, 
        % BigEventMarkerFaceColorDropDown, BigEventMarkerSizeEditField, 
        % BigEventMinMagThreshholdEditField, 
        % BigEventThreshholdPerCatalogCheckBox, 
        % BigEventUseSizeFunctionCheckBox, EventsDotsForSmallCheckBox, 
        % EventsLineWidthEditField, EventsMarkerDropDown, 
        % EventsMarkerEdgeColorDropDown, EventsMarkerFaceColorDropDown, 
        % EventsMarkerSizeFunctionEditField, 
        % EventsSmallMagThreshholdEditField, 
        % MainMapSetAspectRatioByLatitudeCheckBox, 
        % MainMapShowLatLonGridCheckBox, MainMapShowLegendCheckBox
        function MainMapEventValueChanged(app, event)
            %value = app.BigEventLineWidthEditField.Value;
            app.updateMainMapUIPlot();
            app.valueChanged(event);
        end

        % Button pushed function: RevertTabButton
        function RevertTabButtonPushed(app, event)
            app.revertValues(app.TabGroup.SelectedTab);
        end

        % Selection changed function: SampleByButtonGroup
        function SampleByButtonGroupSelectionChanged(app, event)
            selectedButton = app.SampleByButtonGroup.SelectedObject;
            app.valueChanged(event)
            app.updateSamplingUIPlot();
        end

        % Callback function
        function SamplingValueChanged(app, event)
            value = app.SampleRadiusEditField.Value;
            app.updateSamplingUIPlot();
        end

        % Button pushed function: SaveAllButton
        function SaveAllButtonPushed(app, event)
            app.saveDefaults('ALL');
        end

        % Button pushed function: SaveFeatureButton
        function SaveFeatureButtonPushed(app, event)
            featureName = app.FeatureIDLabel.Text;
            app.OriginalFeatureProperties(featureName)=app.FeatureProperties(featureName);
            app.saveDefaults('one_feature');
            app.showFeature(featureName);
        end

        % Button pushed function: SaveTabButton
        function SaveTabButtonPushed(app, event)
            fprintf('Saving %s tab\n', app.TabGroup.SelectedTab.UserData);
            app.saveDefaults(app.TabGroup.SelectedTab.UserData);
        end

        % Value changed function: EnableParallelProcessingCheckBox, 
        % EventsColorByDropDown, GridColorDropDown, 
        % GridFollowMeridiansCheckBox, GridLineWidthEditField, 
        % GridMarkerDropDown, GridMarkerSizeEditField, 
        % GridNameEditField, GridUnitsDropDown, GridXAnchor, 
        % GridYAnchor, GridZAnchor, Griddx, Griddy, 
        % ParallelProcessingThreshholdEditField, 
        % ResultContourCountEditField, SampleRadiusEditField, 
        % SamplingMaxEventsEditField, SamplingWeightByDistanceCheckBox, 
        % ShowSampleRadiusCheckBox, UseFixedAnchorPointCheckBox, 
        % XsecBorderLineStyleDropDown, XsecBorderLineWidthEditField, 
        % XsecEndMarkerDropDown, XsecLabelBoldCheckBox, 
        % XsecLabelFontSizeEditField, XsecLabelOutlineCheckBox, 
        % XsecLineStyleDropDown, XsecLineWidthEditField, 
        % XsecMarkerSizeEditField, XsecWidthEditField, dataEditField, 
        % outputEditField, workingEditField
        function valueChanged(app, event)
            % change color when value is different from the saved value
            
            if isempty(event.Source.UserData)
                switch event.Source.Type
                    case "uibuttongroup"
                        event.Source.UserData=event.OldValue;
                    otherwise
                        event.Source.UserData=event.PreviousValue;
                end
            end
            if event.Source.Type == "uibuttongroup"
                mybuttons=findobj(event.Source.Children,'flat','Type','uiradiobutton');
                if event.NewValue == event.Source.UserData
                    set(mybuttons,'FontWeight','normal');
                    event.Source.BackgroundColor=[0.94 0.94 0.94];
                else
                    set(mybuttons,'FontWeight','bold');
                    event.Source.BackgroundColor=app.ModifiedFieldBackgroundColor-[0.1 0.1 0.1];
                end
                
            elseif string(event.Source.Value) ~= string(event.Source.UserData)
                if event.Source.Type ~= "uicheckbox"
                    event.Source.BackgroundColor=app.ModifiedFieldBackgroundColor;
                else
                    event.Source.FontWeight='bold';
                end
            else
                if event.Source.Type ~= "uicheckbox"
                    event.Source.BackgroundColor=[1 1 1];
                else
                    event.Source.FontWeight='normal';
                end
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ZMAPPreferencesUIFigure = uifigure;
            app.ZMAPPreferencesUIFigure.Name = 'ZMAP Preferences';
            app.TabGroup = uitabgroup(app.ZMAPPreferencesUIFigure);
            app.GeneralTab = uitab(app.TabGroup);
            app.GeneralTab.Title = 'General';
            app.DirectoriesPanel = uipanel(app.GeneralTab);
            app.DirectoriesPanel.Title = 'Directories';
            app.outputEditFieldLabel = uilabel(app.DirectoriesPanel);
            app.outputEditFieldLabel.Text = 'output';
            app.outputEditField = uieditfield(app.DirectoriesPanel, 'text');
            app.outputEditField.ValueChangedFcn = createCallbackFcn(app, @valueChanged, true);
            app.dataEditFieldLabel = uilabel(app.DirectoriesPanel);
            app.dataEditFieldLabel.Text = 'data';
            app.dataEditField = uieditfield(app.DirectoriesPanel, 'text');
            app.dataEditField.ValueChangedFcn = createCallbackFcn(app, @valueChanged, true);
            app.workingEditFieldLabel = uilabel(app.DirectoriesPanel);
            app.workingEditFieldLabel.Text = 'working';
            app.workingEditField = uieditfield(app.DirectoriesPanel, 'text');
            app.workingEditField.ValueChangedFcn = createCallbackFcn(app, @valueChanged, true);
            app.BrowseOutputdirButton = uibutton(app.DirectoriesPanel, 'push');
            app.BrowseOutputdirButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseOutputdirButtonPushed, true);
            app.BrowseOutputdirButton.Text = 'Browse...';
            app.BrowseDatadirButton = uibutton(app.DirectoriesPanel, 'push');
            app.BrowseDatadirButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseDatadirButtonPushed, true);
            app.BrowseDatadirButton.Text = 'Browse...';
            app.BrowseWorkingdirButton = uibutton(app.DirectoriesPanel, 'push');
            app.BrowseWorkingdirButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseWorkingdirButtonPushed, true);
            app.BrowseWorkingdirButton.Text = 'Browse...';
            app.ZMAPEditFieldLabel = uilabel(app.DirectoriesPanel);
            app.ZMAPEditFieldLabel.Text = 'ZMAP';
            app.ZMAPdirEditField = uieditfield(app.DirectoriesPanel, 'text');
            app.ZMAPdirEditField.Editable = 'off';
            app.ParallelProcessingPanel = uipanel(app.GeneralTab);
            app.ParallelProcessingPanel.Title = 'Parallel Processing';
            app.EnableParallelProcessingCheckBox = uicheckbox(app.ParallelProcessingPanel);
            app.EnableParallelProcessingCheckBox.ValueChangedFcn = createCallbackFcn(app, @valueChanged, true);
            app.EnableParallelProcessingCheckBox.Text = 'Enable';
            app.ParallelProcessingThreshholdEditFieldLabel = uilabel(app.ParallelProcessingPanel);
            app.ParallelProcessingThreshholdEditFieldLabel.Text = 'Parallel Processing Threshhold';
            app.ParallelProcessingThreshholdEditField = uieditfield(app.ParallelProcessingPanel, 'numeric');
            app.ParallelProcessingThreshholdEditField.Limits = [0 Inf];
            app.ParallelProcessingThreshholdEditField.ValueDisplayFormat = '%.0f';
            app.ParallelProcessingThreshholdEditField.ValueChangedFcn = createCallbackFcn(app, @valueChanged, true);
            app.GeneralDefaultsLabel = uilabel(app.GeneralTab);
            app.GeneralDefaultsLabel.Text = 'General Defaults';
            app.DebuggingPanel = uipanel(app.GeneralTab);
            app.DebuggingPanel.Title = 'Debugging';
            app.DebugModeCheckBox = uicheckbox(app.DebuggingPanel);
            app.DebugModeCheckBox.ValueChangedFcn = createCallbackFcn(app, @DebugValueChanged, true);
            app.DebugModeCheckBox.Text = 'Debug Mode';
            app.LevelSpinnerLabel = uilabel(app.DebuggingPanel);
            app.LevelSpinnerLabel.Text = 'Level';
            app.DebugLevelSpinner = uispinner(app.DebuggingPanel);
            app.DebugLevelSpinner.ValueDisplayFormat = '%.0f';
            app.DebugLevelSpinner.ValueChangedFcn = createCallbackFcn(app, @DebugValueChanged, true);
            app.DebugLevelSpinner.Editable = 'off';
            app.DebugLevelSpinner.Value = 1;
            app.CrossSectionsTab = uitab(app.TabGroup);
            app.CrossSectionsTab.Title = 'Cross Sections';
            app.CrossSectionDefaultsLabel = uilabel(app.CrossSectionsTab);
            app.CrossSectionDefaultsLabel.Text = {'Cross Section'; 'Defaults'};
            app.CrossSectionsPanel = uipanel(app.CrossSectionsTab);
            app.CrossSectionsPanel.Title = 'Cross Sections';
            app.XsectionWidthkmEditFieldLabel = uilabel(app.CrossSectionsPanel);
            app.XsectionWidthkmEditFieldLabel.Text = 'X-section Width [km]';
            app.XsecWidthEditField = uieditfield(app.CrossSectionsPanel, 'numeric');
            app.XsecWidthEditField.ValueChangedFcn = createCallbackFcn(app, @valueChanged, true);
            app.XsecWidthEditField.Value = 20;
            app.ColorDropDownLabel = uilabel(app.CrossSectionsPanel);
            app.ColorDropDownLabel.Text = 'Color';
            app.XsecColorDropDown = uidropdown(app.CrossSectionsPanel);
            app.XsecColorDropDown.Items = {'AUTO', 'USER DEFINED'};
            app.XsecColorDropDown.Editable = 'on';
            app.XsecColorDropDown.Value = 'AUTO';
            app.LineWidthEditFieldLabel = uilabel(app.CrossSectionsPanel);
            app.LineWidthEditFieldLabel.Text = 'Line Width';
            app.XsecLineWidthEditField = uieditfield(app.CrossSectionsPanel, 'numeric');
            app.XsecLineWidthEditField.LowerLimitInclusive = 'off';
            app.XsecLineWidthEditField.UpperLimitInclusive = 'off';
            app.XsecLineWidthEditField.Limits = [0 Inf];
            app.XsecLineWidthEditField.ValueChangedFcn = createCallbackFcn(app, @valueChanged, true);
            app.XsecLineWidthEditField.Value = 3;
            app.LineStyleDropDownLabel = uilabel(app.CrossSectionsPanel);
            app.LineStyleDropDownLabel.Text = 'Line Style';
            app.XsecLineStyleDropDown = uidropdown(app.CrossSectionsPanel);
            app.XsecLineStyleDropDown.Items = {'-', '--', ':', '-.', 'none'};
            app.XsecLineStyleDropDown.ValueChangedFcn = createCallbackFcn(app, @valueChanged, true);
            app.XsecLineStyleDropDown.Value = '--';
            app.EndMarkerDropDownLabel = uilabel(app.CrossSectionsPanel);
            app.EndMarkerDropDownLabel.Text = 'End Marker';
            app.XsecEndMarkerDropDown = uidropdown(app.CrossSectionsPanel);
            app.XsecEndMarkerDropDown.Items = {'+', 'o', '*', '.', 'x', 'square', 'diamond', 'v', '^', '>', '<', 'pentagram', 'hexagram', 'none'};
            app.XsecEndMarkerDropDown.ValueChangedFcn = createCallbackFcn(app, @valueChanged, true);
            app.XsecEndMarkerDropDown.Value = 'x';
            app.MarkerSizeEditFieldLabel = uilabel(app.CrossSectionsPanel);
            app.MarkerSizeEditFieldLabel.Text = 'Marker Size';
            app.XsecMarkerSizeEditField = uieditfield(app.CrossSectionsPanel, 'numeric');
            app.XsecMarkerSizeEditField.ValueChangedFcn = createCallbackFcn(app, @valueChanged, true);
            app.XsecMarkerSizeEditField.Value = 10;
            app.LabelsPanel = uipanel(app.CrossSectionsTab);
            app.LabelsPanel.Title = 'Labels';
            app.FontSizeEditFieldLabel = uilabel(app.LabelsPanel);
            app.FontSizeEditFieldLabel.Text = 'Font Size';
            app.XsecLabelFontSizeEditField = uieditfield(app.LabelsPanel, 'numeric');
            app.XsecLabelFontSizeEditField.LowerLimitInclusive = 'off';
            app.XsecLabelFontSizeEditField.UpperLimitInclusive = 'off';
            app.XsecLabelFontSizeEditField.Limits = [0 Inf];
            app.XsecLabelFontSizeEditField.ValueChangedFcn = createCallbackFcn(app, @valueChanged, true);
            app.XsecLabelFontSizeEditField.Value = 12;
            app.BackgroundColorDropDownLabel = uilabel(app.LabelsPanel);
            app.BackgroundColorDropDownLabel.Text = 'Background Color';
            app.XsecLabelBackgroundColorDropDown = uidropdown(app.LabelsPanel);
            app.XsecLabelBackgroundColorDropDown.Items = {'NONE', 'white', 'USER DEFINED'};
            app.XsecLabelBackgroundColorDropDown.Value = 'NONE';
            app.XsecLabelOutlineCheckBox = uicheckbox(app.LabelsPanel);
            app.XsecLabelOutlineCheckBox.ValueChangedFcn = createCallbackFcn(app, @valueChanged, true);
            app.XsecLabelOutlineCheckBox.Text = 'Outline';
            app.XsecLabelBoldCheckBox = uicheckbox(app.LabelsPanel);
            app.XsecLabelBoldCheckBox.ValueChangedFcn = createCallbackFcn(app, @valueChanged, true);
            app.XsecLabelBoldCheckBox.Text = 'Bold';
            app.BorderPanel = uipanel(app.CrossSectionsTab);
            app.BorderPanel.Title = 'Border';
            app.LineStyleDropDown_2Label = uilabel(app.BorderPanel);
            app.LineStyleDropDown_2Label.Text = 'Line Style';
            app.XsecBorderLineStyleDropDown = uidropdown(app.BorderPanel);
            app.XsecBorderLineStyleDropDown.Items = {'-', '--', ':', '-.', 'none'};
            app.XsecBorderLineStyleDropDown.ValueChangedFcn = createCallbackFcn(app, @valueChanged, true);
            app.XsecBorderLineStyleDropDown.Value = '-';
            app.LineWidthEditField_2Label = uilabel(app.BorderPanel);
            app.LineWidthEditField_2Label.Text = 'Line Width';
            app.XsecBorderLineWidthEditField = uieditfield(app.BorderPanel, 'numeric');
            app.XsecBorderLineWidthEditField.LowerLimitInclusive = 'off';
            app.XsecBorderLineWidthEditField.UpperLimitInclusive = 'off';
            app.XsecBorderLineWidthEditField.Limits = [0 Inf];
            app.XsecBorderLineWidthEditField.ValueChangedFcn = createCallbackFcn(app, @valueChanged, true);
            app.XsecBorderLineWidthEditField.Value = 0.25;
            app.TabPanel = uipanel(app.CrossSectionsTab);
            app.TabPanel.Title = 'Tab';
            app.ColorDropDown_2Label = uilabel(app.TabPanel);
            app.ColorDropDown_2Label.Text = 'Color';
            app.XsecTabColorDropDown = uidropdown(app.TabPanel);
            app.XsecTabColorDropDown.Items = {'AUTO', 'Black', 'Darker', 'Lighter', 'USER DEFINED'};
            app.XsecTabColorDropDown.Editable = 'on';
            app.XsecTabColorDropDown.Value = 'Darker';
            app.CatalogsTab = uitab(app.TabGroup);
            app.CatalogsTab.Title = 'Catalogs';
            app.ReopenLastCatalogCheckBox = uicheckbox(app.CatalogsTab);
            app.ReopenLastCatalogCheckBox.Text = 'Reopen Last Catalog upon Startup';
            app.ReopenLastCatalogCheckBox.Value = true;
            app.LastCatalogFilenameEditFieldLabel = uilabel(app.CatalogsTab);
            app.LastCatalogFilenameEditFieldLabel.Text = 'Last Catalog Filename';
            app.LastCatalogFilenameEditField = uieditfield(app.CatalogsTab, 'text');
            app.LastCatalogFilenameEditField.Value = 'lastcatalog.mat';
            app.relativetoworkingdirectoryLabel = uilabel(app.CatalogsTab);
            app.relativetoworkingdirectoryLabel.Text = 'relative to working directory';
            app.BigEventCatalogPanel = uipanel(app.CatalogsTab);
            app.BigEventCatalogPanel.Title = '"Big" Event Catalog';
            app.BigEventThreshholdPerCatalogCheckBox = uicheckbox(app.BigEventCatalogPanel);
            app.BigEventThreshholdPerCatalogCheckBox.ValueChangedFcn = createCallbackFcn(app, @MainMapEventValueChanged, true);
            app.BigEventThreshholdPerCatalogCheckBox.Text = 'Threshhold is catalog dependent';
            app.MinMagnitudeThreshholdEditFieldLabel = uilabel(app.BigEventCatalogPanel);
            app.MinMagnitudeThreshholdEditFieldLabel.Text = 'Min Magnitude Threshhold';
            app.BigEventMinMagThreshholdEditField = uieditfield(app.BigEventCatalogPanel, 'numeric');
            app.BigEventMinMagThreshholdEditField.ValueChangedFcn = createCallbackFcn(app, @MainMapEventValueChanged, true);
            app.BigEventMinMagThreshholdEditField.Value = 8;
            app.GridTab = uitab(app.TabGroup);
            app.GridTab.Title = 'Grid';
            app.GridAnchorPointPanel = uipanel(app.GridTab);
            app.GridAnchorPointPanel.Title = 'Anchor Point';
            app.XorLonEditFieldLabel = uilabel(app.GridAnchorPointPanel);
            app.XorLonEditFieldLabel.Text = 'X or Lon';
            app.GridXAnchor = uieditfield(app.GridAnchorPointPanel, 'numeric');
            app.GridXAnchor.LowerLimitInclusive = 'off';
            app.GridXAnchor.UpperLimitInclusive = 'off';
            app.GridXAnchor.ValueChangedFcn = createCallbackFcn(app, @valueChanged, true);
            app.YorLatEditFieldLabel = uilabel(app.GridAnchorPointPanel);
            app.YorLatEditFieldLabel.Text = 'Y or Lat';
            app.GridYAnchor = uieditfield(app.GridAnchorPointPanel, 'numeric');
            app.GridYAnchor.LowerLimitInclusive = 'off';
            app.GridYAnchor.UpperLimitInclusive = 'off';
            app.GridYAnchor.ValueChangedFcn = createCallbackFcn(app, @valueChanged, true);
            app.ZdownEditFieldLabel = uilabel(app.GridAnchorPointPanel);
            app.ZdownEditFieldLabel.Enable = 'off';
            app.ZdownEditFieldLabel.Text = 'Z (+down)';
            app.GridZAnchor = uieditfield(app.GridAnchorPointPanel, 'numeric');
            app.GridZAnchor.LowerLimitInclusive = 'off';
            app.GridZAnchor.UpperLimitInclusive = 'off';
            app.GridZAnchor.ValueChangedFcn = createCallbackFcn(app, @valueChanged, true);
            app.GridZAnchor.Enable = 'off';
            app.GridSeparationPanel = uipanel(app.GridTab);
            app.GridSeparationPanel.Title = 'Separation';
            app.dxEditFieldLabel = uilabel(app.GridSeparationPanel);
            app.dxEditFieldLabel.Text = 'dx';
            app.Griddx = uieditfield(app.GridSeparationPanel, 'numeric');
            app.Griddx.LowerLimitInclusive = 'off';
            app.Griddx.Limits = [0 Inf];
            app.Griddx.ValueDisplayFormat = '%.3f';
            app.Griddx.ValueChangedFcn = createCallbackFcn(app, @valueChanged, true);
            app.Griddx.Value = 0.5;
            app.dyEditFieldLabel = uilabel(app.GridSeparationPanel);
            app.dyEditFieldLabel.Text = 'dy';
            app.Griddy = uieditfield(app.GridSeparationPanel, 'numeric');
            app.Griddy.LowerLimitInclusive = 'off';
            app.Griddy.Limits = [0 Inf];
            app.Griddy.ValueDisplayFormat = '%.3f';
            app.Griddy.ValueChangedFcn = createCallbackFcn(app, @valueChanged, true);
            app.Griddy.Value = 0.5;
            app.dzkmLabel = uilabel(app.GridSeparationPanel);
            app.dzkmLabel.Enable = 'off';
            app.dzkmLabel.Text = 'dz [km]';
            app.Griddz = uieditfield(app.GridSeparationPanel, 'numeric');
            app.Griddz.LowerLimitInclusive = 'off';
            app.Griddz.Limits = [0 Inf];
            app.Griddz.ValueDisplayFormat = '%.3f';
            app.Griddz.Enable = 'off';
            app.Griddz.Value = 1;
            app.UnitsDropDownLabel = uilabel(app.GridSeparationPanel);
            app.UnitsDropDownLabel.Text = 'Units';
            app.GridUnitsDropDown = uidropdown(app.GridSeparationPanel);
            app.GridUnitsDropDown.Items = {'Degrees', 'Kilometers', 'Meters', 'Centimeters', 'Millimeters', 'Microns'};
            app.GridUnitsDropDown.ValueChangedFcn = createCallbackFcn(app, @valueChanged, true);
            app.GridUnitsDropDown.Value = 'Degrees';
            app.GridFollowMeridiansCheckBox = uicheckbox(app.GridSeparationPanel);
            app.GridFollowMeridiansCheckBox.ValueChangedFcn = createCallbackFcn(app, @valueChanged, true);
            app.GridFollowMeridiansCheckBox.Text = 'Follow Meridians';
            app.GridOKButton = uibutton(app.GridTab, 'push');
            app.GridOKButton.Text = 'OK';
            app.GridLoadButton = uibutton(app.GridTab, 'push');
            app.GridLoadButton.Text = 'Load Grid';
            app.GridSaveOKButton = uibutton(app.GridTab, 'push');
            app.GridSaveOKButton.Text = 'Save Grid as...';
            app.GridNameEditFieldLabel = uilabel(app.GridTab);
            app.GridNameEditFieldLabel.Text = 'Grid Name';
            app.GridNameEditField = uieditfield(app.GridTab, 'text');
            app.GridNameEditField.ValueChangedFcn = createCallbackFcn(app, @valueChanged, true);
            app.GridNameEditField.Value = 'grid';
            app.GridDefaultsLabel = uilabel(app.GridTab);
            app.GridDefaultsLabel.Text = 'Grid Defaults';
            app.GridAppearencePanel = uipanel(app.GridTab);
            app.GridAppearencePanel.Title = 'Appearance';
            app.ColorDropDownLabel_2 = uilabel(app.GridAppearencePanel);
            app.ColorDropDownLabel_2.Text = 'Color';
            app.GridColorDropDown = uidropdown(app.GridAppearencePanel);
            app.GridColorDropDown.Items = {'black [#000000]', 'USER DEFINED'};
            app.GridColorDropDown.Editable = 'on';
            app.GridColorDropDown.ValueChangedFcn = createCallbackFcn(app, @valueChanged, true);
            app.GridColorDropDown.Value = 'black [#000000]';
            app.MarkerLabel = uilabel(app.GridAppearencePanel);
            app.MarkerLabel.Text = 'Marker';
            app.GridMarkerDropDown = uidropdown(app.GridAppearencePanel);
            app.GridMarkerDropDown.Items = {'+', 'o', '*', '.', 'x', 'square', 'diamond', 'v', '^', '>', '<', 'pentagram', 'hexagram', 'none'};
            app.GridMarkerDropDown.ValueChangedFcn = createCallbackFcn(app, @valueChanged, true);
            app.GridMarkerDropDown.Value = '+';
            app.LineWidthEditFieldLabel_2 = uilabel(app.GridAppearencePanel);
            app.LineWidthEditFieldLabel_2.Text = 'Line Width';
            app.GridLineWidthEditField = uieditfield(app.GridAppearencePanel, 'numeric');
            app.GridLineWidthEditField.LowerLimitInclusive = 'off';
            app.GridLineWidthEditField.UpperLimitInclusive = 'off';
            app.GridLineWidthEditField.Limits = [0 Inf];
            app.GridLineWidthEditField.ValueChangedFcn = createCallbackFcn(app, @valueChanged, true);
            app.GridLineWidthEditField.Value = 3;
            app.MarkerSizeEditFieldLabel_2 = uilabel(app.GridAppearencePanel);
            app.MarkerSizeEditFieldLabel_2.Text = 'Marker Size';
            app.GridMarkerSizeEditField = uieditfield(app.GridAppearencePanel, 'numeric');
            app.GridMarkerSizeEditField.ValueChangedFcn = createCallbackFcn(app, @valueChanged, true);
            app.GridMarkerSizeEditField.Value = 10;
            app.UseFixedAnchorPointCheckBox = uicheckbox(app.GridTab);
            app.UseFixedAnchorPointCheckBox.ValueChangedFcn = createCallbackFcn(app, @valueChanged, true);
            app.UseFixedAnchorPointCheckBox.Text = 'Use Fixed Anchor Point';
            app.UseAutogriduponloadingacatalogCheckBox = uicheckbox(app.GridTab);
            app.UseAutogriduponloadingacatalogCheckBox.Text = 'Use Autogrid upon loading a catalog';
            app.UseAutogriduponloadingacatalogCheckBox.Value = true;
            app.MainMapTab = uitab(app.TabGroup);
            app.MainMapTab.Title = 'Main Map';
            app.MainTabGroup = uitabgroup(app.MainMapTab);
            app.DisplayOptionsTab = uitab(app.MainTabGroup);
            app.DisplayOptionsTab.Title = 'Display Options';
            app.MainMapSetAspectRatioByLatitudeCheckBox = uicheckbox(app.DisplayOptionsTab);
            app.MainMapSetAspectRatioByLatitudeCheckBox.ValueChangedFcn = createCallbackFcn(app, @MainMapEventValueChanged, true);
            app.MainMapSetAspectRatioByLatitudeCheckBox.Text = 'Set Aspect Ratio By Latitude';
            app.MainMapSetAspectRatioByLatitudeCheckBox.Value = true;
            app.MainMapShowLegendCheckBox = uicheckbox(app.DisplayOptionsTab);
            app.MainMapShowLegendCheckBox.ValueChangedFcn = createCallbackFcn(app, @MainMapEventValueChanged, true);
            app.MainMapShowLegendCheckBox.Text = 'Show Legend';
            app.MainMapShowLegendCheckBox.Value = true;
            app.MainMapShowLatLonGridCheckBox = uicheckbox(app.DisplayOptionsTab);
            app.MainMapShowLatLonGridCheckBox.ValueChangedFcn = createCallbackFcn(app, @MainMapEventValueChanged, true);
            app.MainMapShowLatLonGridCheckBox.Text = 'Show Lat/Lon Grid';
            app.MainMapBigEventsPanel = uipanel(app.DisplayOptionsTab);
            app.MainMapBigEventsPanel.Title = '"Big" Events';
            app.MarkerEdgeColorLabel_2 = uilabel(app.MainMapBigEventsPanel);
            app.MarkerEdgeColorLabel_2.Text = 'Marker Edge Color';
            app.BigEventMarkerEdgeColorDropDown = uidropdown(app.MainMapBigEventsPanel);
            app.BigEventMarkerEdgeColorDropDown.Items = {'none', 'flat', 'black', 'yellow', 'USER DEFINED'};
            app.BigEventMarkerEdgeColorDropDown.Editable = 'on';
            app.BigEventMarkerEdgeColorDropDown.ValueChangedFcn = createCallbackFcn(app, @MainMapEventValueChanged, true);
            app.BigEventMarkerEdgeColorDropDown.Value = 'none';
            app.MarkerLabel_3 = uilabel(app.MainMapBigEventsPanel);
            app.MarkerLabel_3.Text = 'Marker';
            app.BigEventMarkerDropDown = uidropdown(app.MainMapBigEventsPanel);
            app.BigEventMarkerDropDown.Items = {'+', 'o', '*', '.', 'x', 'square', 'diamond', 'v', '^', '>', '<', 'pentagram', 'hexagram', 'none'};
            app.BigEventMarkerDropDown.ValueChangedFcn = createCallbackFcn(app, @MainMapEventValueChanged, true);
            app.BigEventMarkerDropDown.Value = 'hexagram';
            app.LineWidthEditFieldLabel_3 = uilabel(app.MainMapBigEventsPanel);
            app.LineWidthEditFieldLabel_3.Text = 'Line Width';
            app.BigEventLineWidthEditField = uieditfield(app.MainMapBigEventsPanel, 'numeric');
            app.BigEventLineWidthEditField.LowerLimitInclusive = 'off';
            app.BigEventLineWidthEditField.UpperLimitInclusive = 'off';
            app.BigEventLineWidthEditField.Limits = [0 Inf];
            app.BigEventLineWidthEditField.ValueChangedFcn = createCallbackFcn(app, @MainMapEventValueChanged, true);
            app.BigEventLineWidthEditField.Value = 3;
            app.MarkerSizeEditFieldLabel_3 = uilabel(app.MainMapBigEventsPanel);
            app.MarkerSizeEditFieldLabel_3.Enable = 'off';
            app.MarkerSizeEditFieldLabel_3.Text = 'Marker Size';
            app.BigEventMarkerSizeEditField = uieditfield(app.MainMapBigEventsPanel, 'numeric');
            app.BigEventMarkerSizeEditField.LowerLimitInclusive = 'off';
            app.BigEventMarkerSizeEditField.UpperLimitInclusive = 'off';
            app.BigEventMarkerSizeEditField.Limits = [0 Inf];
            app.BigEventMarkerSizeEditField.ValueChangedFcn = createCallbackFcn(app, @MainMapEventValueChanged, true);
            app.BigEventMarkerSizeEditField.Enable = 'off';
            app.BigEventMarkerSizeEditField.Value = 10;
            app.MarkerFaceColorLabel_2 = uilabel(app.MainMapBigEventsPanel);
            app.MarkerFaceColorLabel_2.Text = 'Marker Face Color';
            app.BigEventMarkerFaceColorDropDown = uidropdown(app.MainMapBigEventsPanel);
            app.BigEventMarkerFaceColorDropDown.Items = {'auto', 'flat', 'none', 'black', 'yellow', 'USER DEFINED'};
            app.BigEventMarkerFaceColorDropDown.Editable = 'on';
            app.BigEventMarkerFaceColorDropDown.ValueChangedFcn = createCallbackFcn(app, @MainMapEventValueChanged, true);
            app.BigEventMarkerFaceColorDropDown.Value = 'none';
            app.BigEventUseSizeFunctionCheckBox = uicheckbox(app.MainMapBigEventsPanel);
            app.BigEventUseSizeFunctionCheckBox.ValueChangedFcn = createCallbackFcn(app, @MainMapEventValueChanged, true);
            app.BigEventUseSizeFunctionCheckBox.Enable = 'off';
            app.BigEventUseSizeFunctionCheckBox.Text = 'Use Size Function from "Normal" Events';
            app.BigEventUseSizeFunctionCheckBox.Value = true;
            app.MainMapNormalEventsPanel = uipanel(app.DisplayOptionsTab);
            app.MainMapNormalEventsPanel.Title = '"Normal" (Selected) Events';
            app.MarkerFaceColorLabel = uilabel(app.MainMapNormalEventsPanel);
            app.MarkerFaceColorLabel.Text = 'Marker Face Color';
            app.EventsMarkerFaceColorDropDown = uidropdown(app.MainMapNormalEventsPanel);
            app.EventsMarkerFaceColorDropDown.Items = {'auto', 'flat', 'none', 'black', 'USER DEFINED'};
            app.EventsMarkerFaceColorDropDown.Editable = 'on';
            app.EventsMarkerFaceColorDropDown.ValueChangedFcn = createCallbackFcn(app, @MainMapEventValueChanged, true);
            app.EventsMarkerFaceColorDropDown.Value = 'none';
            app.MarkerLabel_4 = uilabel(app.MainMapNormalEventsPanel);
            app.MarkerLabel_4.Text = 'Marker';
            app.EventsMarkerDropDown = uidropdown(app.MainMapNormalEventsPanel);
            app.EventsMarkerDropDown.Items = {'+', 'o', '*', '.', 'x', 'square', 'diamond', 'v', '^', '>', '<', 'pentagram', 'hexagram', 'none'};
            app.EventsMarkerDropDown.ValueChangedFcn = createCallbackFcn(app, @MainMapEventValueChanged, true);
            app.EventsMarkerDropDown.Value = 'square';
            app.LineWidthEditFieldLabel_4 = uilabel(app.MainMapNormalEventsPanel);
            app.LineWidthEditFieldLabel_4.Text = 'Line Width';
            app.EventsLineWidthEditField = uieditfield(app.MainMapNormalEventsPanel, 'numeric');
            app.EventsLineWidthEditField.LowerLimitInclusive = 'off';
            app.EventsLineWidthEditField.UpperLimitInclusive = 'off';
            app.EventsLineWidthEditField.Limits = [0 Inf];
            app.EventsLineWidthEditField.ValueChangedFcn = createCallbackFcn(app, @MainMapEventValueChanged, true);
            app.EventsLineWidthEditField.Value = 3;
            app.MarkerEdgeColorLabel = uilabel(app.MainMapNormalEventsPanel);
            app.MarkerEdgeColorLabel.Text = 'Marker Edge Color';
            app.EventsMarkerEdgeColorDropDown = uidropdown(app.MainMapNormalEventsPanel);
            app.EventsMarkerEdgeColorDropDown.Items = {'none', 'flat', 'black', 'USER DEFINED'};
            app.EventsMarkerEdgeColorDropDown.Editable = 'on';
            app.EventsMarkerEdgeColorDropDown.ValueChangedFcn = createCallbackFcn(app, @MainMapEventValueChanged, true);
            app.EventsMarkerEdgeColorDropDown.Value = 'black';
            app.ColorByLabel = uilabel(app.MainMapNormalEventsPanel);
            app.ColorByLabel.Enable = 'off';
            app.ColorByLabel.Text = 'Color By';
            app.EventsColorByDropDown = uidropdown(app.MainMapNormalEventsPanel);
            app.EventsColorByDropDown.Items = {'-none-', 'Depth', 'Date', 'Magnitude'};
            app.EventsColorByDropDown.ValueChangedFcn = createCallbackFcn(app, @valueChanged, true);
            app.EventsColorByDropDown.Enable = 'off';
            app.EventsColorByDropDown.Value = '-none-';
            app.EventsDotsForSmallCheckBox = uicheckbox(app.MainMapNormalEventsPanel);
            app.EventsDotsForSmallCheckBox.ValueChangedFcn = createCallbackFcn(app, @MainMapEventValueChanged, true);
            app.EventsDotsForSmallCheckBox.Enable = 'off';
            app.EventsDotsForSmallCheckBox.Text = 'Use dots for events smaller than';
            app.MagEditFieldLabel = uilabel(app.MainMapNormalEventsPanel);
            app.MagEditFieldLabel.Enable = 'off';
            app.MagEditFieldLabel.Text = 'Mag';
            app.EventsSmallMagThreshholdEditField = uieditfield(app.MainMapNormalEventsPanel, 'numeric');
            app.EventsSmallMagThreshholdEditField.ValueChangedFcn = createCallbackFcn(app, @MainMapEventValueChanged, true);
            app.EventsSmallMagThreshholdEditField.Enable = 'off';
            app.MarkerSizeFunctionEditFieldLabel = uilabel(app.MainMapNormalEventsPanel);
            app.MarkerSizeFunctionEditFieldLabel.Text = 'Marker Size  Function';
            app.EventsMarkerSizeFunctionEditField = uieditfield(app.MainMapNormalEventsPanel, 'text');
            app.EventsMarkerSizeFunctionEditField.ValueChangedFcn = createCallbackFcn(app, @MainMapEventValueChanged, true);
            app.EventsMarkerSizeFunctionEditField.Value = 'mag2dotsize';
            app.EventsDotsForTooManyCheckBox = uicheckbox(app.MainMapNormalEventsPanel);
            app.EventsDotsForTooManyCheckBox.Text = 'Use dots for more than';
            app.EventsDotsForTooManyCheckBox.Value = true;
            app.EventsTooManyForMarkerEditField = uieditfield(app.MainMapNormalEventsPanel, 'numeric');
            app.EventsTooManyForMarkerEditField.Limits = [0 Inf];
            app.EventsTooManyForMarkerEditField.ValueDisplayFormat = '%.0f';
            app.EventsTooManyForMarkerEditField.Value = 100000;
            app.eventsLabel = uilabel(app.MainMapNormalEventsPanel);
            app.eventsLabel.Text = 'events';
            app.MainMapEventsUIAxes = uiaxes(app.DisplayOptionsTab);
            title(app.MainMapEventsUIAxes, 'Main Map')
            xlabel(app.MainMapEventsUIAxes, 'Lon')
            ylabel(app.MainMapEventsUIAxes, 'Lat')
            app.MainMapEventsUIAxes.XLim = [0 10];
            app.MainMapEventsUIAxes.YLim = [40 50];
            app.UnselectedEventsPanel = uipanel(app.DisplayOptionsTab);
            app.UnselectedEventsPanel.Title = 'Unselected Events';
            app.MarkerEdgeColorLabel_3 = uilabel(app.UnselectedEventsPanel);
            app.MarkerEdgeColorLabel_3.Text = 'Marker Edge Color';
            app.UnselectedEventsMarkerEdgeColorDropDown = uidropdown(app.UnselectedEventsPanel);
            app.UnselectedEventsMarkerEdgeColorDropDown.Items = {'none', 'flat', 'gray', 'USER DEFINED'};
            app.UnselectedEventsMarkerEdgeColorDropDown.Editable = 'on';
            app.UnselectedEventsMarkerEdgeColorDropDown.Value = 'gray';
            app.MarkerFaceColorLabel_3 = uilabel(app.UnselectedEventsPanel);
            app.MarkerFaceColorLabel_3.Text = 'Marker Face Color';
            app.UnselectedEventsMarkerFaceColorDropDown = uidropdown(app.UnselectedEventsPanel);
            app.UnselectedEventsMarkerFaceColorDropDown.Items = {'auto', 'flat', 'none', 'black', 'USER DEFINED'};
            app.UnselectedEventsMarkerFaceColorDropDown.Editable = 'on';
            app.UnselectedEventsMarkerFaceColorDropDown.Value = 'none';
            app.MarkerSizeEditFieldLabel_4 = uilabel(app.UnselectedEventsPanel);
            app.MarkerSizeEditFieldLabel_4.Enable = 'off';
            app.MarkerSizeEditFieldLabel_4.Text = 'Marker Size';
            app.UnselectedEventsMarkerSizeEditField = uieditfield(app.UnselectedEventsPanel, 'numeric');
            app.UnselectedEventsMarkerSizeEditField.LowerLimitInclusive = 'off';
            app.UnselectedEventsMarkerSizeEditField.UpperLimitInclusive = 'off';
            app.UnselectedEventsMarkerSizeEditField.Limits = [0 Inf];
            app.UnselectedEventsMarkerSizeEditField.Enable = 'off';
            app.UnselectedEventsMarkerSizeEditField.Value = 6;
            app.LineWidthEditFieldLabel_5 = uilabel(app.UnselectedEventsPanel);
            app.LineWidthEditFieldLabel_5.Text = 'Line Width';
            app.UnselectedEventsLineWidthEditField = uieditfield(app.UnselectedEventsPanel, 'numeric');
            app.UnselectedEventsLineWidthEditField.LowerLimitInclusive = 'off';
            app.UnselectedEventsLineWidthEditField.UpperLimitInclusive = 'off';
            app.UnselectedEventsLineWidthEditField.Limits = [0 Inf];
            app.UnselectedEventsLineWidthEditField.Value = 3;
            app.MarkerLabel_5 = uilabel(app.UnselectedEventsPanel);
            app.MarkerLabel_5.Text = 'Marker';
            app.UnselectedEventsMarkerDropDown = uidropdown(app.UnselectedEventsPanel);
            app.UnselectedEventsMarkerDropDown.Items = {'+', 'o', '*', '.', 'x', 'square', 'diamond', 'v', '^', '>', '<', 'pentagram', 'hexagram', 'none'};
            app.UnselectedEventsMarkerDropDown.Value = '.';
            app.FeaturesTab = uitab(app.MainTabGroup);
            app.FeaturesTab.Title = 'Features';
            app.FeaturesListBoxLabel = uilabel(app.FeaturesTab);
            app.FeaturesListBoxLabel.Text = 'Features';
            app.FeaturesListBox = uilistbox(app.FeaturesTab);
            app.FeaturesListBox.Items = {};
            app.FeaturesListBox.ValueChangedFcn = createCallbackFcn(app, @FeaturesListBoxValueChanged, true);
            app.FeaturesListBox.Value = {};
            app.Panel = uipanel(app.FeaturesTab);
            app.Panel.Title = 'Panel';
            app.FeatureUseCheckBox = uicheckbox(app.Panel);
            app.FeatureUseCheckBox.ValueChangedFcn = createCallbackFcn(app, @FeatureUseCheckBoxValueChanged, true);
            app.FeatureUseCheckBox.Text = 'Use Feature';
            app.FeatureIDLabel = uilabel(app.Panel);
            app.FeatureIDLabel.Text = 'FeatureID';
            app.DetailLevelDropDownLabel = uilabel(app.Panel);
            app.DetailLevelDropDownLabel.Text = 'Detail Level';
            app.FeatureDetailLevelDropDown = uidropdown(app.Panel);
            app.FeatureDetailLevelDropDown.Items = {'h', 'l', 'p'};
            app.FeatureDetailLevelDropDown.ValueChangedFcn = createCallbackFcn(app, @FeaturePropertyValueChanged, true);
            app.FeatureDetailLevelDropDown.Enable = 'off';
            app.FeatureDetailLevelDropDown.Value = 'h';
            app.FeatureHitTestCheckBox = uicheckbox(app.Panel);
            app.FeatureHitTestCheckBox.ValueChangedFcn = createCallbackFcn(app, @FeaturePropertyValueChanged, true);
            app.FeatureHitTestCheckBox.Enable = 'off';
            app.FeatureHitTestCheckBox.Text = 'HitTest';
            app.FeatureLinePanel = uipanel(app.Panel);
            app.FeatureLinePanel.Title = 'Line';
            app.WidthEditFieldLabel = uilabel(app.FeatureLinePanel);
            app.WidthEditFieldLabel.Text = 'Width';
            app.FeatureLineWidthEditField = uieditfield(app.FeatureLinePanel, 'numeric');
            app.FeatureLineWidthEditField.ValueChangedFcn = createCallbackFcn(app, @FeaturePropertyValueChanged, true);
            app.ColorEditFieldLabel = uilabel(app.FeatureLinePanel);
            app.ColorEditFieldLabel.Text = 'Color';
            app.FeatureLineColorEditField = uieditfield(app.FeatureLinePanel, 'text');
            app.FeatureLineColorEditField.ValueChangedFcn = createCallbackFcn(app, @FeaturePropertyValueChanged, true);
            app.StyleLabel = uilabel(app.FeatureLinePanel);
            app.StyleLabel.Text = 'Style';
            app.FeatureLineStyleDropDown = uidropdown(app.FeatureLinePanel);
            app.FeatureLineStyleDropDown.Items = {'-', '--', ':', '-.', 'none'};
            app.FeatureLineStyleDropDown.ValueChangedFcn = createCallbackFcn(app, @FeaturePropertyValueChanged, true);
            app.FeatureLineStyleDropDown.Value = '--';
            app.FeatureMarkerPanel = uipanel(app.Panel);
            app.FeatureMarkerPanel.Title = 'Marker';
            app.MarkerLabel_2 = uilabel(app.FeatureMarkerPanel);
            app.MarkerLabel_2.Text = 'Marker';
            app.FeatureMarkerDropDown = uidropdown(app.FeatureMarkerPanel);
            app.FeatureMarkerDropDown.Items = {'+', 'o', '*', '.', 'x', 'square', 'diamond', 'v', '^', '>', '<', 'pentagram', 'hexagram', 'none'};
            app.FeatureMarkerDropDown.ValueChangedFcn = createCallbackFcn(app, @FeaturePropertyValueChanged, true);
            app.FeatureMarkerDropDown.Value = 'x';
            app.SizeLabel = uilabel(app.FeatureMarkerPanel);
            app.SizeLabel.Text = 'Size';
            app.FeatureMarkerSizeEditField = uieditfield(app.FeatureMarkerPanel, 'numeric');
            app.FeatureMarkerSizeEditField.ValueChangedFcn = createCallbackFcn(app, @FeaturePropertyValueChanged, true);
            app.FeatureMarkerSizeEditField.Value = 10;
            app.FaceColorEditFieldLabel = uilabel(app.FeatureMarkerPanel);
            app.FaceColorEditFieldLabel.Text = 'Face Color';
            app.FeatureFaceColorEditField = uieditfield(app.FeatureMarkerPanel, 'text');
            app.FeatureFaceColorEditField.ValueChangedFcn = createCallbackFcn(app, @FeaturePropertyValueChanged, true);
            app.EdgeColorEditFieldLabel = uilabel(app.FeatureMarkerPanel);
            app.EdgeColorEditFieldLabel.Text = 'Edge Color';
            app.FeatureEdgeColorEditField = uieditfield(app.FeatureMarkerPanel, 'text');
            app.FeatureEdgeColorEditField.ValueChangedFcn = createCallbackFcn(app, @FeaturePropertyValueChanged, true);
            app.TagEditFieldLabel = uilabel(app.Panel);
            app.TagEditFieldLabel.Enable = 'off';
            app.TagEditFieldLabel.Text = 'Tag';
            app.FeatureTagEditField = uieditfield(app.Panel, 'text');
            app.FeatureTagEditField.Editable = 'off';
            app.FeatureTagEditField.Enable = 'off';
            app.PickablePartsDropDownLabel = uilabel(app.Panel);
            app.PickablePartsDropDownLabel.Enable = 'off';
            app.PickablePartsDropDownLabel.Text = 'PickableParts';
            app.FeaturePickablePartsDropDown = uidropdown(app.Panel);
            app.FeaturePickablePartsDropDown.Items = {'visible', 'none', 'all'};
            app.FeaturePickablePartsDropDown.ValueChangedFcn = createCallbackFcn(app, @FeaturePropertyValueChanged, true);
            app.FeaturePickablePartsDropDown.Enable = 'off';
            app.FeaturePickablePartsDropDown.Value = 'visible';
            app.DisplayNameEditFieldLabel = uilabel(app.Panel);
            app.DisplayNameEditFieldLabel.Text = 'DisplayName';
            app.FeatureDisplayNameEditField = uieditfield(app.Panel, 'text');
            app.FeatureDisplayNameEditField.ValueChangedFcn = createCallbackFcn(app, @FeaturePropertyValueChanged, true);
            app.FeatureUIAxes = uiaxes(app.Panel);
            app.FeaturesthatareUsedTextAreaLabel = uilabel(app.FeaturesTab);
            app.FeaturesthatareUsedTextAreaLabel.Text = 'Features that are Used:';
            app.FeaturesthatareUsedTextArea = uitextarea(app.FeaturesTab);
            app.FeaturesthatareUsedTextArea.Editable = 'off';
            app.SaveFeatureButton = uibutton(app.FeaturesTab, 'push');
            app.SaveFeatureButton.ButtonPushedFcn = createCallbackFcn(app, @SaveFeatureButtonPushed, true);
            app.SaveFeatureButton.Text = 'Save Feature';
            app.Label = uilabel(app.FeaturesTab);
            app.Label.Text = 'Changes to plotted features will not be applied until ZMAP is restarted';
            app.ResultsTab = uitab(app.TabGroup);
            app.ResultsTab.Title = 'Results';
            app.NumberofResultContoursLabel = uilabel(app.ResultsTab);
            app.NumberofResultContoursLabel.Text = 'Number of Result Contours';
            app.ResultContourCountEditField = uieditfield(app.ResultsTab, 'numeric');
            app.ResultContourCountEditField.Limits = [1 Inf];
            app.ResultContourCountEditField.ValueDisplayFormat = '%.0f';
            app.ResultContourCountEditField.ValueChangedFcn = createCallbackFcn(app, @valueChanged, true);
            app.ResultContourCountEditField.Value = 20;
            app.ShowSampleRadiusCheckBox = uicheckbox(app.ResultsTab);
            app.ShowSampleRadiusCheckBox.ValueChangedFcn = createCallbackFcn(app, @valueChanged, true);
            app.ShowSampleRadiusCheckBox.Text = 'Show Sample Radius';
            app.SamplingTab = uitab(app.TabGroup);
            app.SamplingTab.Title = 'Sampling';
            app.SampleByButtonGroup = uibuttongroup(app.SamplingTab);
            app.SampleByButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @SampleByButtonGroupSelectionChanged, true);
            app.SampleByButtonGroup.Title = 'Sample By...';
            app.Sampling_UseClosestRadioButton = uiradiobutton(app.SampleByButtonGroup);
            app.Sampling_UseClosestRadioButton.Text = 'N closest Events';
            app.Sampling_UseClosestRadioButton.Value = true;
            app.Sampling_UseRadiusRadioButton = uiradiobutton(app.SampleByButtonGroup);
            app.Sampling_UseRadiusRadioButton.Text = 'All events with given Radius';
            app.Sampling_UseBothRadioButton = uiradiobutton(app.SampleByButtonGroup);
            app.Sampling_UseBothRadioButton.Text = 'Closest events, limited to the max. radius';
            app.ofEventsNEditFieldLabel = uilabel(app.SampleByButtonGroup);
            app.ofEventsNEditFieldLabel.Text = '# of Events (N)';
            app.SamplingMaxEventsEditField = uieditfield(app.SampleByButtonGroup, 'numeric');
            app.SamplingMaxEventsEditField.Limits = [0 Inf];
            app.SamplingMaxEventsEditField.ValueDisplayFormat = '%.0f';
            app.SamplingMaxEventsEditField.ValueChangedFcn = createCallbackFcn(app, @valueChanged, true);
            app.SamplingMaxEventsEditField.Value = 100;
            app.MaximumRadiuskmLabel = uilabel(app.SampleByButtonGroup);
            app.MaximumRadiuskmLabel.Text = 'Maximum Radius [km]';
            app.SampleRadiusEditField = uieditfield(app.SampleByButtonGroup, 'numeric');
            app.SampleRadiusEditField.LowerLimitInclusive = 'off';
            app.SampleRadiusEditField.Limits = [0 Inf];
            app.SampleRadiusEditField.ValueChangedFcn = createCallbackFcn(app, @valueChanged, true);
            app.SampleRadiusEditField.Value = 5;
            app.SamplingDefaultsLabel = uilabel(app.SamplingTab);
            app.SamplingDefaultsLabel.Text = 'Sampling Defaults';
            app.SamplingWeightByDistanceCheckBox = uicheckbox(app.SamplingTab);
            app.SamplingWeightByDistanceCheckBox.ValueChangedFcn = createCallbackFcn(app, @valueChanged, true);
            app.SamplingWeightByDistanceCheckBox.Enable = 'off';
            app.SamplingWeightByDistanceCheckBox.Text = 'Weight Samples by DISTANCE (where appropriate)';
            app.SamplingUIAxes = uiaxes(app.SamplingTab);
            title(app.SamplingUIAxes, 'Sample')
            xlabel(app.SamplingUIAxes, 'X [km]')
            ylabel(app.SamplingUIAxes, 'Y [km]')
            app.SaveAllButton = uibutton(app.ZMAPPreferencesUIFigure, 'push');
            app.SaveAllButton.ButtonPushedFcn = createCallbackFcn(app, @SaveAllButtonPushed, true);
            app.SaveAllButton.Text = 'Save All';
            app.RevertTabButton = uibutton(app.ZMAPPreferencesUIFigure, 'push');
            app.RevertTabButton.ButtonPushedFcn = createCallbackFcn(app, @RevertTabButtonPushed, true);
            app.RevertTabButton.Text = 'Revert Tab';
            app.SaveTabButton = uibutton(app.ZMAPPreferencesUIFigure, 'push');
            app.SaveTabButton.ButtonPushedFcn = createCallbackFcn(app, @SaveTabButtonPushed, true);
            app.SaveTabButton.Text = 'Save Tab';
            app.CancelButton = uibutton(app.ZMAPPreferencesUIFigure, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = 'Cancel';
        end
    end

    methods (Access = public)

        % Construct app
        function app = ZmapSettings

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ZMAPPreferencesUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ZMAPPreferencesUIFigure)
        end
    end
end