classdef catalog_from_workbench < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        TabGroup                    matlab.ui.container.TabGroup
        FromZmapCatalogTab          matlab.ui.container.Tab
        ChooseCatalogDropDownLabel  matlab.ui.control.Label
        ChooseCatalogDropDown       matlab.ui.control.DropDown
        Label                       matlab.ui.control.Label
        FromTable                   matlab.ui.container.Tab
        ChooseTableDropDownLabel    matlab.ui.control.Label
        ChooseTableDropDown         matlab.ui.control.DropDown
        Label_2                     matlab.ui.control.Label
        FromIndividualVariablesTab  matlab.ui.container.Tab
        TabGroup2                   matlab.ui.container.TabGroup
        SummaryTab                  matlab.ui.container.Tab
        TextArea                    matlab.ui.control.TextArea
        ValuesTab                   matlab.ui.container.Tab
        UITable                     matlab.ui.control.Table
    end

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.TabGroupSelectionChanged([]);
        end

        % Selection change function: TabGroup
        function TabGroupSelectionChanged(app, event)
            selectedTab = app.TabGroup.SelectedTab;
            w = evalin('base','whos');
            switch selectedTab
                case app.FromZmapCatalogTab
                    w=w({w.class}=="ZmapCatalog");
                    app.ChooseCatalogDropDown.Items={w.name};
                    vname=app.ChooseCatalogDropDown.Value;
                    app.TextArea.Value=evalin('base',vname +  ".summary('stats')");
                case app.FromTable
                    w=w({w.class}=="table");
                    app.ChooseTableDropDown.Items={w.name};
                    vname=app.ChooseTableDropDown.Value;
                    v=evalin('base',vname);
                    app.TextArea.Value=strcat(...
                        [height(v)+ " rows, with Columns:" ,...
                        strcat(v.Properties.VariableNames,newline)], newline);
                case app.FromIndividualVariablesTab
            end
        end

        % Value changed function: TextArea
        function TextAreaValueChanged(app, event)
            
        end

        % Value changed function: ChooseCatalogDropDown
        function ChooseCatalogDropDownValueChanged(app, event)
            value = app.ChooseCatalogDropDown.Value;
            app.TextArea.Value=evalin('base', value +  ".summary('stats')");
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.TabGroup.SelectionChangedFcn = createCallbackFcn(app, @TabGroupSelectionChanged, true);
            app.FromZmapCatalogTab = uitab(app.TabGroup);
            app.FromZmapCatalogTab.Title = 'From ZmapCatalog';
            app.ChooseCatalogDropDownLabel = uilabel(app.FromZmapCatalogTab);
            app.ChooseCatalogDropDownLabel.Text = 'Choose Catalog';
            app.ChooseCatalogDropDown = uidropdown(app.FromZmapCatalogTab);
            app.ChooseCatalogDropDown.ValueChangedFcn = createCallbackFcn(app, @ChooseCatalogDropDownValueChanged, true);
            app.Label = uilabel(app.FromZmapCatalogTab);
            app.Label.Text = 'The dropdown contains variables from the workspace that are ZmapCatalogs';
            app.FromTable = uitab(app.TabGroup);
            app.FromTable.Title = 'From Table';
            app.ChooseTableDropDownLabel = uilabel(app.FromTable);
            app.ChooseTableDropDownLabel.Text = 'Choose Table';
            app.ChooseTableDropDown = uidropdown(app.FromTable);
            app.Label_2 = uilabel(app.FromTable);
            app.Label_2.Text = 'The dropdown contains variables from the workspace that are Tables';
            app.FromIndividualVariablesTab = uitab(app.TabGroup);
            app.FromIndividualVariablesTab.Title = 'From Individual Variables';
            app.TabGroup2 = uitabgroup(app.UIFigure);
            app.SummaryTab = uitab(app.TabGroup2);
            app.SummaryTab.Title = 'Summary';
            app.TextArea = uitextarea(app.SummaryTab);
            app.TextArea.ValueChangedFcn = createCallbackFcn(app, @TextAreaValueChanged, true);
            app.TextArea.Editable = 'off';
            app.ValuesTab = uitab(app.TabGroup2);
            app.ValuesTab.Title = 'Values';
            app.UITable = uitable(app.ValuesTab);
            app.UITable.ColumnName = {'Date'; 'longitude'; 'Latitude'; 'Depth'; 'Magnitude'; 'MagType'};
            app.UITable.RowName = {};
        end
    end

    methods (Access = public)

        % Construct app
        function app = catalog_from_workbench

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end