classdef QuickCatalogCrop < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SplitorCropbasedonlastclickUIFigure  matlab.ui.Figure
        CancelButton         matlab.ui.control.Button
        CROPButton           matlab.ui.control.Button
        XFieldDropDownLabel  matlab.ui.control.Label
        XFieldDropDown       matlab.ui.control.DropDown
        YFieldDropDownLabel  matlab.ui.control.Label
        YFieldDropDown       matlab.ui.control.DropDown
        XValueEditField      matlab.ui.control.EditField
        YValueEditField      matlab.ui.control.EditField
        SelectedeventsLabel  matlab.ui.control.Label
        SPLITButton          matlab.ui.control.Button
        ForSPLITselectionbecomesCatalogAunselectedbecomesCatalogBLabel  matlab.ui.control.Label
    end


    properties (Access = private)
        details % Description
        XCatalogField % Description
        YCatalogField % Description
        SelectedEventsFmt % Description
    end

    methods (Access = private)
    
        function results = LabelToFieldName(app, name)
            
            name = split(name);
            name=name{1}
            
            fn = properties(app.details.fig.UserData.catalog);
            idx = startsWith(fn,name);
            if any(idx)
                results = fn{idx};
            elseif startsWith(name,'Time','IgnoreCase',true)
                results = 'Date';
                return
            else
                results='';
            end
            disp(results)
        end
        
        function results = selectedEvents(app, catalog)
            Yop = app.YFieldDropDown.Value;
            Xop = app.XFieldDropDown.Value;
            results = true(catalog.Count,1);
            
            if ~isempty(app.XCatalogField)
                Xclass = str2func( class(catalog.(app.XCatalogField)) );
                XVal = Xclass(string(app.XValueEditField.Value));
                XSelected = Xop(catalog.(app.XCatalogField), XVal);
                results = results & XSelected;
            end
            
            if ~isempty(app.YCatalogField)
                Yclass = str2func( class(catalog.(app.YCatalogField)) );
                YVal = Yclass(string(app.YValueEditField.Value));
                YSelected = Yop(catalog.(app.YCatalogField),YVal);
                results = results & YSelected;
            end
        end
        
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.details = callbacks.cropBasedOnAxis(); % get latest axes click.
            if isempty(app.details)
                disp('closing. no last-click details exist');
            end
            if ~isfield(app.details,'X')
                warning('no point had been recongized, so crop is disabled');
                delete(app);
                return
            end
            app.XCatalogField = app.LabelToFieldName(app.details.X.Name);
            app.YCatalogField = app.LabelToFieldName(app.details.Y.Name);
            
            app.XFieldDropDownLabel.Text = app.XCatalogField; %app.details.X.Name;
            app.XValueEditField.Value = char(string(app.details.X.Value));
            enableValue = matlab.lang.OnOffSwitchState(~isempty(app.XCatalogField));
            set([app.XValueEditField app.XFieldDropDown],'Enable', char(enableValue));
            
            app.YFieldDropDownLabel.Text = app.YCatalogField;
            app.YValueEditField.Value = char(string(app.details.Y.Value));
            enableValue = matlab.lang.OnOffSwitchState(~isempty(app.YCatalogField));
            set([app.YValueEditField app.YFieldDropDown],'Enable', char(enableValue));
            
            app.SelectedEventsFmt = app.SelectedeventsLabel.Text;
            idatalist = {@(vals,~)true(size(vals)), @lt, @le, @eq, @gt, @ge, @ne};
            app.XFieldDropDown.ItemsData=idatalist;
            app.YFieldDropDown.ItemsData=idatalist;
            app.XYFieldDropDownValueChanged([]);
        end

        % Button pushed function: CROPButton
        function CROPButtonPushed(app, event)
            zmw = app.details.fig.UserData; % get ZmapMainWindow
            
            zmw.rawcatalog.subsetInPlace(app.selectedEvents(zmw.rawcatalog));
            %zmw.catalog.subsetInPlace(app.selectedEvents(zmw.catalog));
            %zmw.catalog=zmw.catalog.subset(app.selectedEvents(zmw.catalog));
            zmw.notify('CatalogChanged');
            delete(app);
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            delete(app);
        end

        % Value changed function: XFieldDropDown, XValueEditField, 
        % YFieldDropDown, YValueEditField
        function XYFieldDropDownValueChanged(app, event)
            zmw = app.details.fig.UserData; % get ZmapMainWindow
            nEvents = zmw.catalog.Count;
            nSelected = sum( app.selectedEvents(zmw.catalog) ) ;
            app.SelectedeventsLabel.Text=sprintf(app.SelectedEventsFmt, nSelected, nEvents);
                    
        end

        % Button pushed function: SPLITButton
        function SPLITButtonPushed(app, event)
            zmw = app.details.fig.UserData; % get ZmapMainWindow
            ZG = ZmapGlobal.Data;
            ZG.catalogs.set('catalogA',zmw.rawcatalog.subset(app.selectedEvents(zmw.rawcatalog)));
            ZG.catalogs.set('catalogB',zmw.rawcatalog.subset(~app.selectedEvents(zmw.rawcatalog)));
            zmw.notify('CatalogChanged');
            delete(app);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.SplitorCropbasedonlastclickUIFigure = uifigure;
            app.SplitorCropbasedonlastclickUIFigure.Name = 'Split or Crop based on last click';
            app.CancelButton = uibutton(app.SplitorCropbasedonlastclickUIFigure, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = 'Cancel';
            app.CROPButton = uibutton(app.SplitorCropbasedonlastclickUIFigure, 'push');
            app.CROPButton.ButtonPushedFcn = createCallbackFcn(app, @CROPButtonPushed, true);
            app.CROPButton.Text = 'CROP';
            app.XFieldDropDownLabel = uilabel(app.SplitorCropbasedonlastclickUIFigure);
            app.XFieldDropDownLabel.Text = 'X Field';
            app.XFieldDropDown = uidropdown(app.SplitorCropbasedonlastclickUIFigure);
            app.XFieldDropDown.Items = {'no filter', '<', '<=', '==', '>=', '>', '~='};
            app.XFieldDropDown.ValueChangedFcn = createCallbackFcn(app, @XYFieldDropDownValueChanged, true);
            app.XFieldDropDown.Value = 'no filter';
            app.YFieldDropDownLabel = uilabel(app.SplitorCropbasedonlastclickUIFigure);
            app.YFieldDropDownLabel.Text = 'Y Field';
            app.YFieldDropDown = uidropdown(app.SplitorCropbasedonlastclickUIFigure);
            app.YFieldDropDown.Items = {'no filter', '<', '<=', '==', '>=', '>', '~='};
            app.YFieldDropDown.ValueChangedFcn = createCallbackFcn(app, @XYFieldDropDownValueChanged, true);
            app.YFieldDropDown.Value = 'no filter';
            app.XValueEditField = uieditfield(app.SplitorCropbasedonlastclickUIFigure, 'text');
            app.XValueEditField.ValueChangedFcn = createCallbackFcn(app, @XYFieldDropDownValueChanged, true);
            app.YValueEditField = uieditfield(app.SplitorCropbasedonlastclickUIFigure, 'text');
            app.YValueEditField.ValueChangedFcn = createCallbackFcn(app, @XYFieldDropDownValueChanged, true);
            app.SelectedeventsLabel = uilabel(app.SplitorCropbasedonlastclickUIFigure);
            app.SelectedeventsLabel.Text = 'Selected %d of %d events';
            app.SPLITButton = uibutton(app.SplitorCropbasedonlastclickUIFigure, 'push');
            app.SPLITButton.ButtonPushedFcn = createCallbackFcn(app, @SPLITButtonPushed, true);
            app.SPLITButton.Text = 'SPLIT';
            app.ForSPLITselectionbecomesCatalogAunselectedbecomesCatalogBLabel = uilabel(app.SplitorCropbasedonlastclickUIFigure);
            app.ForSPLITselectionbecomesCatalogAunselectedbecomesCatalogBLabel.Text = {'For SPLIT:'; 'selection becomes CatalogA'; 'unselected becomes CatalogB'; ''};
        end
    end

    methods (Access = public)

        % Construct app
        function app = QuickCatalogCrop

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.SplitorCropbasedonlastclickUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SplitorCropbasedonlastclickUIFigure)
        end
    end
end