classdef grid_chooser < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ChooseGridParametersUIFigure  matlab.ui.Figure
        GridNameEditFieldLabel        matlab.ui.control.Label
        GridNameEditField             matlab.ui.control.EditField
        AnchorPointPanel              matlab.ui.container.Panel
        XorLonEditFieldLabel          matlab.ui.control.Label
        XAnchor                       matlab.ui.control.NumericEditField
        YorLatEditFieldLabel          matlab.ui.control.Label
        YAnchor                       matlab.ui.control.NumericEditField
        ZdownEditFieldLabel           matlab.ui.control.Label
        ZAnchor                       matlab.ui.control.NumericEditField
        SeparationPanel               matlab.ui.container.Panel
        dxEditFieldLabel              matlab.ui.control.Label
        dx                            matlab.ui.control.NumericEditField
        dyEditFieldLabel              matlab.ui.control.Label
        dy                            matlab.ui.control.NumericEditField
        dzkmLabel                     matlab.ui.control.Label
        dz                            matlab.ui.control.NumericEditField
        UnitsDropDownLabel            matlab.ui.control.Label
        UnitsDropDown                 matlab.ui.control.DropDown
        FollowMeridiansCheckBox       matlab.ui.control.CheckBox
        LinkDxDyButton                matlab.ui.control.Button
        dxdylinkedLabel               matlab.ui.control.Label
        OKButton                      matlab.ui.control.Button
        UIAxes                        matlab.ui.control.UIAxes
        LoadButton                    matlab.ui.control.Button
        SaveOKButton                  matlab.ui.control.Button
        CancelButton                  matlab.ui.control.Button
        RefEllipsoidLabel             matlab.ui.control.Label
    end


    properties (Access = private)
        AnchorPoint % Grid initiates at this point
        GridPoints % All points for the grid
        GridDirectory % Load and Save location for grid
        RefEllipsoid referenceEllipsoid = referenceEllipsoid('wgs84','kilometer');
        Shape ShapeGeneral % shape from main window
    end
    properties
        ResultDump =[] % function to send results to as (g, gridopt)
    end
    
    properties (Dependent)
        GridOpts % Grid options
    end

    methods
        function gridopt = get.GridOpts(app)
            % create the GridOptions object
            if app.UnitsDropDown.Value == "degree"
                assert(app.RefEllipsoid.LengthUnit=="kilometer")
                gridopt = GridOptions('XY',[deg2km([app.dx.Value, app.dy.Value]), app.dz.Value],...
                    app.RefEllipsoid,  'FollowMeridians', app.FollowMeridiansCheckBox.Value);
            else
                gridopt = GridOptions('XY',[app.dx.Value, app.dy.Value, app.dz.Value],...
                    app.RefEllipsoid, 'FollowMeridians',app.FollowMeridiansCheckBox.Value);
            end
        end
        
        function set.GridOpts(app, gridopt)
            
            app.dx.Value = gridopt.dx;
            app.dy.Value = gridopt.dy;
            
            if ~ isempty(gridopt.dz)
                app.dz.Value = gridopt.dz;
            else
                app.dz.Value = 1;
            end
            
            if startsWith(gridopt.horizUnits,"deg",'IgnoreCase',true)
                app.UnitsDropDown.Value = 'degree';
            else
                app.UnitsDropDown.Value = validateLengthUnit(gridopt.horizUnits);
            end
            
            app.FollowMeridiansCheckBox.Value = logical(gridopt.followMeridians);
            app.UpdateGridPoints;
        end
        
        function set.ResultDump(app, fcn)
            assert(isa(fcn,'function_handle') || isempty(fcn))
            assert(nargin(fcn)==2 || isempty(fcn));
            app.ResultDump=fcn;
        end
        
    end
    
    methods (Access = private)
    
        function [lonMat, latMat] = get_eq_grid(app)
            lon0 = app.XAnchor.Value;
            lat0 = app.YAnchor.Value;
            dLon = app.dx.Value;
            dLat = app.dy.Value;
            % GET_EQ_GRID
            % input is the origin point and arclength between points
            % output is 2 matrices (lon, lat)
            
            if app.UnitsDropDown.Value ~= "degree"
                myratio = unitsratio('kilometer', app.UnitsDropDown.Value); % unitsratio(TO , FROM)
                dLon_deg = km2deg(dLon .* myratio);
                dLat_deg = km2deg(dLat .* myratio);
            else
                dLon_deg = dLon;
                dLat_deg = dLat;
            end
                
            % use the axes limits (assumed degrees) to control size of grid
            ylims_deg = app.UIAxes.YLim;
            xlims_deg = app.UIAxes.XLim;
            
            lonMat=[];
            latMat=[];
            
            nPointsApprox=round(((range(ylims_deg) / dLat_deg) * (range(xlims_deg) / dLon_deg)));
            if nPointsApprox > 1e6 || nPointsApprox < 4
                if nPointsApprox > 1e6
                    msg = sprintf('Too many points ~[%d]. Grid will not recalculate until larger dx or dy are given',nPointsApprox);
                else
                    msg = sprintf('Too few points [%d]. Grid will not recalculate until smaller dx or dy are given',nPointsApprox);
                    dbstack
                end
                uialert(app.ChooseGridParametersUIFigure,msg,'Invalid grid');
                app.UIAxes.BackgroundColor=[1 0.95 0.95];
                app.dx.BackgroundColor=[1 0.95 0.95];
                app.dy.BackgroundColor=[1 0.95 0.95];
                return
            else
                app.UIAxes.BackgroundColor=[1 1 1];
                app.dx.BackgroundColor=[1 1 1];
                app.dy.BackgroundColor=[1 1 1];
            end
            
            % pick out latitude spacing. Our grid will have this many rows.
            lats = app.vector_including_origin(lat0, dLat_deg, ylims_deg);
            
            if app.UnitsDropDown.Value == "degree"
                reckoning_fn =@(la) reckon('rh',la, 0, dLon_deg, 90);
            else
                reckoning_fn =@(la) reckon('rh',la, 0, dLon, 90, app.RefEllipsoid);
            end

            if app.FollowMeridiansCheckBox.Visible=="on" &&  app.FollowMeridiansCheckBox.Value
                % when following the meridian lines, the longitude span covered by the arc-distance at lat0 (along the rhumb!) remains constant.
                [~,dLon] = reckoning_fn(lat0);
                lonValues = app.vector_including_origin(lon0, dLon_deg, xlims_deg);
                [lonMat,latMat]=meshgrid(lonValues,lats); % size: lonValues x lats
                
            else
                % when ignoring meridian lines, and aiming for an approximately constant distance, the dLon at each latitude will differ.
                [~,dLon_per_lat] = reckoning_fn(lats);
                    
                for n=1:numel(lats)
                    theseLonValues = app.vector_including_origin(lon0, dLon_per_lat(n), xlims_deg);
                    lonMat=[lonMat;theseLonValues(:)]; %#ok<AGROW>
                    latMat=[latMat;repmat(lats(n),size(theseLonValues(:)))]; %#ok<AGROW>
                end
                
                [lonMat,latMat] = cols2matrix(app.XAnchor.Value, lonMat,latMat);
                % each gridx & gridy are vectors.
            end
            
        end
        
            
        function v = vector_including_origin(~, orig_deg, delta_deg, lims_deg)
            v = unique([orig_deg : -delta_deg : min(lims_deg) , orig_deg : delta_deg :max(lims_deg)]);
        end
        
        
        function g = CreateGrid(app)
            if app.UnitsDropDown.Value == "degree"
                dxdy = [deg2km(app.dx.Value),deg2km(app.dy.Value)];
            else
                assert(app.RefEllipsoid.LengthUnit==string(app.UnitsDropDown.Value))
                dxdy = [app.dx.Value, app.dy.Value];
            end
            gopParams = {'XY',dxdy,...
                app.RefEllipsoid,'FollowMeridians',app.FollowMeridiansCheckBox.Value,'GridEntireArea',false};
            
            gop = GridOptions(gopParams{:});
            gop.FixedAnchorPoint = [app.XAnchor.Value, app.YAnchor.Value];
            
            g = ZmapGrid(app.GridNameEditField.Value,"FromGridOptions", gop, "Shape",app.Shape);
        end
        
        function SetGridOptions(app, g, gridopt)
            app.GridNameEditField.Value = g.Name;
            switch lower(g.Units)
                case 'degree'
                    app.UnitsDropDown.Value='degree';
                otherwise
                    app.UnitsDropDown.Value=validateLengthUnit(g.Units);
            end
            app.dx.Value = gridopt.dx;
            app.dy.Value = gridopt.dy;
            app.dz.Value = gridopt.dz;
            app.FollowMeridiansCheckBox.Value = startsWith(gridopt.horizUnits, "deg", 'IgnoreCase',true);
            disp(gridopt);
        end
        
        function DumpTheResult(app)
            if ~isempty(app.ResultDump)
                app.ResultDump(app.CreateGrid, app.GridOpts)
            end
            
        end
        
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, ellipsoid, gridopt, shape)
            ZG = ZmapGlobal.Data;
            if exist('ellipsoid','var')
                app.RefEllipsoid = ellipsoid;
            end
            app.RefEllipsoidLabel.Text = "Ref: " + app.RefEllipsoid.Name;
            app.GridDirectory = fullfile(ZG.Directories.data,'grids');
            if ~exist('gridopt','var') || isempty(gridopt)
                gridopt = ZG.gridopt;
            end
            if exist('shape','var')
                app.Shape = shape;
            end
            app.dx.Value = gridopt.dx;
            app.dy.Value = gridopt.dy;
            app.dz.Value = gridopt.dz;
            app.dxdylinkedLabel.Visible = app.dx.Value == app.dy.Value;
            app.LinkDxDyButton.UserData = logical(~app.dxdylinkedLabel.Visible); % make valu wrong, then
            app.LinkDxDyButton.ButtonPushedFcn(app, struct('Source',app.LinkDxDyButton)); % sync it up
            
            % synchronize the reference ellipsoid, length units, and the units drop-down
            if startsWith(gridopt.horizUnits,"deg",'IgnoreCase',true)
                len_unit = 'degree';
                app.RefEllipsoid.LengthUnit = 'kilometer';
                app.FollowMeridiansCheckBox.Visible = 'on';
            else
                len_unit = validateLengthUnit(gridopt.horizUnits);
                app.RefEllipsoid.LengthUnit = len_unit;
                app.FollowMeridiansCheckBox.Visible = 'off';
            end
   
            app.UnitsDropDown.Value = app.UnitsDropDown.Items{strcmpi(app.UnitsDropDown.Items,len_unit)};
            
            mapax= findobj(gcf,'Tag','mainmap_ax');
            if ~isempty(mapax)
                % could use linkprop to keep synced
                app.UIAxes.XLim = mapax.XLim;
                app.UIAxes.YLim = mapax.YLim;
                app.UIAxes.DataAspectRatio = mapax.DataAspectRatio;
            elseif ~isempty(ZG.primeCatalog)
                app.UIAxes.XLim = [min(ZG.primeCatalog.Longitude) max(ZG.primeCatalog.Longitude)];
                app.UIAxes.YLim = [min(ZG.primeCatalog.Latitude) max(ZG.primeCatalog.Latitude)];
            else
                app.UIAxes.XLim = [-5 5];
                app.UIAxes.YLim = [-5 5];
            end
            if ~isempty(app.Shape)
                hold(app.UIAxes,'on');
                plot(app.UIAxes, app.Shape.X, app.Shape.Y, 'Color', [.7 .7 .85], 'Tag', 'shape');
            end
                
            
            g = ZG.Grid;
            if ~isempty(g.Name)
                app.GridNameEditField.Value=g.Name;
            end
            if isfield(g,'Origin') && ~isempty(g.Origin)
                app.XAnchor.Value=origin(1);
                app.YAnchor.Value=origin(2);
                if length(g.Origin)==3
                    app.ZAnchor.Value=origin(3);
                end
            else
                app.XAnchor.Value=round(mean(app.UIAxes.XLim),3);
                app.YAnchor.Value=round(mean(app.UIAxes.YLim),3);
            end
            
            app.AnchorPoint = scatter(app.UIAxes, app.XAnchor.Value, app.YAnchor.Value,100,'x','LineWidth',2);
            app.AnchorPointPanel.ForegroundColor=app.AnchorPoint.CData;
            
            hold(app.UIAxes,'on');

            [lonMat, latMat] = app.get_eq_grid;
            app.GridPoints = scatter(app.UIAxes, lonMat(:), latMat(:), 12,'+','LineWidth',1);
            
            hold(app.UIAxes,'off');
                
        end

        % Value changed function: UnitsDropDown
        function UnitsDropDownValueChanged(app, event)
            % three distinct regimes exist.
            % 1. going from a length to a degree
            % 2. going from a degree to a length
            % 3. going from one length to another
            old_value = event.PreviousValue;
            new_value = app.UnitsDropDown.Value;
            
            if old_value == "degree"
                % convert to new value
                app.dx.Limits = [0 inf];
                app.dy.Limits = [0 inf];
                my_ratio = unitsratio(new_value, app.RefEllipsoid.LengthUnit);
                app.dx.Value  = deg2km(app.dx.Value) * my_ratio;
                app.dy.Value  = deg2km(app.dy.Value) * my_ratio;
                %app.dz.Value  = app.dz.Value * my_ratio;
                app.RefEllipsoid.LengthUnit = new_value;
                app.FollowMeridiansCheckBox.Visible = 'off';
                
            elseif new_value == "degree"
                to_km = unitsratio('kilometer', app.RefEllipsoid.LengthUnit);
                app.FollowMeridiansCheckBox.Visible = 'on';
                app.dx.Value  = km2deg(app.dx.Value .* to_km);
                app.dy.Value  = km2deg(app.dy.Value .* to_km);
                %app.dz.Value  = app.dz.Value * unitsratio('kilometer', old_value);
                app.RefEllipsoid.LengthUnit = 'kilometer';
                app.dx.Limits = [0 30];
                app.dy.Limits = [0 30];
                
            else
                app.dx.Value = app.dx.Value * unitsratio(new_value, old_value);
                app.dy.Value = app.dy.Value * unitsratio(new_value, old_value);
                % app.dz.Value = app.dz.Value * unitsratio(new_value, old_value);
                app.RefEllipsoid.LengthUnit = new_value;
            end
            
            app.UpdateGridPoints()
        end

        % Value changed function: XAnchor, YAnchor
        function UpdateAnchorPoint(app, event)
            app.AnchorPoint.XData = app.XAnchor.Value;
            app.AnchorPoint.YData = app.YAnchor.Value;
            app.UpdateGridPoints;
        end

        % Value changed function: FollowMeridiansCheckBox, dx, dy
        function UpdateGridPoints(app, event)
            if app.dxdylinkedLabel.Visible == "on"
                if event.Source == app.dx
                    app.dy.Value = event.Source.Value;
                elseif event.Source == app.dy 
                    app.dx.Value = event.Source.Value;
                end
            end
            [lonMat, latMat] = app.get_eq_grid;
            app.GridPoints.XData=lonMat(:);
            app.GridPoints.YData=latMat(:);
        end

        % Button pushed function: OKButton
        function OKButtonPushed(app, event)
            ZG = ZmapGlobal.Data;
            ZG.gridopt = app.GridOpts;
            ZG.Grid = app.CreateGrid;
            app.DumpTheResult;
            delete(app)
        end

        % Button pushed function: SaveOKButton
        function SaveOKButtonPushed(app, event)
            % first, save grid
            ZG=ZmapGlobal.Data;
            gridopt = app.GridOpts;
            g = app.CreateGrid;
            app.ChooseGridParametersUIFigure.Visible = 'off';
            [file,path,opt] = uiputfile({'*.mat','MATLAB data file';...
                '*.txt','grid points text'},...
                'Save Grid As',...
                fullfile(app.GridDirectory,[app.GridNameEditField.Value,'_grid']));
            
            app.ChooseGridParametersUIFigure.Visible = 'on';
            switch opt
                case 1 % save both as .mat
                    savename = fullfile(path,file);
                    save(savename,'gridopt','g');
                case 2 % save grid points as text
                    error('unimplemented');
                    
                otherwise
                    % don't save, don't close app
                    return
            end
            app.DumpTheResult;
            % next, press the ok button
            delete(app)
        end

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
            default = fullfile(app.GridDirectory,'default_grid.mat')
            [file, path,opt] = uigetfile({'*.mat','MATLAB data file';...
                '*.txt','grid points text'},...
                'Load Grid',default);
            loaded=load(fullfile(path,file),'gridopt','g');
            app.SetGridOptions(loaded.g, loaded.gridopt);
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            delete(app)
        end

        % Button pushed function: LinkDxDyButton
        function LinkHUnitsValueChanged(app, event)
            value = ~ event.Source.UserData;
            app.dxdylinkedLabel.Visible = matlab.lang.OnOffSwitchState(value);
            if value
                event.Source.FontColor = [0 .55 .55];
            else
                event.Source.FontColor = [0.3 0.3 0.3];
            end
            event.Source.UserData = value;
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ChooseGridParametersUIFigure = uifigure;
            app.ChooseGridParametersUIFigure.Name = 'Choose Grid Parameters';
            app.GridNameEditFieldLabel = uilabel(app.ChooseGridParametersUIFigure);
            app.GridNameEditFieldLabel.Text = 'Grid Name';
            app.GridNameEditField = uieditfield(app.ChooseGridParametersUIFigure, 'text');
            app.GridNameEditField.Value = 'grid';
            app.AnchorPointPanel = uipanel(app.ChooseGridParametersUIFigure);
            app.AnchorPointPanel.Title = 'Anchor Point';
            app.XorLonEditFieldLabel = uilabel(app.AnchorPointPanel);
            app.XorLonEditFieldLabel.Text = 'X or Lon';
            app.XAnchor = uieditfield(app.AnchorPointPanel, 'numeric');
            app.XAnchor.LowerLimitInclusive = 'off';
            app.XAnchor.UpperLimitInclusive = 'off';
            app.XAnchor.ValueChangedFcn = createCallbackFcn(app, @UpdateAnchorPoint, true);
            app.YorLatEditFieldLabel = uilabel(app.AnchorPointPanel);
            app.YorLatEditFieldLabel.Text = 'Y or Lat';
            app.YAnchor = uieditfield(app.AnchorPointPanel, 'numeric');
            app.YAnchor.LowerLimitInclusive = 'off';
            app.YAnchor.UpperLimitInclusive = 'off';
            app.YAnchor.ValueChangedFcn = createCallbackFcn(app, @UpdateAnchorPoint, true);
            app.ZdownEditFieldLabel = uilabel(app.AnchorPointPanel);
            app.ZdownEditFieldLabel.Enable = 'off';
            app.ZdownEditFieldLabel.Text = 'Z (+down)';
            app.ZAnchor = uieditfield(app.AnchorPointPanel, 'numeric');
            app.ZAnchor.LowerLimitInclusive = 'off';
            app.ZAnchor.UpperLimitInclusive = 'off';
            app.ZAnchor.Enable = 'off';
            app.SeparationPanel = uipanel(app.ChooseGridParametersUIFigure);
            app.SeparationPanel.Title = 'Separation';
            app.dxEditFieldLabel = uilabel(app.SeparationPanel);
            app.dxEditFieldLabel.Text = 'dx';
            app.dx = uieditfield(app.SeparationPanel, 'numeric');
            app.dx.LowerLimitInclusive = 'off';
            app.dx.Limits = [0 Inf];
            app.dx.ValueDisplayFormat = '%.3f';
            app.dx.ValueChangedFcn = createCallbackFcn(app, @UpdateGridPoints, true);
            app.dx.Value = 0.5;
            app.dyEditFieldLabel = uilabel(app.SeparationPanel);
            app.dyEditFieldLabel.Text = 'dy';
            app.dy = uieditfield(app.SeparationPanel, 'numeric');
            app.dy.LowerLimitInclusive = 'off';
            app.dy.Limits = [0 Inf];
            app.dy.ValueDisplayFormat = '%.3f';
            app.dy.ValueChangedFcn = createCallbackFcn(app, @UpdateGridPoints, true);
            app.dy.Value = 0.5;
            app.dzkmLabel = uilabel(app.SeparationPanel);
            app.dzkmLabel.Enable = 'off';
            app.dzkmLabel.Text = 'dz [km]';
            app.dz = uieditfield(app.SeparationPanel, 'numeric');
            app.dz.LowerLimitInclusive = 'off';
            app.dz.Limits = [0 Inf];
            app.dz.ValueDisplayFormat = '%.3f';
            app.dz.Enable = 'off';
            app.dz.Value = 1;
            app.UnitsDropDownLabel = uilabel(app.SeparationPanel);
            app.UnitsDropDownLabel.Text = 'Units';
            app.UnitsDropDown = uidropdown(app.SeparationPanel);
            app.UnitsDropDown.Items = {'degree', 'kilometer', 'meter', 'millimeter', 'micron'};
            app.UnitsDropDown.ValueChangedFcn = createCallbackFcn(app, @UnitsDropDownValueChanged, true);
            app.UnitsDropDown.Value = 'degree';
            app.FollowMeridiansCheckBox = uicheckbox(app.SeparationPanel);
            app.FollowMeridiansCheckBox.ValueChangedFcn = createCallbackFcn(app, @UpdateGridPoints, true);
            app.FollowMeridiansCheckBox.Text = 'Follow Meridians';
            app.LinkDxDyButton = uibutton(app.SeparationPanel, 'push');
            app.LinkDxDyButton.ButtonPushedFcn = createCallbackFcn(app, @LinkHUnitsValueChanged, true);
            app.LinkDxDyButton.Text = '{';
            app.dxdylinkedLabel = uilabel(app.SeparationPanel);
            app.dxdylinkedLabel.Enable = 'off';
            app.dxdylinkedLabel.Text = 'dx, dy linked';
            app.OKButton = uibutton(app.ChooseGridParametersUIFigure, 'push');
            app.OKButton.ButtonPushedFcn = createCallbackFcn(app, @OKButtonPushed, true);
            app.OKButton.Text = 'OK';
            app.UIAxes = uiaxes(app.ChooseGridParametersUIFigure);
            title(app.UIAxes, 'Sample Grid Spacing')
            xlabel(app.UIAxes, 'Longitude')
            ylabel(app.UIAxes, 'Latitude')
            app.UIAxes.XLim = [0 1];
            app.UIAxes.YLim = [0 1];
            app.UIAxes.CLim = [0 1];
            app.UIAxes.XTick = [0 0.2 0.4 0.6 0.8 1];
            app.UIAxes.YTick = [0 0.2 0.4 0.6 0.8 1];
            app.LoadButton = uibutton(app.ChooseGridParametersUIFigure, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Load';
            app.SaveOKButton = uibutton(app.ChooseGridParametersUIFigure, 'push');
            app.SaveOKButton.ButtonPushedFcn = createCallbackFcn(app, @SaveOKButtonPushed, true);
            app.SaveOKButton.Text = 'Save & OK';
            app.CancelButton = uibutton(app.ChooseGridParametersUIFigure, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = 'Cancel';
            app.RefEllipsoidLabel = uilabel(app.ChooseGridParametersUIFigure);
            app.RefEllipsoidLabel.Text = 'Using:';
        end
    end

    methods (Access = public)

        % Construct app
        function app = grid_chooser(varargin)

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ChooseGridParametersUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ChooseGridParametersUIFigure)
        end
    end
end