classdef fdsn_chooser < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ImportfromFDSNwebserviceUIFigure  matlab.ui.Figure
        ProviderDropDownLabel        matlab.ui.control.Label
        ProviderDropDown             matlab.ui.control.DropDown
        TimeConstraintsPanel         matlab.ui.container.Panel
        StartTime                    matlab.ui.control.EditField
        EndTime                      matlab.ui.control.EditField
        timeformathintLabel          matlab.ui.control.Label
        toLabel                      matlab.ui.control.Label
        MagnitudeConstraintsPanel    matlab.ui.container.Panel
        MinMagField                  matlab.ui.control.NumericEditField
        MaxMagField                  matlab.ui.control.NumericEditField
        MagnitudeTypesListEditField  matlab.ui.control.EditField
        MLabel                       matlab.ui.control.Label
        ProviderInformationLabel     matlab.ui.control.Label
        GeographicConstraintsPanel   matlab.ui.container.Panel
        TabGroup                     matlab.ui.container.TabGroup
        NoneTab                      matlab.ui.container.Tab
        DatawillnotbefilteredgeographicallyLabel  matlab.ui.control.Label
        RectangularSelectionTab      matlab.ui.container.Tab
        NorthEditFieldLabel          matlab.ui.control.Label
        NorthEditField               matlab.ui.control.NumericEditField
        WestEditFieldLabel           matlab.ui.control.Label
        WestEditField                matlab.ui.control.NumericEditField
        SouthEditFieldLabel          matlab.ui.control.Label
        SouthEditField               matlab.ui.control.NumericEditField
        EastEditFieldLabel           matlab.ui.control.Label
        EastEditField                matlab.ui.control.NumericEditField
        RectMap                      matlab.ui.control.UIAxes
        RadialSelectionTab           matlab.ui.container.Tab
        CircAxes                     matlab.ui.control.UIAxes
        OriginLabel                  matlab.ui.control.Label
        LongitudeEditFieldLabel      matlab.ui.control.Label
        LongitudeEditField           matlab.ui.control.NumericEditField
        LatitudeEditFieldLabel       matlab.ui.control.Label
        LatitudeEditField            matlab.ui.control.NumericEditField
        maxRadiusEditFieldLabel      matlab.ui.control.Label
        maxRadiusEditField           matlab.ui.control.NumericEditField
        minRadiusEditFieldLabel      matlab.ui.control.Label
        minRadiusEditField           matlab.ui.control.NumericEditField
        allunitsareindegreesLabel    matlab.ui.control.Label
        DepthConstraintsPanel        matlab.ui.container.Panel
        ZLabel                       matlab.ui.control.Label
        MinDepth                     matlab.ui.control.NumericEditField
        MaxDepth                     matlab.ui.control.NumericEditField
        kmLabel                      matlab.ui.control.Label
        FetchButton                  matlab.ui.control.Button
        NameCatalogLabel             matlab.ui.control.Label
        NameCatalogEditField         matlab.ui.control.EditField
        CancelButton                 matlab.ui.control.Button
    end


    properties (Access = private)
        sendParts = containers.Map % Description
        NameTouched logical = false % whether user modified the name field
        CoastDeets % Description
    end

    properties (Access = public)
        setCatalogFcn function_handle = @(c)assignin('base','FdsnDownloadedZmapCatalog',c)% Description
    end

    methods (Access = private)
    
        function results = circlePlot(app)
            % plot radial selection graphic
            axis(app.CircAxes,'equal');
            app.CircAxes.Visible='off';
            s = 0.5;
            plot(app.CircAxes,[0; cosd(45)* s; cosd(45)],[0; sind(45)*s; sind(45)],'o-','lineWidth',2);
            hold(app.CircAxes,'on');
            plot(app.CircAxes,0,0,'+','LineWidth',2,'markersize',12);
            d = 0:0.05:90;
            plot(app.CircAxes,cosd(d),sind(d),':','LineWidth',3);
            plot(app.CircAxes,cosd(d) .* s,sind(d) .* s,':','LineWidth',3);
            r = rand(size(d)); r(r<s)=nan;
            plot(app.CircAxes,cosd(d).*r,sind(d).*r,'.','Color',[0.3 0.5 0.3]);
        end
        
        function results = rectPlot(app)
        end

        function results = signalValidRange(app, hSmaller, hBigger)
            nan_bgcolor = [1.0 1.0 1.0];
            good_bgcolor = [0.95 1.0 0.95];
            bad_bgcolor = [1.0 0.95 0.95];
            hasSmaller = ~isnan(hSmaller.Value) && ~isempty(hSmaller.Value);
            hasBigger = ~isnan(hBigger.Value) && ~isempty(hBigger.Value);
            if hasBigger && ~hasSmaller
                hSmaller.BackgroundColor = nan_bgcolor;
                hBigger.BackgroundColor = good_bgcolor;
                %
            elseif hasSmaller && ~hasBigger
                hSmaller.BackgroundColor = good_bgcolor;
                hBigger.BackgroundColor = nan_bgcolor;
            elseif ~hasBigger && ~hasSmaller
                hSmaller.BackgroundColor = nan_bgcolor;
                hBigger.BackgroundColor = nan_bgcolor;
            else
                if hBigger.Value >= hSmaller.Value
                    hSmaller.BackgroundColor = good_bgcolor;
                    hBigger.BackgroundColor = good_bgcolor;
                else
                    hSmaller.BackgroundColor = bad_bgcolor;
                    hBigger.BackgroundColor = bad_bgcolor;
                end
            end
            
        end
    
        function ok = loadDatacenterDetails(app)
            ok = haveInet('service.iris.edu');
            if ~ok
                return
            end
            datacenter_details = webread('http://service.iris.edu/irisws/fedcatalog/1/datacenters');
            
            %dump datacenters with no event catalog access
            i=1;
            while i <= numel(datacenter_details)
                fldnm = fieldnames(datacenter_details(i).serviceURLs);
                if ~ismember('eventService',fldnm)
                    datacenter_details(i)=[];
                else
                    i=i+1;
                end
            end
            datacenter_details(2:end+1) = datacenter_details;
            datacenter_details(1).name = '<none>';
            datacenter_details(1).description = 'choose a datacenter';
            datacenter_details(1).website = ' ';
            datacenter_details(1).location = ' ';
            datacenter_details(1).lastUpdate = ' ';
            datacenter_details(1).serviceURLs.eventService = '';
            
            if exist('fdsnservices.json','file')
                try
                    %TODO add a way to modify data centers
                    % this is the datacenter_details structure, saved as a json file in the resources directory
                    jj=jsondecode(fileread('fdsnservices.json')); % get additional services
                    for i=1:numel(jj)
                        % only include datacenters that are not already retrieved by the querying fedcatalog
                        if ~ismember(jj(i).name,{datacenter_details.name})
                            datacenter_details(end+1)=jj(i);
                        end
                    end
                catch ME
                    disp(['unable to access additional datacenter information: ', ME.message]);
                end
            end
            %app.ProviderDropDown.UserData = datacenter_details;
            % currprovider = datacenter_details(app.ProviderDropDown.Value);
            app.ProviderDropDown.Items={datacenter_details.name};
            app.ProviderDropDown.ItemsData=datacenter_details;
            
            function tf = haveInet(server)
                % returns true if able to connect to the internet
                % adapted from  Stack Overflow: https://stackoverflow.com/a/19560468/949344
                tf = false;
                try %#ok<TRYNC>
                    [~] = java.net.InetAddress.getByName(server);
                    tf = true;
                end
            end
        
        end

        function TimeValueChanged(app,src)
            value = src.Value;
            if isempty(src.Value)
                src.Value=src.Tag;
                src.UserData=[];
                src.FontColor=[0.5 0.5 0.5];
                src.BackgroundColor=[1 1 1];
                return
            end
            try
                d = datetime(value);
                src.UserData = d; % was datenum
                src.FontColor=[0 0 0];
                if any( ismember(value,['a':'z','A':'z']))
                    tmp=char(d,'uuuu-MM-dd HH:mm:SS');
                    if ~isempty(tmp)
                        src.BackgroundColor=[0.95 1.0 0.95];
                        src.Value=tmp;
                    else
                        src.BackgroundColor=[1.0 0.95 0.95];
                    end
                end
                
                src.BackgroundColor=[0.95 1.0 0.95];
            catch
                d = str2double(value);
                if d >= 1800 && d < 3000
                    %treat as year
                    src.Value=[value, '-01-01'];
                    app.TimeValueChanged(src);
                    return
                else
                    if ~isempty(src.UserData)
                        src.Value=char(d);
                        src.FontColor=[0 0 0];
                        return
                    else
                        src.Value = src.Tag;
                        src.FontColor=[0.5 0.5 0.5];
                    end
                end
                
                src.BackgroundColor=[1.0 0.95 0.95];
                pause(.5)
                src.BackgroundColor=[1 1 1];
            end
            
        end
        
        function results = ColorByLimits(app, minSrc, maxSrc)
            if maxSrc.Value < minSrc.Value
                complain
            end
            
            if minSrc.Value == minSrc.Limits(1)
                % deactivate MinDepth
                minSrc.FontColor=[0.5 0.5 0.5];
            else
                %activate maxDepth
                minSrc.FontColor=[0 0 0];
            end
            if maxSrc.Value == app.MaxDepth.Limits(2)
                % deactivate maxDepth
                maxSrc.FontColor=[0.5 0.5 0.5];
            else
                %activate maxDepth
                maxSrc.FontColor=[0 0 0];
            end
        end
        
        function queryset = buildQuery(app)
            queryset=[];
            if app.ProviderDropDown.Value.name == "<none>"
                % no datacenter has been chosen
                beep;
                uialert(app.ImportfromFDSNwebserviceUIFigure,...
                    'Incomplete Request: You must first choose a data provider.', ...
                    'Choose a Provider' , 'Icon', 'error');
                errorflash(app.ProviderDropDown);
                return
            end
            
            queryset=app.ProviderDropDown.Value.name;
            
            if app.StartTime.Value ~= "Start Time"
                queryset = add_datetime(app.StartTime.Value, queryset, 'starttime');
            end
            if app.EndTime.Value ~= "End Time"
                queryset = add_datetime(app.EndTime.Value, queryset, 'endtime');
            end
            if app.TabGroup.SelectedTab == app.RectangularSelectionTab
                queryset = add_numeric(app.SouthEditField.Value, queryset, 'minlatitude');
                queryset = add_numeric(app.NorthEditField.Value, queryset, 'maxlatitude');
                queryset = add_numeric(app.WestEditField.Value, queryset, 'minlongitude');
                queryset = add_numeric(app.EastEditField.Value, queryset, 'maxlongitude');
                
            elseif app.TabGroup.SelectedTab == app.RadialSelectionTab
                queryset = add_numeric(app.LatitudeEditField.Value, queryset, 'latitude');
                queryset = add_numeric(app.LongitudeEditField.Value, queryset, 'longitude');
                queryset = add_numeric(app.minRadiusEditField.Value, queryset, 'minradius');
                queryset = add_numeric(app.maxRadiusEditField.Value, queryset, 'maxradius');
            end
            
            queryset = add_numeric(app.MinMagField.Value, queryset, 'minmagnitude');
            queryset = add_numeric(app.MaxMagField.Value, queryset, 'maxmagnitude');
            queryset = add_numeric(app.MinDepth.Value, queryset, 'mindepth');
            queryset = add_numeric(app.MaxDepth.Value, queryset, 'maxdepth');
            queryset = add_string(app.MagnitudeTypesListEditField.Value, queryset, 'magnitudetype');
            
            
            function optlist = add_datetime(value, optlist, label)
                % optlist = add_numeric(value, optlist, 'something')
                % adds only if 'something is not nan or empty
                if ~isempty(value)
                    value = char(datetime(value),"uuuu-MM-dd'T'HH:mm:ss");
                    optlist = [optlist, {label, value}];
                end
            end
        
            function optlist = add_numeric(value, optlist, label)
                % optlist = add_numeric(value, optlist, 'something')
                % adds only if 'something is not nan or empty
                if ~isempty(value) && ~isnan(value) && ~isinf(value)
                    optlist = [optlist, {label, value}];
                end
            end
        
            function [optlist] = add_string(value, optlist, label)
                % optlist = add_numeric(handles, optlist, 'something')
                % adds only if 'something is not nan or empty
                if ~isempty(value) && ~any(value==' ')
                    optlist = [optlist, {label, value}];
                end
            end
        end

        function results = get_rect_mask(app)
            if app.WestEditField.Value <= app.EastEditField.Value
                results = app.CoastDeets.lat <= app.NorthEditField.Value &...
                    app.CoastDeets.lat >= app.SouthEditField.Value &...
                    app.CoastDeets.lon >= app.WestEditField.Value &...
                    app.CoastDeets.lon <= app.EastEditField.Value;
            else
                % wrapping around 180.
                results = app.CoastDeets.lat <= app.NorthEditField.Value &...
                    app.CoastDeets.lat >= app.SouthEditField.Value &...
                    (app.CoastDeets.lon >= app.WestEditField.Value |...
                    app.CoastDeets.lon <= app.EastEditField.Value);
            end
        end
        
        function update_box(app)
            bh = app.CoastDeets.boxh;
            n = app.NorthEditField.Value;
            s = app.SouthEditField.Value;
            e = app.EastEditField.Value;
            w = app.WestEditField.Value;
            if w <= e
                bh.YData = [n, n, s, s, n];
                bh.XData = [w, e, e, w, w];
            else
                % need 2 boxes
                bh.YData = [n, n, s, s, n , nan, n, n, s, s, n];
                bh.XData = [w, 180, 180, w, w, nan, -180, e, e, -180, -180];
            end
            
        end
        
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            iAmConnected = app.loadDatacenterDetails;
            if ~iAmConnected
                errordlg('Cannot connect to the datacenter server. Perhaps this machine is not connected to the internet?');
                return
            end
            app.circlePlot;
            app.ProviderInformationLabel.Text='Choose a provider from the dropdown menu';
            app.StartTime.Tag = 'Start Time';
            app.EndTime.Tag = 'End Time';
            app.ImportfromFDSNwebserviceUIFigure.UserData = app;
            cst = load('coast.mat');
            app.CoastDeets = struct('lat',cst.lat, 'lon', cst.long);
            plot(app.RectMap, cst.long, cst.lat, 'Color',[0.7 0.7 0.7],'Tag','background_coast');
            hold(app.RectMap, 'on')
            themask = app.get_rect_mask();
            theseLats = cst.lat;
            theseLats(~themask) = nan;
            theseLons = cst.long;
            theseLons(~themask) = nan;
            app.CoastDeets.activeh = plot(app.RectMap,theseLons, theseLats,'k', 'Tag', 'active_coast');
            app.CoastDeets.boxh = plot(app.RectMap, nan, nan, 'r', 'LineWidth', 1);
            app.update_box();
            hold(app.RectMap, 'off')
            axis(app.RectMap, [-180 180 -90 90]);
            app.RectMap.XTick = -180 : 15 : 180;
            app.RectMap.XTickLabel(1:3:end) = {''};
            app.RectMap.XTickLabel(2:3:end) = {''};
            app.RectMap.YTick = -90 : 15 : 90;
            app.RectMap.YTickLabel(2:2:end) = {''};
            app.RectMap.GridLineStyle = ':';
        end

        % Value changed function: ProviderDropDown
        function ProviderDropDownValueChanged(app, event)
            value = app.ProviderDropDown.Value;
            app.ProviderInformationLabel.Text=sprintf('%s\n%s\n%s',...
                value.description,...
                value.location,...
                value.serviceURLs.eventService);
            if strcmp(value.name,'<none>')
                app.ProviderDropDown.BackgroundColor = [1.0 0.95 0.95];
                msg.infodisp('Importing FDSN data - First choose a data provider...','FDSN Fetch');
                
            else
                app.ProviderDropDown.BackgroundColor = [0.95 1.0 0.95];
                msg.infodisp('Importing FDSN data - Choose the desired catalog constraints (time, magnitude, etc..)','FDSN Fetch');
                
            end
                    
             if ~ app.NameTouched
                 app.NameCatalogEditField.Value = [value.name,'_fdsn'];
             end
        end

        % Value changed function: StartTime
        function StartTimeValueChanged(app, event)
            event.Source.Value = replace(event.Source.Value, 'Start Time', '');
            app.TimeValueChanged(event.Source);
            
        end

        % Value changed function: EndTime
        function EndTimeValueChanged(app, event)
            event.Source.Value = replace(event.Source.Value, 'End Time', '');
            app.TimeValueChanged(event.Source);
        end

        % Button pushed function: FetchButton
        function FetchButtonPushed(app, event)
           q = app.buildQuery();
           
           if isempty(q) || ~iscell(q)
                uialert(app.ImportfromFDSNwebserviceUIFigure, 'Incomplete Request: You must first choose some filter constraints', ...
                    'Error: unconstrained request','Icon','error');
                return
            end
           
            msg.dbdisp('Importing FDSN data from the web. This might take a minute','FDSN Fetch');
            
            drawnow;
            % FETCH THE DATA
            watchon;
            whos queryset
            app.FetchButton.Text='Fetching...';
            % uialert(app.ImportfromFDSNwebserviceUIFigure, 'Please wait while requested data is downloaded',...
            %    'Downloading','Icon','info');
            m=msgbox_nobutton('Please wait while requested data is downloaded','Downloading');
            %% do the import
            [tmp,ok]=import_fdsn_event(1, q{:});
            app.FetchButton.Text='Fetch';
            if ~ok
                uialert(app.ImportfromFDSNwebserviceUIFigure, 'FDSN Import was Unsuccessful.', ...
                    'Error: Unsuccessful Import','Icon','error');
                app.ImportfromFDSNwebserviceUIFigure.Visible = 'on';
                return
            end
            
            % make it OK to close dialog box.
            if isvalid(m)
                m.Name='Done';
                m.String=sprintf('Done Downloading. Found %d events',tmp.Count);
                m.delay_for_close(1);
                delete(m);
            end
            watchoff;
            
            tmp.sort('Date');
            tmp.Name = app.NameCatalogEditField.Value;
            
            app.setCatalogFcn(tmp);
            app.ImportfromFDSNwebserviceUIFigure.Visible = 'off';
            clear tmp
            
        end

        % Value changed function: MaxMagField, MinMagField
        function MagnitudeChanged(app, event)
            app.ColorByLimits(app.MinMagField, app.MaxMagField);
        end

        % Value changed function: MaxDepth, MinDepth
        function DepthChanged(app, event)
            app.ColorByLimits(app.MinDepth, app.MaxDepth);
        end

        % Value changed function: NameCatalogEditField
        function NameCatalogEditFieldValueChanged(app, event)
            app.NameTouched = true;
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            close(app.ImportfromFDSNwebserviceUIFigure)
        end

        % Value changed function: EastEditField, NorthEditField, 
        % SouthEditField, WestEditField
        function RectFieldValueChanged(app, event)
            themask = app.get_rect_mask();
            theseLats = app.CoastDeets.lat;
            theseLats(~themask)=nan;
            theseLons = app.CoastDeets.lon;
            theseLons(~themask)=nan;
            app.CoastDeets.activeh.XData = theseLons;
            app.CoastDeets.activeh.YData = theseLats;
            app.update_box();
            n = app.NorthEditField.Value;
            s = app.SouthEditField.Value;
            e = app.EastEditField.Value;
            w = app.WestEditField.Value;
            xr = range([e,w]);
            yr = range([n,s]);
            yl = app.RectMap.YLim;
            xl = app.RectMap.XLim;
            axr = range(xl);
            ayr = range(yl);
            if axr < xr || ayr < yr || n > yl(2) || s < yl(1) || w < xl(1) || e > xl(2) 
                app.RectMap.XLim = [-180, 180];
                app.RectMap.YLim = [-90, 90];
                axr = 360;
                ayr = 180;
            end
            
            cx = (e+w)/2;
            cy = (n+s)/2;
            
            while e>w && axr/xr > 2 && ayr/yr > 2
                axr = axr/2;
                ayr = ayr/2;
                app.RectMap.XLim = [cx + [-axr, axr]];
                app.RectMap.YLim = [cy + [-ayr, ayr]];
            end
            if app.RectMap.YLim(2) > 90
                dy = 90 - app.RectMap.YLim(2);
                app.RectMap.YLim = app.RectMap.YLim + dy;
            end
            if app.RectMap.YLim(1) < -90
                dy = -90 - app.RectMap.YLim(1);
                app.RectMap.YLim = app.RectMap.YLim + dy;
            end
            if app.RectMap.XLim(1) < -180
                dx = -180 - app.RectMap.XLim(1);
                app.RectMap.XLim = app.RectMap.XLim + dx;
            end
            if app.RectMap.XLim(2) > 180
                dx = -180 - app.RectMap.XLim(2);
                app.RectMap.XLim = app.RectMap.XLim + dx;
            end
                
                
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ImportfromFDSNwebserviceUIFigure = uifigure;
            app.ImportfromFDSNwebserviceUIFigure.Name = 'Import from FDSN webservice';
            app.ProviderDropDownLabel = uilabel(app.ImportfromFDSNwebserviceUIFigure);
            app.ProviderDropDownLabel.Text = 'Provider';
            app.ProviderDropDown = uidropdown(app.ImportfromFDSNwebserviceUIFigure);
            app.ProviderDropDown.ValueChangedFcn = createCallbackFcn(app, @ProviderDropDownValueChanged, true);
            app.TimeConstraintsPanel = uipanel(app.ImportfromFDSNwebserviceUIFigure);
            app.TimeConstraintsPanel.Title = 'Time Constraints';
            app.StartTime = uieditfield(app.TimeConstraintsPanel, 'text');
            app.StartTime.ValueChangedFcn = createCallbackFcn(app, @StartTimeValueChanged, true);
            app.StartTime.Value = 'Start Time';
            app.EndTime = uieditfield(app.TimeConstraintsPanel, 'text');
            app.EndTime.ValueChangedFcn = createCallbackFcn(app, @EndTimeValueChanged, true);
            app.EndTime.Value = 'End Time';
            app.timeformathintLabel = uilabel(app.TimeConstraintsPanel);
            app.timeformathintLabel.Enable = 'off';
            app.timeformathintLabel.Text = ' as  yyyy or yyyy-mm-dd  or  yyyy-mm-dd HH:MM:SS';
            app.toLabel = uilabel(app.TimeConstraintsPanel);
            app.toLabel.Text = 'to';
            app.MagnitudeConstraintsPanel = uipanel(app.ImportfromFDSNwebserviceUIFigure);
            app.MagnitudeConstraintsPanel.Title = 'Magnitude Constraints';
            app.MinMagField = uieditfield(app.MagnitudeConstraintsPanel, 'numeric');
            app.MinMagField.Limits = [-Inf 10];
            app.MinMagField.ValueDisplayFormat = '%.2f';
            app.MinMagField.ValueChangedFcn = createCallbackFcn(app, @MagnitudeChanged, true);
            app.MinMagField.Value = -Inf;
            app.MaxMagField = uieditfield(app.MagnitudeConstraintsPanel, 'numeric');
            app.MaxMagField.Limits = [-Inf 10];
            app.MaxMagField.ValueDisplayFormat = '%.2f';
            app.MaxMagField.ValueChangedFcn = createCallbackFcn(app, @MagnitudeChanged, true);
            app.MaxMagField.Value = 10;
            app.MagnitudeTypesListEditField = uieditfield(app.MagnitudeConstraintsPanel, 'text');
            app.MagnitudeTypesListEditField.Value = 'Magnitude Types (List)';
            app.MLabel = uilabel(app.MagnitudeConstraintsPanel);
            app.MLabel.Text = '<= M <=';
            app.ProviderInformationLabel = uilabel(app.ImportfromFDSNwebserviceUIFigure);
            app.ProviderInformationLabel.Text = 'Provider Information';
            app.GeographicConstraintsPanel = uipanel(app.ImportfromFDSNwebserviceUIFigure);
            app.GeographicConstraintsPanel.Title = 'Geographic Constraints';
            app.TabGroup = uitabgroup(app.GeographicConstraintsPanel);
            app.NoneTab = uitab(app.TabGroup);
            app.NoneTab.Title = 'None';
            app.DatawillnotbefilteredgeographicallyLabel = uilabel(app.NoneTab);
            app.DatawillnotbefilteredgeographicallyLabel.Enable = 'off';
            app.DatawillnotbefilteredgeographicallyLabel.Text = 'Data will not be filtered geographically';
            app.RectangularSelectionTab = uitab(app.TabGroup);
            app.RectangularSelectionTab.Title = 'Rectangular Selection';
            app.NorthEditFieldLabel = uilabel(app.RectangularSelectionTab);
            app.NorthEditFieldLabel.Text = 'North';
            app.NorthEditField = uieditfield(app.RectangularSelectionTab, 'numeric');
            app.NorthEditField.Limits = [-90 90];
            app.NorthEditField.ValueChangedFcn = createCallbackFcn(app, @RectFieldValueChanged, true);
            app.NorthEditField.Value = 90;
            app.WestEditFieldLabel = uilabel(app.RectangularSelectionTab);
            app.WestEditFieldLabel.Text = 'West';
            app.WestEditField = uieditfield(app.RectangularSelectionTab, 'numeric');
            app.WestEditField.Limits = [-180 180];
            app.WestEditField.ValueChangedFcn = createCallbackFcn(app, @RectFieldValueChanged, true);
            app.WestEditField.Value = -180;
            app.SouthEditFieldLabel = uilabel(app.RectangularSelectionTab);
            app.SouthEditFieldLabel.Text = 'South';
            app.SouthEditField = uieditfield(app.RectangularSelectionTab, 'numeric');
            app.SouthEditField.Limits = [-90 90];
            app.SouthEditField.ValueChangedFcn = createCallbackFcn(app, @RectFieldValueChanged, true);
            app.SouthEditField.Value = -90;
            app.EastEditFieldLabel = uilabel(app.RectangularSelectionTab);
            app.EastEditFieldLabel.Text = 'East';
            app.EastEditField = uieditfield(app.RectangularSelectionTab, 'numeric');
            app.EastEditField.Limits = [-180 180];
            app.EastEditField.ValueChangedFcn = createCallbackFcn(app, @RectFieldValueChanged, true);
            app.EastEditField.Value = 180;
            app.RectMap = uiaxes(app.RectangularSelectionTab);
            app.RadialSelectionTab = uitab(app.TabGroup);
            app.RadialSelectionTab.Title = 'Radial Selection';
            app.CircAxes = uiaxes(app.RadialSelectionTab);
            app.OriginLabel = uilabel(app.RadialSelectionTab);
            app.OriginLabel.Text = 'Origin: ';
            app.LongitudeEditFieldLabel = uilabel(app.RadialSelectionTab);
            app.LongitudeEditFieldLabel.Text = 'Longitude';
            app.LongitudeEditField = uieditfield(app.RadialSelectionTab, 'numeric');
            app.LongitudeEditField.Limits = [-180 180];
            app.LatitudeEditFieldLabel = uilabel(app.RadialSelectionTab);
            app.LatitudeEditFieldLabel.Text = 'Latitude';
            app.LatitudeEditField = uieditfield(app.RadialSelectionTab, 'numeric');
            app.LatitudeEditField.Limits = [-90 90];
            app.maxRadiusEditFieldLabel = uilabel(app.RadialSelectionTab);
            app.maxRadiusEditFieldLabel.Text = 'max Radius';
            app.maxRadiusEditField = uieditfield(app.RadialSelectionTab, 'numeric');
            app.minRadiusEditFieldLabel = uilabel(app.RadialSelectionTab);
            app.minRadiusEditFieldLabel.Text = 'min Radius';
            app.minRadiusEditField = uieditfield(app.RadialSelectionTab, 'numeric');
            app.allunitsareindegreesLabel = uilabel(app.RadialSelectionTab);
            app.allunitsareindegreesLabel.Text = 'all units are in degrees';
            app.DepthConstraintsPanel = uipanel(app.ImportfromFDSNwebserviceUIFigure);
            app.DepthConstraintsPanel.Title = 'Depth Constraints';
            app.ZLabel = uilabel(app.DepthConstraintsPanel);
            app.ZLabel.Text = '<= Z <=';
            app.MinDepth = uieditfield(app.DepthConstraintsPanel, 'numeric');
            app.MinDepth.ValueChangedFcn = createCallbackFcn(app, @DepthChanged, true);
            app.MinDepth.Value = -Inf;
            app.MaxDepth = uieditfield(app.DepthConstraintsPanel, 'numeric');
            app.MaxDepth.ValueChangedFcn = createCallbackFcn(app, @DepthChanged, true);
            app.MaxDepth.Value = Inf;
            app.kmLabel = uilabel(app.DepthConstraintsPanel);
            app.kmLabel.Text = '(km)';
            app.FetchButton = uibutton(app.ImportfromFDSNwebserviceUIFigure, 'push');
            app.FetchButton.ButtonPushedFcn = createCallbackFcn(app, @FetchButtonPushed, true);
            app.FetchButton.Text = 'Fetch';
            app.NameCatalogLabel = uilabel(app.ImportfromFDSNwebserviceUIFigure);
            app.NameCatalogLabel.Text = 'Name Catalog:';
            app.NameCatalogEditField = uieditfield(app.ImportfromFDSNwebserviceUIFigure, 'text');
            app.NameCatalogEditField.ValueChangedFcn = createCallbackFcn(app, @NameCatalogEditFieldValueChanged, true);
            app.CancelButton = uibutton(app.ImportfromFDSNwebserviceUIFigure, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = 'Cancel';
        end
    end

    methods (Access = public)

        % Construct app
        function app = fdsn_chooser

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ImportfromFDSNwebserviceUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ImportfromFDSNwebserviceUIFigure)
        end
    end
end