classdef combine_catalogs < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        CombineCatalogsUIFigure         matlab.ui.Figure
        FirstCatalogSource              matlab.ui.container.TabGroup
        ZMAPTab                         matlab.ui.container.Tab
        ChooseListBoxLabel              matlab.ui.control.Label
        ChooseListBox                   matlab.ui.control.ListBox
        DesktopTab                      matlab.ui.container.Tab
        VariableListBoxLabel            matlab.ui.control.Label
        DesktopVariableList1            matlab.ui.control.ListBox
        FileTab                         matlab.ui.container.Tab
        ChooseFileButton1               matlab.ui.control.Button
        FileEditField_2Label            matlab.ui.control.Label
        FileEditField1                  matlab.ui.control.EditField
        VariableDropDown_2Label         matlab.ui.control.Label
        VariableDropDown1               matlab.ui.control.DropDown
        SecondCatalogSource             matlab.ui.container.TabGroup
        ZMAPTab2                        matlab.ui.container.Tab
        ChooseListBox_2Label            matlab.ui.control.Label
        ChooseListBox2                  matlab.ui.control.ListBox
        DesktopTab_2                    matlab.ui.container.Tab
        VariableListBox_2Label          matlab.ui.control.Label
        DesktopVariableList2            matlab.ui.control.ListBox
        FileTab_2                       matlab.ui.container.Tab
        ChooseFileButton2               matlab.ui.control.Button
        FileEditFieldLabel              matlab.ui.control.Label
        FileEditField2                  matlab.ui.control.EditField
        VariableDropDownLabel           matlab.ui.control.Label
        VariableDropDown2               matlab.ui.control.DropDown
        NameofCombinedCatalogLabel      matlab.ui.control.Label
        NameofCombinedCatalogEditField  matlab.ui.control.EditField
        FirstCatalogSourceLabel         matlab.ui.control.Label
        SecondCatalogSourceLabel        matlab.ui.control.Label
        EventDuplicationPanel           matlab.ui.container.Panel
        RemoveDuplicatesCheckBox        matlab.ui.control.CheckBox
        DistanceTolerancemEditFieldLabel  matlab.ui.control.Label
        DistanceTolerancemEditField     matlab.ui.control.NumericEditField
        DepthTolerancemEditFieldLabel   matlab.ui.control.Label
        DepthTolerancemEditField        matlab.ui.control.NumericEditField
        TimeTolerancesEditFieldLabel    matlab.ui.control.Label
        TimeTolerancesEditField         matlab.ui.control.NumericEditField
        MagnitudeToleranceEditFieldLabel  matlab.ui.control.Label
        MagnitudeToleranceEditField     matlab.ui.control.NumericEditField
        SavetoWorkspaceasCheckBox       matlab.ui.control.CheckBox
        asEditField                     matlab.ui.control.EditField
        CancelButton                    matlab.ui.control.Button
        OKButton                        matlab.ui.control.Button
        OpeninnewZMAPwindowCheckBox     matlab.ui.control.CheckBox
        ReplaceglobalcatalogCheckBox    matlab.ui.control.CheckBox
        nEvents1Label                   matlab.ui.control.Label
        nEvents2Label                   matlab.ui.control.Label
        Catalog1NameLabel               matlab.ui.control.Label
        Catalog2NameLabel               matlab.ui.control.Label
    end

    
    properties (Access = private)
        FirstCatalog ZmapCatalog  % Description
        SecondCatalog ZmapCatalog % Description
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.FirstCatalogSourceSelectionChanged([]);
            app.SecondCatalogSourceSelectionChanged([]);
        end

        % Selection change function: SecondCatalogSource
        function SecondCatalogSourceSelectionChanged(app, event)
            selectedTab = app.SecondCatalogSource.SelectedTab;
            switch selectedTab.Title
                case "Desktop"
                    ww=evalin('base','whos');
                    app.DesktopVariableList2.Items = {ww({ww.class} == "ZmapCatalog").name};
                    app.SecondCatalog = evalin('base',app.DesktopVariableList2.Value');
                    app.nEvents2Label.Text = string(app.SecondCatalog.Count) + " events";
                    app.Catalog2NameLabel.Text = ['"', app.SecondCatalog.Name, '"'];
                    
                case "ZMAP"
                    app.ChooseListBox2ValueChanged([]);
                case "File"
                    app.VariableDropDown2ValueChanged([]);
                otherwise
            end
        end

        % Value changed function: DesktopVariableList2
        function DesktopVariableList2ValueChanged(app, event)
            value = app.DesktopVariableList2.Value;
            app.SecondCatalog = evalin('base',value);
            app.nEvents2Label.Text = string(app.SecondCatalog.Count) + " events";
            app.Catalog2NameLabel.Text = ['"', app.SecondCatalog.Name, '"'];
        end

        % Value changed function: RemoveDuplicatesCheckBox
        function RemoveDuplicatesCheckBoxValueChanged(app, event)
            value = app.RemoveDuplicatesCheckBox.Value;
            enableValue = matlab.lang.OnOffSwitchState(value);
            app.DistanceTolerancemEditField.Enable  = enableValue;
            app.DepthTolerancemEditField.Enable     = enableValue;
            app.TimeTolerancesEditField.Enable      = enableValue;
            app.MagnitudeToleranceEditField.Enable  = enableValue;
        end

        % Button pushed function: OKButton
        function OKButtonPushed(app, event)
            if isempty(app.SecondCatalog) || isempty(app.FirstCatalog)
                warndlg("Two catalogs have not yet been chosen");
                return;
            end
            mergedcat=app.FirstCatalog.cat(app.SecondCatalog);
            mergedcat.Name = app.NameofCombinedCatalogEditField.Value;
            if ~any([app.RemoveDuplicatesCheckBox.Value, ...
                    app.OpeninnewZMAPwindowCheckBox.Value, ...
                    app.SavetoWorkspaceasCheckBox.Value])
                warndlg("Nothing is set to receive the merged catalog. Choose a place to send it first","No output selected");
                return
            end
            if app.RemoveDuplicatesCheckBox.Value
                mergedcat = mergedcat.removeDuplicates('tolDist_m',app.DistanceTolerancemEditField.Value,...
                    'tolDepth_m',app.DepthTolerancemEditField.Value,...
                    'tolTime',seconds(app.TimeTolerancesEditField.Value),...
                    'tolMag', app.MagnitudeToleranceEditField.Value);
            end
            if app.OpeninnewZMAPwindowCheckBox.Value
                ZmapMainWindow(mergedcat);
                %{
                zmw = get(gcf,'UserData');
                if isa(zmw,'ZmapMainWindow')
                    zmw.rawcatalog = mergedcat;
                    % do I need to update something?
                end
                %}
            end
            if app.ReplaceglobalcatalogCheckBox.Value
                ZG=ZmapGlobal.Data;
                ZG.primeCatalog = mergedcat;
            end
            if app.SavetoWorkspaceasCheckBox.Value
                vn = app.asEditField.Value;
                existsInBase = ~isempty(evalin('base',"who('"+ vn + "')"));
                if existsInBase
                    doconfirm=uiconfirm(app.CombineCatalogsUIFigure,['A variable ',vn,' already exists. Overwrite it?'],'Overwrite?');
                    if doconfirm ~="OK"
                        msg.dbfprintf('Did not write merged catalog to workspace because variable <strong>%s</strong> already existed\n',vn);
                        return
                    end
                end
                assignin('base', vn, mergedcat);
                msg.dbfprintf('Created Merged Catalog "%s" as %s\n',mergedcat.Name, vn);
            end
            delete(app);
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            delete(app)
        end

        % Button pushed function: ChooseFileButton2
        function ChooseFileButton2Pushed(app, event)
            app.CombineCatalogsUIFigure.Visible='off';
            [FILENAME, PATHNAME, FILTERINDEX]=uigetfile();
            app.CombineCatalogsUIFigure.Visible='on';
            if FILTERINDEX ~=0
                app.FileEditField2.Value=fullfile(PATHNAME,FILENAME);
                ww=whos('-file',app.FileEditField2.Value);
                ww=ww({ww.class}=="ZmapCatalog");
                app.VariableDropDown2.Items={ww.name};
            end
            app.VariableDropDown2.Enable = matlab.lang.OnOffSwitchState(~isempty(ww));
            app.VariableDropDown2ValueChanged([]);
        end

        % Value changed function: ChooseListBox2
        function ChooseListBox2ValueChanged(app, event)
            value = app.ChooseListBox2.Value;
            switch value
                case 'Global Catalog'
                    ZG = ZmapGlobal.Data;
                    app.SecondCatalog=ZG.primeCatalog;
                    
                case {'Selected Catalog', 'Inverse Selected Catalog'}
                    % get catalog from ZmapMainWindow. It is not given that gcf will be the ZmapMainWindow
                    zmw = get(gcf,'UserData');
                    if isempty(zmw)
                        h=findobj('Tag','Zmap Main Window');
                        if ~isempty(h)
                            zmw = h(1).UserData;
                        else
                            errordlg('No ZMAP Main Window found');
                            app.ChooseListBox2.value = event.PreviousValue;
                        end
                    end
                    if value=="Selected Catalog"
                        app.SecondCatalog=zmw.catalog;
                    else
                        app.SecondCatalog=zmw.rawcatalog.setdiff(zmw.catalog);
                    end
                    
                otherwise
                    errordlg("Unimplemented choice");
            end
            app.nEvents2Label.Text = string(app.SecondCatalog.Count) + " events";
            app.Catalog2NameLabel.Text = ['"', app.SecondCatalog.Name, '"'];
            
        end

        % Value changed function: ChooseListBox
        function ChooseListBoxValueChanged(app, event)
            value = app.ChooseListBox.Value;
            switch value
                case 'Global Catalog'
                    ZG = ZmapGlobal.Data;
                    app.FirstCatalog=ZG.primeCatalog;
                    
                case {'Selected Catalog', 'Inverse Selected Catalog'}
                    % get catalog from ZmapMainWindow. It is not given that gcf will be the ZmapMainWindow
                    zmw = get(gcf,'UserData');
                    if isempty(zmw)
                        h=findobj('Tag','Zmap Main Window');
                        if ~isempty(h)
                            zmw = h(1).UserData;
                        else
                            errordlg('No ZMAP Main Window found');
                            app.ChooseListBox.value = event.PreviousValue;
                        end
                    end
                    if value=="Selected Catalog"
                        app.FirstCatalog=zmw.catalog;
                    else
                        app.FirstCatalog=zmw.rawcatalog.setdiff(zmw.catalog);
                    end
                    
                otherwise
                    errordlg("Unimplemented choice");
            end
            app.nEvents1Label.Text = string(app.FirstCatalog.Count) + " events";
            app.Catalog1NameLabel.Text = ['"', app.FirstCatalog.Name, '"'];
            
        end

        % Value changed function: DesktopVariableList1
        function DesktopVariableList1ValueChanged(app, event)
            value = app.DesktopVariableList1.Value;
            app.FirstCatalog = evalin('base',value);
            app.nEvents1Label.Text = string(app.FirstCatalog.Count) + " events";
            app.Catalog1NameLabel.Text = ['"', app.FirstCatalog.Name, '"'];
        end

        % Selection change function: FirstCatalogSource
        function FirstCatalogSourceSelectionChanged(app, event)
            selectedTab = app.FirstCatalogSource.SelectedTab;
            switch selectedTab.Title
                case "Desktop"
                    ww=evalin('base','whos');
                    app.DesktopVariableList1.Items = {ww({ww.class} == "ZmapCatalog").name};
                    app.FirstCatalog = evalin('base',app.DesktopVariableList1.Value');
                    app.nEvents1Label.Text = string(app.FirstCatalog.Count) + " events";
                    app.Catalog1NameLabel.Text = ['"', app.FirstCatalog.Name, '"'];
                    
                case "ZMAP"
                    app.ChooseListBoxValueChanged([]);
                case "File"
                    app.VariableDropDown1ValueChanged([]);
                otherwise
            end
        end

        % Value changed function: VariableDropDown2
        function VariableDropDown2ValueChanged(app, event)
            value = app.VariableDropDown2.Value;
            if app.VariableDropDown2.Enable=="on"
                tmp = load(app.FileEditField2.Value, value);
                app.SecondCatalog = tmp.(value);
            else
                do_nothing();
            end
            app.nEvents2Label.Text = string(app.SecondCatalog.Count) + " events";
            app.Catalog2NameLabel.Text = ['"', app.SecondCatalog.Name, '"'];
        end

        % Value changed function: asEditField
        function asEditFieldValueChanged(app, event)
            value = app.asEditField.Value;
            app.asEditField.Value = uiservices.generateVariableName(value);
        end

        % Button pushed function: ChooseFileButton1
        function ChooseFileButton1Pushed(app, event)
            app.CombineCatalogsUIFigure.Visible='off';
            [FILENAME, PATHNAME, FILTERINDEX]=uigetfile();
            app.CombineCatalogsUIFigure.Visible='on';
            if FILTERINDEX ~=0
                app.FileEditField1.Value=fullfile(PATHNAME,FILENAME);
                ww=whos('-file',app.FileEditField1.Value);
                ww=ww({ww.class}=="ZmapCatalog");
                app.VariableDropDown1.Items={ww.name};
            end
            app.VariableDropDown1.Enable = matlab.lang.OnOffSwitchState(~isempty(ww));
            app.VariableDropDown1ValueChanged([]);
        end

        % Value changed function: VariableDropDown1
        function VariableDropDown1ValueChanged(app, event)
            value = app.VariableDropDown1.Value;
            if app.VariableDropDown1.Enable=="on"
                tmp = load(app.FileEditField1.Value, value);
                app.FirstCatalog = tmp.(value);
            else
                do_nothing();
            end
            app.nEvents1Label.Text = string(app.FirstCatalog.Count) + " events";
            app.Catalog1NameLabel.Text = ['"', app.FirstCatalog.Name, '"'];
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.CombineCatalogsUIFigure = uifigure;
            app.CombineCatalogsUIFigure.Name = 'Combine Catalogs';
            app.FirstCatalogSource = uitabgroup(app.CombineCatalogsUIFigure);
            app.FirstCatalogSource.SelectionChangedFcn = createCallbackFcn(app, @FirstCatalogSourceSelectionChanged, true);
            app.ZMAPTab = uitab(app.FirstCatalogSource);
            app.ZMAPTab.Title = 'ZMAP';
            app.ChooseListBoxLabel = uilabel(app.ZMAPTab);
            app.ChooseListBoxLabel.Text = 'Choose:';
            app.ChooseListBox = uilistbox(app.ZMAPTab);
            app.ChooseListBox.Items = {'Selected Catalog', 'Global Catalog', 'Inverse Selected Catalog'};
            app.ChooseListBox.ValueChangedFcn = createCallbackFcn(app, @ChooseListBoxValueChanged, true);
            app.ChooseListBox.Value = 'Selected Catalog';
            app.DesktopTab = uitab(app.FirstCatalogSource);
            app.DesktopTab.Title = 'Desktop';
            app.VariableListBoxLabel = uilabel(app.DesktopTab);
            app.VariableListBoxLabel.Text = 'Variable';
            app.DesktopVariableList1 = uilistbox(app.DesktopTab);
            app.DesktopVariableList1.ValueChangedFcn = createCallbackFcn(app, @DesktopVariableList1ValueChanged, true);
            app.FileTab = uitab(app.FirstCatalogSource);
            app.FileTab.Title = 'File';
            app.ChooseFileButton1 = uibutton(app.FileTab, 'push');
            app.ChooseFileButton1.ButtonPushedFcn = createCallbackFcn(app, @ChooseFileButton1Pushed, true);
            app.ChooseFileButton1.Text = 'Choose *.mat File';
            app.FileEditField_2Label = uilabel(app.FileTab);
            app.FileEditField_2Label.Text = 'File';
            app.FileEditField1 = uieditfield(app.FileTab, 'text');
            app.VariableDropDown_2Label = uilabel(app.FileTab);
            app.VariableDropDown_2Label.Text = 'Variable';
            app.VariableDropDown1 = uidropdown(app.FileTab);
            app.VariableDropDown1.ValueChangedFcn = createCallbackFcn(app, @VariableDropDown1ValueChanged, true);
            app.SecondCatalogSource = uitabgroup(app.CombineCatalogsUIFigure);
            app.SecondCatalogSource.SelectionChangedFcn = createCallbackFcn(app, @SecondCatalogSourceSelectionChanged, true);
            app.ZMAPTab2 = uitab(app.SecondCatalogSource);
            app.ZMAPTab2.Title = 'ZMAP';
            app.ChooseListBox_2Label = uilabel(app.ZMAPTab2);
            app.ChooseListBox_2Label.Text = 'Choose:';
            app.ChooseListBox2 = uilistbox(app.ZMAPTab2);
            app.ChooseListBox2.Items = {'Selected Catalog', 'Global Catalog', 'Inverse Selected Catalog'};
            app.ChooseListBox2.ValueChangedFcn = createCallbackFcn(app, @ChooseListBox2ValueChanged, true);
            app.ChooseListBox2.Value = 'Selected Catalog';
            app.DesktopTab_2 = uitab(app.SecondCatalogSource);
            app.DesktopTab_2.Title = 'Desktop';
            app.VariableListBox_2Label = uilabel(app.DesktopTab_2);
            app.VariableListBox_2Label.Text = 'Variable';
            app.DesktopVariableList2 = uilistbox(app.DesktopTab_2);
            app.DesktopVariableList2.ValueChangedFcn = createCallbackFcn(app, @DesktopVariableList2ValueChanged, true);
            app.FileTab_2 = uitab(app.SecondCatalogSource);
            app.FileTab_2.Title = 'File';
            app.ChooseFileButton2 = uibutton(app.FileTab_2, 'push');
            app.ChooseFileButton2.ButtonPushedFcn = createCallbackFcn(app, @ChooseFileButton2Pushed, true);
            app.ChooseFileButton2.Text = 'Choose *.mat File';
            app.FileEditFieldLabel = uilabel(app.FileTab_2);
            app.FileEditFieldLabel.Text = 'File';
            app.FileEditField2 = uieditfield(app.FileTab_2, 'text');
            app.VariableDropDownLabel = uilabel(app.FileTab_2);
            app.VariableDropDownLabel.Text = 'Variable';
            app.VariableDropDown2 = uidropdown(app.FileTab_2);
            app.VariableDropDown2.Items = {};
            app.VariableDropDown2.ValueChangedFcn = createCallbackFcn(app, @VariableDropDown2ValueChanged, true);
            app.VariableDropDown2.Enable = 'off';
            app.VariableDropDown2.Value = {};
            app.NameofCombinedCatalogLabel = uilabel(app.CombineCatalogsUIFigure);
            app.NameofCombinedCatalogLabel.Text = 'Name of Combined Catalog:';
            app.NameofCombinedCatalogEditField = uieditfield(app.CombineCatalogsUIFigure, 'text');
            app.NameofCombinedCatalogEditField.Value = 'merged';
            app.FirstCatalogSourceLabel = uilabel(app.CombineCatalogsUIFigure);
            app.FirstCatalogSourceLabel.Text = 'First Catalog Source';
            app.SecondCatalogSourceLabel = uilabel(app.CombineCatalogsUIFigure);
            app.SecondCatalogSourceLabel.Text = 'Second Catalog Source';
            app.EventDuplicationPanel = uipanel(app.CombineCatalogsUIFigure);
            app.EventDuplicationPanel.Title = 'Event Duplication';
            app.RemoveDuplicatesCheckBox = uicheckbox(app.EventDuplicationPanel);
            app.RemoveDuplicatesCheckBox.ValueChangedFcn = createCallbackFcn(app, @RemoveDuplicatesCheckBoxValueChanged, true);
            app.RemoveDuplicatesCheckBox.Text = 'Remove Duplicates';
            app.RemoveDuplicatesCheckBox.Value = true;
            app.DistanceTolerancemEditFieldLabel = uilabel(app.EventDuplicationPanel);
            app.DistanceTolerancemEditFieldLabel.Text = 'Distance Tolerance (m)';
            app.DistanceTolerancemEditField = uieditfield(app.EventDuplicationPanel, 'numeric');
            app.DistanceTolerancemEditField.Limits = [0 Inf];
            app.DistanceTolerancemEditField.Value = 10;
            app.DepthTolerancemEditFieldLabel = uilabel(app.EventDuplicationPanel);
            app.DepthTolerancemEditFieldLabel.Text = 'Depth Tolerance (m)';
            app.DepthTolerancemEditField = uieditfield(app.EventDuplicationPanel, 'numeric');
            app.DepthTolerancemEditField.Limits = [0 Inf];
            app.DepthTolerancemEditField.Value = 10;
            app.TimeTolerancesEditFieldLabel = uilabel(app.EventDuplicationPanel);
            app.TimeTolerancesEditFieldLabel.Text = 'Time Tolerance (s)';
            app.TimeTolerancesEditField = uieditfield(app.EventDuplicationPanel, 'numeric');
            app.TimeTolerancesEditField.Limits = [0 Inf];
            app.TimeTolerancesEditField.Value = 0.01;
            app.MagnitudeToleranceEditFieldLabel = uilabel(app.EventDuplicationPanel);
            app.MagnitudeToleranceEditFieldLabel.Text = 'Magnitude Tolerance';
            app.MagnitudeToleranceEditField = uieditfield(app.EventDuplicationPanel, 'numeric');
            app.MagnitudeToleranceEditField.Limits = [0 Inf];
            app.SavetoWorkspaceasCheckBox = uicheckbox(app.CombineCatalogsUIFigure);
            app.SavetoWorkspaceasCheckBox.Text = 'Save to Workspace as...';
            app.SavetoWorkspaceasCheckBox.Value = true;
            app.asEditField = uieditfield(app.CombineCatalogsUIFigure, 'text');
            app.asEditField.ValueChangedFcn = createCallbackFcn(app, @asEditFieldValueChanged, true);
            app.asEditField.Value = 'merged_catalog';
            app.CancelButton = uibutton(app.CombineCatalogsUIFigure, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = 'Cancel';
            app.OKButton = uibutton(app.CombineCatalogsUIFigure, 'push');
            app.OKButton.ButtonPushedFcn = createCallbackFcn(app, @OKButtonPushed, true);
            app.OKButton.Text = 'OK';
            app.OpeninnewZMAPwindowCheckBox = uicheckbox(app.CombineCatalogsUIFigure);
            app.OpeninnewZMAPwindowCheckBox.Text = 'Open in new ZMAP window';
            app.ReplaceglobalcatalogCheckBox = uicheckbox(app.CombineCatalogsUIFigure);
            app.ReplaceglobalcatalogCheckBox.Text = 'Replace global catalog';
            app.nEvents1Label = uilabel(app.CombineCatalogsUIFigure);
            app.nEvents1Label.Text = 'nEvents1';
            app.nEvents2Label = uilabel(app.CombineCatalogsUIFigure);
            app.nEvents2Label.Text = 'nEvents2';
            app.Catalog1NameLabel = uilabel(app.CombineCatalogsUIFigure);
            app.Catalog1NameLabel.Text = 'Catalog 1 Name';
            app.Catalog2NameLabel = uilabel(app.CombineCatalogsUIFigure);
            app.Catalog2NameLabel.Text = 'Catalog 2 Name';
        end
    end

    methods (Access = public)

        % Construct app
        function app = combine_catalogs

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.CombineCatalogsUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.CombineCatalogsUIFigure)
        end
    end
end