classdef multi_range_selector < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        CatalogComparisonRangesUIFigure  matlab.ui.Figure
        DistributionsPanel        matlab.ui.container.Panel
        TimeAxes                  matlab.ui.control.UIAxes
        MagAxes                   matlab.ui.control.UIAxes
        DepthAxes                 matlab.ui.control.UIAxes
        DatebinnedbyLabel         matlab.ui.control.Label
        TimeDropDown              matlab.ui.control.DropDown
        compareButton             matlab.ui.control.Button
        cancelButton              matlab.ui.control.Button
        resetButton               matlab.ui.control.Button
        CatalogAPanel             matlab.ui.container.Panel
        EventsincatalogLabel      matlab.ui.control.Label
        NQuakes                   matlab.ui.control.Label
        DateRangePanel            matlab.ui.container.Panel
        EarliestEditFieldLabel    matlab.ui.control.Label
        EarliestEditField         matlab.ui.control.EditField
        LatestEditFieldLabel      matlab.ui.control.Label
        LatestEditField           matlab.ui.control.EditField
        MagnitudeRangePanel       matlab.ui.container.Panel
        MinMagEdit                matlab.ui.control.NumericEditField
        MagLabel                  matlab.ui.control.Label
        MaxMagEdit                matlab.ui.control.NumericEditField
        LocationRagesPanel        matlab.ui.container.Panel
        ZkmLabel                  matlab.ui.control.Label
        MinDepthEdit              matlab.ui.control.NumericEditField
        MaxDepthEdit              matlab.ui.control.NumericEditField
        XdegLabel                 matlab.ui.control.Label
        MaxLonEdit                matlab.ui.control.NumericEditField
        MinLonEdit                matlab.ui.control.NumericEditField
        MinLatEdit                matlab.ui.control.NumericEditField
        MaxLatEdit                matlab.ui.control.NumericEditField
        YdegLabel                 matlab.ui.control.Label
        CatalogBPanel             matlab.ui.container.Panel
        LocationRagesPanel_2      matlab.ui.container.Panel
        ZkmLabel_2                matlab.ui.control.Label
        MinDepthEdit_2            matlab.ui.control.NumericEditField
        MaxDepthEdit_2            matlab.ui.control.NumericEditField
        XdegLabel_2               matlab.ui.control.Label
        MaxLonEdit_2              matlab.ui.control.NumericEditField
        MinLonEdit_2              matlab.ui.control.NumericEditField
        MinLatEdit_2              matlab.ui.control.NumericEditField
        MaxLatEdit_2              matlab.ui.control.NumericEditField
        YdegLabel_2               matlab.ui.control.Label
        MagnitudeRangePanel_2     matlab.ui.container.Panel
        MinMagEdit_2              matlab.ui.control.NumericEditField
        MagLabel_2                matlab.ui.control.Label
        MaxMagEdit_2              matlab.ui.control.NumericEditField
        DateRangePanel_2          matlab.ui.container.Panel
        EarliestEditField_2Label  matlab.ui.control.Label
        EarliestEditField_2       matlab.ui.control.EditField
        LatestEditField_2Label    matlab.ui.control.Label
        LatestEditField_2         matlab.ui.control.EditField
        NQuakes_2                 matlab.ui.control.Label
        EventsincatalogLabel_2    matlab.ui.control.Label
        Connect_Z                 matlab.ui.control.StateButton
        Connect_Y                 matlab.ui.control.StateButton
        Connect_X                 matlab.ui.control.StateButton
        Connect_Mag               matlab.ui.control.StateButton
        Connect_Dates             matlab.ui.control.StateButton
    end


    properties (Access = private)
        % Event catalog, with a same handle as the input catalog.  When the 'Apply' button
        % is pressed, then the original catalog will be changed.
        Catalog
        CurrDateDist
        CurrMagDist 
        CurrDepthDist
        CurrDateDist_2
        CurrMagDist_2
        CurrDepthDist_2
        MagBins
        DateBins
        DepthBins
        % DateRules contains the logic table describing bin widths for catalogs of certain date ranges
        DateRules = cell2table(...
            {'year'     , years(1)          , years(10);...
            'month'     , days(years(1)/12) , years(2);...
            'week'      , days(7)           , years(0.5);...
            'day'      , days(1)           , days(20);...
            'hour'     , hours(1)          , hours(12);... 
            'minute'   , minutes(1)        , minutes(5);...
            'second'   , seconds(1)        , seconds(0)},...
            'VariableNames',{'label','bin_width','min_date_range'});
        AssignToA % Description
        AssignToB % Description
    end

    methods (Access = private)
    
        function mask=updateValues(app, WhichCat)
            if WhichCat == 'A'
                mask = true(app.Catalog.Count,1);
                mask = mask & app.Catalog.Depth >= app.MinDepthEdit.Value & app.Catalog.Depth <= app.MaxDepthEdit.Value;
                mask = mask & app.Catalog.Magnitude >= app.MinMagEdit.Value & app.Catalog.Magnitude <= app.MaxMagEdit.Value;
                mask = mask & app.Catalog.Date >= datetime(app.EarliestEditField.Value) &...
                    app.Catalog.Date <= datetime(app.LatestEditField.Value);
                mask = mask & app.Catalog.Longitude >= app.MinLonEdit.Value & app.Catalog.Longitude <= app.MaxLonEdit.Value;
                mask = mask & app.Catalog.Latitude >= app.MinLatEdit.Value & app.Catalog.Latitude <= app.MaxLatEdit.Value;
                
                app.CurrDateDist.Data=app.Catalog.Date(mask);
                app.CurrMagDist.Data=app.Catalog.Magnitude(mask);
                app.CurrDepthDist.Data=app.Catalog.Depth(mask);
            else
                mask = true(app.Catalog.Count,1);
                mask = mask & app.Catalog.Depth >= app.MinDepthEdit_2.Value & app.Catalog.Depth <= app.MaxDepthEdit_2.Value;
                mask = mask & app.Catalog.Magnitude >= app.MinMagEdit_2.Value & app.Catalog.Magnitude <= app.MaxMagEdit_2.Value;
                mask = mask & app.Catalog.Date >= datetime(app.EarliestEditField_2.Value) &...
                    app.Catalog.Date <= datetime(app.LatestEditField_2.Value);
                mask = mask & app.Catalog.Longitude >= app.MinLonEdit_2.Value & app.Catalog.Longitude <= app.MaxLonEdit_2.Value;
                mask = mask & app.Catalog.Latitude >= app.MinLatEdit_2.Value & app.Catalog.Latitude <= app.MaxLatEdit_2.Value;
                
                app.CurrDateDist_2.Data=app.Catalog.Date(mask);
                app.CurrMagDist_2.Data=app.Catalog.Magnitude(mask);
                app.CurrDepthDist_2.Data=app.Catalog.Depth(mask);
            end
        end
        
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, catalog, catalogAassigner, catalogBassigner)
            app.Catalog = catalog;
            assert(isa(catalogAassigner,'function_handle'));
            app.AssignToA = catalogAassigner;
            assert(isa(catalogBassigner,'function_handle'));
            app.AssignToB = catalogBassigner;
            r = range(catalog.Date);
            i=1;
            while app.DateRules.min_date_range(i) >= r && i < height(app.DateRules)
                i=i+1; 
            end
            theseDateRules = app.DateRules(i,:);


            app.MagBins = floor(min(catalog.Magnitude)) :0.1: ceil(max(catalog.Magnitude));
                
            %plot initial values
            h=histogram(app.TimeAxes,catalog.Date, 'BinWidth', theseDateRules.bin_width,...
                'FaceColor',[.8 .8 .8], 'EdgeAlpha',.5,...
                'DisplayName', 'all');
            app.DateBins=h.BinEdges;
            app.TimeAxes.NextPlot = 'add';
            
            histogram(app.MagAxes,catalog.Magnitude,'BinEdges',app.MagBins,...
                 'FaceColor',[.8 .8 .8], 'EdgeAlpha',.5,...
                 'DisplayName','original');
            app.MagAxes.NextPlot = 'add';
            
            app.TimeDropDown.Value = theseDateRules.label;
            
            h=histogram(app.DepthAxes,catalog.Depth, 'FaceColor',[.8 .8 .8], 'FaceAlpha',.5);
            app.DepthAxes.NextPlot = 'add';
            app.DepthBins=h.BinEdges;
            %plot current values
            app.CurrDateDist=histogram(app.TimeAxes,app.Catalog.Date, app.DateBins,...
                'DisplayName','A','FaceColor',app.CatalogAPanel.ForegroundColor);
            app.CurrMagDist=histogram(app.MagAxes,app.Catalog.Magnitude, app.MagBins,...
                'DisplayName','A','FaceColor',app.CatalogAPanel.ForegroundColor);
            app.CurrDepthDist=histogram(app.DepthAxes,app.Catalog.Depth, app.DepthBins,...
                'DisplayName','A','FaceColor',app.CatalogAPanel.ForegroundColor);
            % plot catalog B values
            app.CurrDateDist_2=histogram(app.TimeAxes,app.Catalog.Date, app.DateBins,...
                'DisplayName','B','FaceColor',app.CatalogBPanel.ForegroundColor);
            app.CurrMagDist_2=histogram(app.MagAxes,app.Catalog.Magnitude, app.MagBins,...
                'DisplayName','B','FaceColor',app.CatalogBPanel.ForegroundColor);
            app.CurrDepthDist_2=histogram(app.DepthAxes,app.Catalog.Depth, app.DepthBins,...
                'DisplayName','B','FaceColor',app.CatalogBPanel.ForegroundColor);
            
                
            app.MaxMagEdit.Tag = 'Mag';
            app.MinMagEdit_2.Tag = 'Mag';
            app.MaxLatEdit.Tag = 'Y';
            app.MinLatEdit_2.Tag = 'Y';
            app.MaxLonEdit.Tag = 'X';
            app.MinLonEdit_2.Tag = 'X';
            app.MaxDepthEdit.Tag = 'Z';
            app.MinDepthEdit_2.Tag = 'Z';
                
            legend(app.MagAxes);
            app.resetButtonPushed();


        end

        % Value changed function: MaxDepthEdit, MaxDepthEdit_2, 
        % MaxLatEdit, MaxLatEdit_2, MaxLonEdit, MaxLonEdit_2, 
        % MaxMagEdit, MaxMagEdit_2, MinDepthEdit, MinDepthEdit_2, 
        % MinLatEdit, MinLatEdit_2, MinLonEdit, MinLonEdit_2, 
        % MinMagEdit, MinMagEdit_2
        function RangeChanged(app, event)
            whichone = event.Source.Parent.Parent.Title(end);
            joinbuttonname= ['Connect_', event.Source.Tag];
            if isprop(app,joinbuttonname) && app.(['Connect_', event.Source.Tag]).Value==true
                switch event.Source.Tag
                    case 'Mag'
                        app.MaxMagEdit.Value = event.Source.Value;
                        app.MinMagEdit_2.Value = event.Source.Value;
                    case 'X'
                        app.MaxLonEdit.Value = event.Source.Value;
                        app.MinLonEdit_2.Value = event.Source.Value;
                    case 'Y'
                        app.MaxLatEdit.Value = event.Source.Value;
                        app.MinLatEdit_2.Value = event.Source.Value;
                    case 'Z'
                        app.MaxDepthEdit.Value = event.Source.Value;
                        app.MinDepthEdit_2.Value = event.Source.Value;
                end
                app.updateValues('A');
                app.updateValues('B');
            else
                app.updateValues(whichone);
            end
        end

        % Button pushed function: compareButton
        function compareButtonPushed(app, event)
           app.AssignToB(app.Catalog.subset(app.updateValues('B')));
           app.AssignToA(app.Catalog.subset(app.updateValues('A')));
           delete(app);
        end

        % Value changed function: EarliestEditField, 
        % EarliestEditField_2, LatestEditField, LatestEditField_2
        function DateFieldValueChanged(app, event)
            value = event.Source.Value ;% app.EarliestEditField.Value;
            whichone = event.Source.Parent.Parent.Title(end);
            s=strsplit(value,["-" "/" " "]);
            if numel(s) ==1
                event.Source.Value = string(datetime(str2double(value),1,1),'uuuu-MM-dd HH:mm:ss');
                value=event.Source.Value;
            end
            try
                datetime(value);
                app.updateValues(whichone);
            catch
                event.Source.Value=event.PreviousValue;
            end
            
        end

        % Button pushed function: resetButton
        function resetButtonPushed(app, event)
            
            buttons_to_reset = {'Connect_Dates','Connect_Mag','Connect_X','Connect_Y','Connect_Z'};
            for btn = buttons_to_reset
                if app.(btn{:}).Value
                    app.(btn{:}).Value = false;
                    app.(btn{:}).Text = 'join';
                    app.(btn{:}).Icon = '';
                end
            end
            
            app.NQuakes.Text = string(app.Catalog.Count);
            app.EarliestEditField.Value = string(min(app.Catalog.Date), 'uuuu-MM-dd HH:mm:ss');
            app.LatestEditField.Value = string(max(app.Catalog.Date), 'uuuu-MM-dd HH:mm:ss');
            
            [app.MinMagEdit.Value,   app.MaxMagEdit.Value]   = bounds(app.Catalog.Magnitude);
            [app.MinDepthEdit.Value, app.MaxDepthEdit.Value] = bounds(app.Catalog.Depth);
            [app.MinLonEdit.Value,   app.MaxLonEdit.Value]   = bounds(app.Catalog.Longitude);
            [app.MinLatEdit.Value,   app.MaxLatEdit.Value]   = bounds(app.Catalog.Latitude);
            app.updateValues('A');
            app.EarliestEditField_2.Value = string(min(app.Catalog.Date), 'uuuu-MM-dd HH:mm:ss');
            app.LatestEditField_2.Value = string(max(app.Catalog.Date), 'uuuu-MM-dd HH:mm:ss');
            [app.MinMagEdit_2.Value,   app.MaxMagEdit_2.Value]   = bounds(app.Catalog.Magnitude);
            [app.MinDepthEdit_2.Value, app.MaxDepthEdit_2.Value] = bounds(app.Catalog.Depth);
            [app.MinLonEdit_2.Value,   app.MaxLonEdit_2.Value]   = bounds(app.Catalog.Longitude);
            [app.MinLatEdit_2.Value,   app.MaxLatEdit_2.Value]   = bounds(app.Catalog.Latitude);
            app.updateValues('B');
        end

        % Value changed function: TimeDropDown
        function TimeDropDownValueChanged(app, event)
            value = app.TimeDropDown.Value;
            bin_width = app.DateRules.bin_width(app.DateRules.label==string(value));
            nBinsToBe = ceil(range(app.DateBins) / bin_width);
            if nBinsToBe > 1500
                uialert(app.CatalogComparisonRangesUIFigure,...
                    sprintf('Choosing %s would result in %d bins.',value,nBinsToBe),...
                    'Too many bins');
                app.TimeDropDown.Value=event.PreviousValue;
            else
                h=findobj(app.TimeAxes,'Type','histogram');
                set(h,'BinWidth',bin_width);
                app.DateBins = h(1).BinEdges;
            end
        end

        % Button pushed function: cancelButton
        function cancelButtonPushed(app, event)
            delete(app)
        end

        % Value changed function: Connect_Dates, Connect_Mag, 
        % Connect_X, Connect_Y, Connect_Z
        function Connect_ValueChanged(app, event)
            disp(event)
            value = event.Source.Value;
            if value
                event.Source.Icon = 'chain.gif';
                event.Source.Text = '';
            else
                event.Source.Text = 'join';
                event.Source.Icon = '';
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.CatalogComparisonRangesUIFigure = uifigure;
            app.CatalogComparisonRangesUIFigure.Name = 'Catalog Comparison Ranges';
            app.DistributionsPanel = uipanel(app.CatalogComparisonRangesUIFigure);
            app.DistributionsPanel.Title = 'Distributions';
            app.TimeAxes = uiaxes(app.DistributionsPanel);
            ylabel(app.TimeAxes, '# events')
            app.MagAxes = uiaxes(app.DistributionsPanel);
            xlabel(app.MagAxes, 'magnitude')
            ylabel(app.MagAxes, '# events')
            app.DepthAxes = uiaxes(app.DistributionsPanel);
            xlabel(app.DepthAxes, 'depth')
            ylabel(app.DepthAxes, '# events')
            app.DatebinnedbyLabel = uilabel(app.DistributionsPanel);
            app.DatebinnedbyLabel.Text = 'Date, binned by';
            app.TimeDropDown = uidropdown(app.DistributionsPanel);
            app.TimeDropDown.Items = {'year', 'month', 'week', 'day', 'hour', 'minute', 'second'};
            app.TimeDropDown.ValueChangedFcn = createCallbackFcn(app, @TimeDropDownValueChanged, true);
            app.TimeDropDown.Value = 'month';
            app.compareButton = uibutton(app.CatalogComparisonRangesUIFigure, 'push');
            app.compareButton.ButtonPushedFcn = createCallbackFcn(app, @compareButtonPushed, true);
            app.compareButton.Text = 'compare';
            app.cancelButton = uibutton(app.CatalogComparisonRangesUIFigure, 'push');
            app.cancelButton.ButtonPushedFcn = createCallbackFcn(app, @cancelButtonPushed, true);
            app.cancelButton.Text = 'cancel';
            app.resetButton = uibutton(app.CatalogComparisonRangesUIFigure, 'push');
            app.resetButton.ButtonPushedFcn = createCallbackFcn(app, @resetButtonPushed, true);
            app.resetButton.Text = 'reset';
            app.CatalogAPanel = uipanel(app.CatalogComparisonRangesUIFigure);
            app.CatalogAPanel.Title = 'Catalog A';
            app.EventsincatalogLabel = uilabel(app.CatalogAPanel);
            app.EventsincatalogLabel.Text = 'Events in catalog:';
            app.NQuakes = uilabel(app.CatalogAPanel);
            app.NQuakes.Text = '000';
            app.DateRangePanel = uipanel(app.CatalogAPanel);
            app.DateRangePanel.Title = 'Date Range';
            app.EarliestEditFieldLabel = uilabel(app.DateRangePanel);
            app.EarliestEditFieldLabel.Text = 'Earliest';
            app.EarliestEditField = uieditfield(app.DateRangePanel, 'text');
            app.EarliestEditField.ValueChangedFcn = createCallbackFcn(app, @DateFieldValueChanged, true);
            app.LatestEditFieldLabel = uilabel(app.DateRangePanel);
            app.LatestEditFieldLabel.Text = 'Latest';
            app.LatestEditField = uieditfield(app.DateRangePanel, 'text');
            app.LatestEditField.ValueChangedFcn = createCallbackFcn(app, @DateFieldValueChanged, true);
            app.MagnitudeRangePanel = uipanel(app.CatalogAPanel);
            app.MagnitudeRangePanel.Title = 'Magnitude Range';
            app.MinMagEdit = uieditfield(app.MagnitudeRangePanel, 'numeric');
            app.MinMagEdit.Limits = [-Inf 10];
            app.MinMagEdit.ValueDisplayFormat = '%.2f';
            app.MinMagEdit.ValueChangedFcn = createCallbackFcn(app, @RangeChanged, true);
            app.MagLabel = uilabel(app.MagnitudeRangePanel);
            app.MagLabel.Text = '<= Mag <=';
            app.MaxMagEdit = uieditfield(app.MagnitudeRangePanel, 'numeric');
            app.MaxMagEdit.Limits = [-Inf 10];
            app.MaxMagEdit.ValueDisplayFormat = '%.2f';
            app.MaxMagEdit.ValueChangedFcn = createCallbackFcn(app, @RangeChanged, true);
            app.LocationRagesPanel = uipanel(app.CatalogAPanel);
            app.LocationRagesPanel.Title = 'Location Rages';
            app.ZkmLabel = uilabel(app.LocationRagesPanel);
            app.ZkmLabel.Text = '<= Z [km] <=';
            app.MinDepthEdit = uieditfield(app.LocationRagesPanel, 'numeric');
            app.MinDepthEdit.ValueChangedFcn = createCallbackFcn(app, @RangeChanged, true);
            app.MaxDepthEdit = uieditfield(app.LocationRagesPanel, 'numeric');
            app.MaxDepthEdit.ValueChangedFcn = createCallbackFcn(app, @RangeChanged, true);
            app.XdegLabel = uilabel(app.LocationRagesPanel);
            app.XdegLabel.Text = '<= X [deg] <=';
            app.MaxLonEdit = uieditfield(app.LocationRagesPanel, 'numeric');
            app.MaxLonEdit.Limits = [-180 180];
            app.MaxLonEdit.ValueChangedFcn = createCallbackFcn(app, @RangeChanged, true);
            app.MinLonEdit = uieditfield(app.LocationRagesPanel, 'numeric');
            app.MinLonEdit.Limits = [-180 180];
            app.MinLonEdit.ValueChangedFcn = createCallbackFcn(app, @RangeChanged, true);
            app.MinLatEdit = uieditfield(app.LocationRagesPanel, 'numeric');
            app.MinLatEdit.Limits = [-90 90];
            app.MinLatEdit.ValueChangedFcn = createCallbackFcn(app, @RangeChanged, true);
            app.MaxLatEdit = uieditfield(app.LocationRagesPanel, 'numeric');
            app.MaxLatEdit.Limits = [-90 90];
            app.MaxLatEdit.ValueChangedFcn = createCallbackFcn(app, @RangeChanged, true);
            app.YdegLabel = uilabel(app.LocationRagesPanel);
            app.YdegLabel.Text = '<= Y [deg] <=';
            app.CatalogBPanel = uipanel(app.CatalogComparisonRangesUIFigure);
            app.CatalogBPanel.Title = 'Catalog B';
            app.LocationRagesPanel_2 = uipanel(app.CatalogBPanel);
            app.LocationRagesPanel_2.Title = 'Location Rages';
            app.ZkmLabel_2 = uilabel(app.LocationRagesPanel_2);
            app.ZkmLabel_2.Text = '<= Z [km] <=';
            app.MinDepthEdit_2 = uieditfield(app.LocationRagesPanel_2, 'numeric');
            app.MinDepthEdit_2.ValueChangedFcn = createCallbackFcn(app, @RangeChanged, true);
            app.MaxDepthEdit_2 = uieditfield(app.LocationRagesPanel_2, 'numeric');
            app.MaxDepthEdit_2.ValueChangedFcn = createCallbackFcn(app, @RangeChanged, true);
            app.XdegLabel_2 = uilabel(app.LocationRagesPanel_2);
            app.XdegLabel_2.Text = '<= X [deg] <=';
            app.MaxLonEdit_2 = uieditfield(app.LocationRagesPanel_2, 'numeric');
            app.MaxLonEdit_2.Limits = [-180 180];
            app.MaxLonEdit_2.ValueChangedFcn = createCallbackFcn(app, @RangeChanged, true);
            app.MinLonEdit_2 = uieditfield(app.LocationRagesPanel_2, 'numeric');
            app.MinLonEdit_2.Limits = [-180 180];
            app.MinLonEdit_2.ValueChangedFcn = createCallbackFcn(app, @RangeChanged, true);
            app.MinLatEdit_2 = uieditfield(app.LocationRagesPanel_2, 'numeric');
            app.MinLatEdit_2.Limits = [-90 90];
            app.MinLatEdit_2.ValueChangedFcn = createCallbackFcn(app, @RangeChanged, true);
            app.MaxLatEdit_2 = uieditfield(app.LocationRagesPanel_2, 'numeric');
            app.MaxLatEdit_2.Limits = [-90 90];
            app.MaxLatEdit_2.ValueChangedFcn = createCallbackFcn(app, @RangeChanged, true);
            app.YdegLabel_2 = uilabel(app.LocationRagesPanel_2);
            app.YdegLabel_2.Text = '<= Y [deg] <=';
            app.MagnitudeRangePanel_2 = uipanel(app.CatalogBPanel);
            app.MagnitudeRangePanel_2.Title = 'Magnitude Range';
            app.MinMagEdit_2 = uieditfield(app.MagnitudeRangePanel_2, 'numeric');
            app.MinMagEdit_2.Limits = [-Inf 10];
            app.MinMagEdit_2.ValueDisplayFormat = '%.2f';
            app.MinMagEdit_2.ValueChangedFcn = createCallbackFcn(app, @RangeChanged, true);
            app.MagLabel_2 = uilabel(app.MagnitudeRangePanel_2);
            app.MagLabel_2.Text = '<= Mag <=';
            app.MaxMagEdit_2 = uieditfield(app.MagnitudeRangePanel_2, 'numeric');
            app.MaxMagEdit_2.Limits = [-Inf 10];
            app.MaxMagEdit_2.ValueDisplayFormat = '%.2f';
            app.MaxMagEdit_2.ValueChangedFcn = createCallbackFcn(app, @RangeChanged, true);
            app.DateRangePanel_2 = uipanel(app.CatalogBPanel);
            app.DateRangePanel_2.Title = 'Date Range';
            app.EarliestEditField_2Label = uilabel(app.DateRangePanel_2);
            app.EarliestEditField_2Label.Text = 'Earliest';
            app.EarliestEditField_2 = uieditfield(app.DateRangePanel_2, 'text');
            app.EarliestEditField_2.ValueChangedFcn = createCallbackFcn(app, @DateFieldValueChanged, true);
            app.LatestEditField_2Label = uilabel(app.DateRangePanel_2);
            app.LatestEditField_2Label.Text = 'Latest';
            app.LatestEditField_2 = uieditfield(app.DateRangePanel_2, 'text');
            app.LatestEditField_2.ValueChangedFcn = createCallbackFcn(app, @DateFieldValueChanged, true);
            app.NQuakes_2 = uilabel(app.CatalogBPanel);
            app.NQuakes_2.Text = '000';
            app.EventsincatalogLabel_2 = uilabel(app.CatalogBPanel);
            app.EventsincatalogLabel_2.Text = 'Events in catalog:';
            app.Connect_Z = uibutton(app.CatalogComparisonRangesUIFigure, 'state');
            app.Connect_Z.ValueChangedFcn = createCallbackFcn(app, @Connect_ValueChanged, true);
            app.Connect_Z.Text = 'join';
            app.Connect_Y = uibutton(app.CatalogComparisonRangesUIFigure, 'state');
            app.Connect_Y.ValueChangedFcn = createCallbackFcn(app, @Connect_ValueChanged, true);
            app.Connect_Y.Text = 'join';
            app.Connect_X = uibutton(app.CatalogComparisonRangesUIFigure, 'state');
            app.Connect_X.ValueChangedFcn = createCallbackFcn(app, @Connect_ValueChanged, true);
            app.Connect_X.Text = 'join';
            app.Connect_Mag = uibutton(app.CatalogComparisonRangesUIFigure, 'state');
            app.Connect_Mag.ValueChangedFcn = createCallbackFcn(app, @Connect_ValueChanged, true);
            app.Connect_Mag.Text = 'join';
            app.Connect_Dates = uibutton(app.CatalogComparisonRangesUIFigure, 'state');
            app.Connect_Dates.ValueChangedFcn = createCallbackFcn(app, @Connect_ValueChanged, true);
            app.Connect_Dates.Text = 'join';
        end
    end

    methods (Access = public)

        % Construct app
        function app = multi_range_selector(varargin)

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.CatalogComparisonRangesUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.CatalogComparisonRangesUIFigure)
        end
    end
end