classdef replacement_for_topo < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        Panel3                         matlab.ui.container.Panel
        DEMButton                      matlab.ui.control.Button
        verticalexaggerationEditField  matlab.ui.control.NumericEditField
        verticalexaggerationLabel      matlab.ui.control.Label
        VectorMapButton                matlab.ui.control.Button
        DEMresolutionListBox           matlab.ui.control.ListBox
        DEMresolutionListBoxLabel      matlab.ui.control.Label
        CoastlineresolutionListBox     matlab.ui.control.ListBox
        CoastlineresolutionListBoxLabel  matlab.ui.control.Label
        Panel2                         matlab.ui.container.Panel
        ListBox                        matlab.ui.control.ListBox
        ListBoxLabel                   matlab.ui.control.Label
        drawmapButton                  matlab.ui.control.Button
        drawcolorbarButton             matlab.ui.control.Button
        Panel                          matlab.ui.container.Panel
        colorsDropDown                 matlab.ui.control.DropDown
        colorsDropDownLabel            matlab.ui.control.Label
        SpecialLinesDropDown           matlab.ui.control.DropDown
        SpecialLinesDropDownLabel      matlab.ui.control.Label
        SpecialObjectsDropDown         matlab.ui.control.DropDown
        SpecialObjectsDropDownLabel    matlab.ui.control.Label
        faultsDropDown                 matlab.ui.control.DropDown
        faultsDropDownLabel            matlab.ui.control.Label
        EQmarkerDropDown               matlab.ui.control.DropDown
        EQmarkerDropDownLabel          matlab.ui.control.Label
        coloringButtonGroup            matlab.ui.container.ButtonGroup
        whiteButton_2                  matlab.ui.control.RadioButton
        blackButton_2                  matlab.ui.control.RadioButton
        ShadingButtonGroup             matlab.ui.container.ButtonGroup
        interpButton_2                 matlab.ui.control.RadioButton
        flatButton_2                   matlab.ui.control.RadioButton
        brightenButton                 matlab.ui.control.Button
        darkenButton                   matlab.ui.control.Button
        axesmuiButton                  matlab.ui.control.Button
    end

    
    properties (Access = private)
        fig % figure that will be modified by this application
        ploc = 1% handle to coastline
        plof = 1% handle to fault
        ploe = 1% handle to earthquakes
        plos = 1
        ploli = 1
        depq = 1
        depf = 1 % unsure
        desp = 1
        depl = 1
        resmap = 1
        maptype = 1 % unsure
        faults
        coast
        bor % border
        equ % earthquakes
        resu % resultate
        gx
        gy
        spec = 1 % special objects
        lines = 1 % lines
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, fig)
            app.fig = fig;
        end

        % Value changed function: EQmarkerDropDown
        function EQmarkerDropDownValueChanged(app, event)
            value = app.EQmarkerDropDown.Value;
            % popeq_Callback
            
            A = app.equ;
            if app.maptype==1
                tmap=app.tmap;
                tmapleg=app.tmapleg;
            end
            figure_w_normalized_uicontrolunits(app.plma);
            if app.depq==1  &&  contains(value,'on top')  &&  app.maptype==1
                [lat,lon] = meshgrat(tmap,tmapleg);
                app.depq = interp2(lon,lat,tmap,A(:,1),A(:,2));
                close(hw);
            end
            switch value
                
                case "EQ (dot)"
                    app.ploe=plotm(A.Latitude,A.Longitude,'ro');
                    set(app.ploe,'LineWidth',0.1,'MarkerSize',2,...
                        'MarkerFaceColor','w','MarkerEdgeColor','r');
                    if app.maptype==1;zdatam(handlem('allline'),max(tmap(:)));end
                    
                case "EQ (o)"
                    app.ploe=plotm(A.Latitude,A.Longitude,'ro');
                    set(app.ploe,'LineWidth',0.1,'MarkerSize',3,...
                        'MarkerFaceColor','w','MarkerEdgeColor','k');
                    if app.maptype==1;zdatam(handlem('allline'),max(tmap(:)));end
                    
                case "EQ (dot) on top (slow)"
                    if app.maptype==1
                        app.ploe=plot3m(A.Latitude,A.Longitude,app.depq+25,'ro');
                        set(app.ploe,'LineWidth',0.1,'MarkerSize',2,...
                            'MarkerFaceColor','w','MarkerEdgeColor','r');
                    end
                case "EQ (o) on top (slow)"
                    if app.maptype==1
                        app.ploe=plot3m(A.Latitude,A.Longitude,app.depq+25,'ro');
                        set(app.ploe,'LineWidth',0.1,'MarkerSize',3,...
                            'MarkerFaceColor','w','MarkerEdgeColor','k');
                    end
                    
                    
                case "No EQ"
                    delete(app.ploe);
                otherwise
                    error("unexpected choice");
                    
            end
        end

        % Value changed function: DEMresolutionListBox
        function DEMresolutionListBoxValueChanged(app, event)
            value = app.DEMresolutionListBox.Value;
            % listdem_Callback
        end

        % Value changed function: CoastlineresolutionListBox
        function CoastlineresolutionListBoxValueChanged(app, event)
            value = app.CoastlineresolutionListBox.Value;
            % listvec_Callback
        end

        % Value changed function: verticalexaggerationEditField
        function verticalexaggerationEditFieldValueChanged(app, event)
            value = app.verticalexaggerationEditField.Value;
            % vexag_Callback
            figure(app.plma);
            daspectm('m', value);
            tightmap
        end

        % Button pushed function: DEMButton
        function DEMButtonPushed(app, event)
            % gemapwi_Callback
            disp('this is topo|gemapwi')
            global psloc
            global pgt30
            global pgdem
            
            inp = app.DEMresolutionListBox.Value;
            app.plma = figure_w_normalized_uicontrolunits( ...
                'Name','Topographic Map',...
                'NumberTitle','off', ...
                'Color',[1 1 1],...
                'Visible','off');
            
            app.axm = axesm('MapProjection','eqdcylin');
            figure(app.plma);
            set(app.plma,'Visible','on');
            
            lo1 = app.bor(1);
            lo2=app.bor(2);
            la1=app.bor(3);
            la2=app.bor(4);
            fac = 1;
            if abs(la2-la1) > 10 || abs(lo2-lo1) > 10
                def = {'3'};
                ni2 = inputdlg('Decimation factor for DEM data?','Input',1,def);
                l = ni2{:};
                fac = str2double(l);
            end
            switch(inp)
                case "30 arc sec resolution (GTOPO30)"
                    gtopo30s([la1 la2],[lo1 lo2]);
                    [tmap, tmapleg] = gtopo302(pgt30,fac,[la1 la2],[lo1 lo2]);
                    cd (psloc); %Stao des Skriptes
                    tmap(isnan(tmap)) = -1; %Replace the NaNs in the ocean with -1 to color them blue
                    [latlim,lonlim] = limitm(tmap,tmapleg);
                    
                case "GLOBE Digital Elevation Map"
                    fname = globedems([la1 la2],[lo1 lo2]);
                    try
                        [tmap, tmapleg] = globedem(fname{1},fac,[la1 la2],[lo1 lo2]);
                    catch ME
                        errordlg(ME.message,'ERROR:topo');
                        return
                    end
                    cd (psloc); %Stao des Skriptes
                    tmap(isnan(tmap)) = nan; %Replace the NaNs in the ocean with -1 to color them blue
                    [latlim,lonlim] = limitm(tmap,tmapleg);
                    
                case "2 deg resolution"
                    
                    [lat,lon, gtmap] = satbath(fac,[la1 la2],[lo1 lo2]); % general matrix map
                    gtmap(isnan(gtmap)) = -1;
                    latlim = [la1 la2];
                    lonlim = [lo1 lo2];
                    pack
                    [map,maplegend] = nanm(latlim,lonlim,30);
                    % original was about 1 cell per degree
                    tmap = imbedm(lat,lon,gtmap,map,maplegend);
                    tmapleg=maplegend;
                    
                case "5 deg resolution"
                    region = [la1 la2 lo1 lo2];
                    [tmap,tmapleg] = tbase(fac,[la1 la2 ],[lo1 lo2] );
                    [latlim,lonlim] = limitm(tmap,tmapleg);
                    tmap(isnan(tmap)) = -1; %Replace the NaNs in the ocean with -1 to color them blue
            end
            
            lai=abs((la2-la1)/4);
            tilat=transpose([la1+0.5*lai la1+1.5*lai la1+2.5*lai la1+3.5*lai]);
            loi=abs((lo2-lo1)/4);
            tilon=transpose([lo1+0.5*loi lo1+1.5*loi lo1+2.5*loi lo1+3.5*loi]);
            
            meshm(tmap,tmapleg,size(tmap),tmap);demcmap(tmap);
            setm(app.axm,'maplatlimit',latlim,'maplonlimit',lonlim);
            
            if min(tmap(:)) > 0
                demcmap(tmap,100,[0 0.3 1],[]);
                daspectm('m',05);
            else
                demcmap(tmap)
                daspectm('m',05);
            end
            
            showaxes('hide');
            setm(app.axm,'meridianlabel','on','parallellabel','on',...
                'LabelUnits','dm',...
                'mlabelround',-2,...
                'plabelround',-2,...
                'grid','off',...
                'LabelFormat','compass',...
                'plabellocation',tilat,'mlabellocation',tilon,...
                'plinelocation',tilat,'mlinelocation',tilon,...
                'frame','off');
            shading flat
            
            zdatam(handlem('mlabel'),0);
            zdatam(handlem('plabel'),0);
            %   zdatam(handlem('frame'),0)
            camlight(-80,0); lighting phong; material([.8 1 0]);
            
            app.colbar=colorbar;
            set(app.colbar,'Position', [0.9 0.3 0.015 .3]) ;
            set(app.colbar,'visible','on','FontSize',10,'FontWeight','normal',...
                'FontWeight','normal','LineWidth',1,...
                'Box','on','TickDir','out');
            
            set(app.gemapwi,'String','NEW DEM')
            app.tmapleg=tmapleg;
            app.tmap=tmap;
            app.maptype=1;
            guidata(gcbo,handles);
            maptool;
            set(app.draw,'Visible','on');
        end

        % Button pushed function: VectorMapButton
        function VectorMapButtonPushed(app, event)
            % dvmap_Callback
            inp = app.listvec.String(app.listvec.Value);
            inp = extractbefore(inp,' '); %get first word, which will be one of: "crude", "low", "intermediate", "high"
            bor=app.bor;
            app.plma=figure_w_normalized_uicontrolunits( ...
                'Name','Topographic Map',...
                'NumberTitle','off', ...
                'Color',[1 1 1],...
                'Visible','off');
            
            app.axm=axesm('MapProjection','eqdcylin');
            figure_w_normalized_uicontrolunits(app.plma);
            set(app.plma,'Visible','on');
            
            lo1 = bor(1); lo2=bor(2);
            la1=bor(3); la2=bor(4);
            latlim=[la1 la2];
            lonlim=[lo1 lo2];
            
            switch inp
                case "crude"
                    load worldlo;
                    displaym(POline);
                    delete(handlem('International Boundary'));
                    
                case "low"
                    GSHHS('gshhs_c.b','createindex');
                    vdata = gshhs('gshhs_c.b',latlim,lonlim);
                    coli=displaym(vdata);
                    set(coli,'FaceColor',[1 1 1]);
                    
                case "intermediate"
                    GSHHS('gshhs_i.b','createindex');
                    vdata = gshhs('gshhs_i.b',latlim,lonlim);
                    coli=displaym(vdata);
                    set(coli,'FaceColor',[1 1 1]) ;
                    
                case "high"
                    GSHHS('gshhs_h.b','createindex');
                    vdata = gshhs('gshhs_h.b',latlim,lonlim);
                    coli=displaym(vdata);
                    set(coli,'FaceColor',[1 1 1]);
            end
            
            tilat=(abs(abs(latlim(1))-abs(latlim(2)))/4);
            tilon=(abs(abs(lonlim(1))-abs(lonlim(2)))/4);
            setm(app.axm,'maplatlimit',latlim,'maplonlimit',lonlim);
            setm(app.axm,'meridianlabel','on','parallellabel','on',...
                'plinelocation',tilat,'mlinelocation',tilon,...
                'glinestyle','-.',...
                'grid','off',...
                'plabellocation',tilat,'mlabellocation',tilon,...
                'LabelFormat','compass',...
                'flinewidth',3);
            showaxes('hide');
            app.maptype=2;
            app.coli=coli;
            set(app.pushbutton14,'String','NEW Vector Map');
            set(app.draw,'Visible','on');
            guidata(gcbo,handles);
            maptool;
        end

        % Value changed function: ListBox
        function ListBoxValueChanged(app, event)
            value = app.ListBox.Value;
            % listres_Callback
        end

        % Button pushed function: drawmapButton
        function drawmapButtonPushed(app, event)
            % draw_Callback
            
            if app.maptype==1
                tmap=app.tmap;
                tmapleg=app.tmapleg;
            end
            
            resu=app.resu;
            figure_w_normalized_uicontrolunits(app.plma);
            resmap=app.resmap;
            
            if resmap >1
                delete(resmap);
            end
            
            gx=app.gx;
            gy=app.gy;
            
            if app.maptype==1 %matrix map
                colorbar= app.colbar;
            end
            
            if app.maptype==2 % vector map
                app.colbar=colorbar;
                set(app.colbar,'Position', [0.9 0.3 0.015 .3]) ;
                set(app.colbar,'visible','on','FontSize',10,'FontWeight','normal',...
                    'FontWeight','normal','LineWidth',1,...
                    'Box','on','TickDir','out');
            end
            
            inp=get(app.listres,'Value');
            ren=resu(inp).data;
            figure_w_normalized_uicontrolunits(app.plma);
            
            if app.maptype==1 %matrix map
                [lat,lon] = meshgrat(tmap,tmapleg);
                [X , Y]  = meshgrid(gx,gy);
                ren = interp2(X,Y,ren,lon,lat);
                cmin=min(ren(:));
                cmax=max(ren(:));
                cmin=min(min(resu(inp).data));
                cmax=max(max(resu(inp).data));
                mi = min(ren(:));
                ren(isnan(ren)) = mi-20;
                
                ren(tmap < 0 & ren < 0) = 20;
                resmap=meshm(ren,tmapleg,size(tmap),tmap);
                daspectm('m',05);
                tightmap;
                view([0 90]);
                camlight; lighting phong;
                set(gca,'projection','perspective');
                j = jet;
                %j = j(64:-1:1,:);
                j = [ [ 0.85 0.9 0.9 ] ; j;[ 0.85 0.9 0.9 ] ];
                caxis([ cmin cmax ]);
                colormap(j);
                app.colbar=colorbar ;
                app.resmap=resmap;
                delete(colorbar);
                shading flat;
            end
            
            if app.maptype==2 %vector map
                resmap=pcolorm(ren);
                j = jet;
                colormap(j);
                app.colbar=colorbar ;
                app.resmap=resmap;
                zdatam(handlem('allline'))
                delete(colorbar);
                shading interp;
            end
            set(app.draw,'Visible','off');
        end

        % Button pushed function: drawcolorbarButton
        function drawcolorbarButtonPushed(app, event)
            % drawcob_Callback
            figure_w_normalized_uicontrolunits(app.plma);
            
            resu=app.resu;
            inp =get(app.listres,'Value');
            ret=resu(inp).lab;
            
            app.colbar=colorbar;
            set(app.colbar,'Position', [0.9 0.3 0.015 .3])
            set(app.colbar,'visible','on','FontSize',10,'FontWeight','normal',...
                'FontWeight','normal','LineWidth',1,...
                'Box','on','TickDir','out');
            colorbar;
            
            txt1 = text(...
                'Units','normalized',...
                'Position',[ 1.2 0.7 0 ],...
                'HorizontalAlignment','right',...
                'FontSize',10,....
                'FontWeight','normal',...
                'String',ret);
        end

        % Button pushed function: axesmuiButton
        function axesmuiButtonPushed(app, event)
            %callaxesmui_Callback
            axesmui (app.axm);
        end

        % Button pushed function: darkenButton
        function darkenButtonPushed(app, event)
            %calldarken_callback
            figure(app.plma);
            brighten(app.plma, -.1)
        end

        % Button pushed function: brightenButton
        function brightenButtonPushed(app, event)
            % callligghten_callback
            figure(app.plma);
            brighten(app.plma,+.1);
        end

        % Selection changed function: ShadingButtonGroup
        function ShadingButtonGroupSelectionChanged(app, event)
            selectedButton = app.ShadingButtonGroup.SelectedObject;
            if selectedButton == app.flatButton_2
                % maflat_Callback
                figure(app.plma);
            else
                figure(app.plma);
                shading interp
                % mainterp_Callback
            end
        end

        % Selection changed function: coloringButtonGroup
        function coloringButtonGroupSelectionChanged(app, event)
            selectedButton = app.coloringButtonGroup.SelectedObject;
            if selectedButton == app.blackButton_2
                %wtob_Callback
                figure(app.plma);
                whitebg('black');
                set(gcf,'color','k');
            else
                %btow_Callback
                figure(app.plma);
                whitebg('white');
                set(gcf,'color','w');
            end
        end

        % Value changed function: faultsDropDown
        function faultsDropDownValueChanged(app, event)
            value = app.faultsDropDown.Value;
            % popfau_Callback
            depf=app.depf;
            faults=app.faults;
            coast=app.coast;
            if app.maptype==1
                tmap=app.tmap;
                tmapleg=app.tmapleg;
            end
            plof=app.plof;
            figure_w_normalized_uicontrolunits(app.plma);
            inp =app.pop2.String{app.pop2.Value};
            
            switch inp
                case "Faults"
                    if depf==1 &&  app.maptype==1
                        clear('depf');
                        [lat,lon] = meshgrat(tmap,tmapleg);
                        depf= interp2(lon,lat,tmap,faults(:,1),faults(:,2));
                    end
                    plof = plotm(faults(:,2),faults(:,1),'m','Linewidth',2);
                    if app.maptype==1
                        zdatam(handlem('allline'),max(tmap(:)));
                    end
                case "Faults on topo (slow)"
                    plof = plot3m(faults(:,2),faults(:,1),depf+25,'m','Linewidth',2);
                case "No faults"
                    delete(plof);
                case "Coastlines"
                    ploc = plot3m(coast(:,2),coast(:,1),1,'k','Linewidth',2)
                case "No Coastlines"
                    delete (ploc)
                otherwise
                    error("unexpected value");
            end
            app.ploc=ploc;
            app.plof=plof;
            app.depf=depf;
        end

        % Value changed function: SpecialLinesDropDown
        function SpecialLinesDropDownValueChanged(app, event)
            value = app.SpecialLinesDropDown.Value;
            % ploli_Callback
            
            inp =app.pop4.String(app.pop4.Value);
            if ~(inp == "No Special Objects")
                [file1,path1] = uigetfile([ '*.txt'],'File containing  my, mx ');
                s=load(file1);
            end
            depl=app.depl;
            lines=app.lines;
            if app.maptype==1
                tmap=app.tmap;
                tmapleg=app.tmapleg;
            end
            ploli=app.ploli;
            figure_w_normalized_uicontrolunits(app.plma);
            if depl==1  &&  contains(inp,'on topo')  &&  app.maptype==1
                [lat,lon] = meshgrat(tmap,tmapleg);
                depl = interp2(lon,lat,tmap,s(:,1),s(:,2));
            end
            switch inp
                case "Special Lines"
                    ploli = plotm(s(:,2),s(:,1),'m','Linewidth',2);
                    if app.maptype==1
                        zdatam(handlem('allline'),max(tmap(:)));
                    end
                case "Special Lines on topo"
                    if app.maptype==1
                        ploli = plot3m(s(:,2),s(:,1),depl+25,'m','Linewidth',2);end
                case "No Special Objects"
                    delete(ploli) ;
                otherwise
                    error("unknown selection");
            end
            
            app.ploli=ploli;
            app.depl=depl;
        end

        % Value changed function: SpecialObjectsDropDown
        function SpecialObjectsDropDownValueChanged(app, event)
            value = app.SpecialObjectsDropDown.Value;
            % plopspec_Callback
            s=app.spec;
            inp = app.pop3.String(app.pop3.Value);
            if ~inp=="No Special Objects"
                [file1,path1] = uigetfile([ '*.txt'],'File containing  my, mx ');
                s=load(file1);
            end
            desp=app.desp;
            if app.maptype==1
                tmap=app.tmap;
                tmapleg=app.tmapleg;
            end
            plos=app.plos;
            figure_w_normalized_uicontrolunits(app.plma);
            
            if desp==1  &&  contains(inp,'on topo') &&  app.maptype==1
                [lat,lon] = meshgrat(tmap,tmapleg);
                desp = interp2(lat,lon,tmap,s(:,1),s(:,2));
            end
            
            switch inp
                case "Special Objects (star)"
                    plos=plotm(s(:,1),s(:,2),'*');
                    set(plos,'LineWidth',0.1,'MarkerSize',3,...
                        'MarkerFaceColor','w','Marker','*','MarkerEdgeColor','r');
                    if app.maptype==1;zdatam(handlem('allline'),max(tmap(:))); end
                    
                case "Special Objects (triangle)"
                    plos=plotm(s(:,1),s(:,2),'v');
                    set(plos,'LineWidth',0.1,'MarkerSize',3,...
                        'MarkerFaceColor','w','Marker','v','MarkerEdgeColor','r');
                    if app.maptype==1;zdatam(handlem('allline'),max(tmap(:)));end
                    
                case "Special Objects (star) on topo"
                    if app.maptype==1
                        plos=plot3m(s(:,1),s(:,2),desp+25,'*');
                        set(plos,'LineWidth',0.1,'MarkerSize',3,...
                            'MarkerFaceColor','w','Marker','*','MarkerEdgeColor','r');
                    end
                case "Special Objects (triangle) on topo"
                    if  app.maptype==1
                        plos=plot3m(s(:,1),s(:,2),desp+25,'v');
                        set(plos,'LineWidth',0.1,'MarkerSize',3,...
                            'MarkerFaceColor','w','Marker','v','MarkerEdgeColor','r');
                    end
                case "No Special Objects"
                    delete(plos);
                otherwise
                    error('unknown choice');
            end
            app.plos=plos;
            app.depsp=desp;
        end

        % Value changed function: colorsDropDown
        function colorsDropDownValueChanged(app, event)
            value = app.colorsDropDown.Value;
            % colorsty_Callback
            disp('this is topo|colorsty')
            tmap=app.tmap;
            figure_w_normalized_uicontrolunits(app.plma);
            inp =get(app.pop5,'Value');
            if inp == 3 % colormap(decmap)
                if min(tmap(:)) > 0
                    demcmap(tmap,100,[0 0.3 1],[]);
                    daspectm('m',05);
                else
                    demcmap(tmap);
                    daspectm('m',05);
                end
            elseif inp == 1 %greyscale 1
                demcmap(tmap,265,[1 1 1],[.3 .3 .3; .8 .8 .8]);
                daspectm('m',05);
            elseif inp==2 %greyscale 2
                demcmap(tmap,265,[1 1 1],[.5 .5 .5; .8 .8 .8]);
                daspectm('m',05);
            end
            app.tmap=tmap;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.Panel = uipanel(app.UIFigure);
            app.Panel.Title = 'Panel';
            app.axesmuiButton = uibutton(app.Panel, 'push');
            app.axesmuiButton.ButtonPushedFcn = createCallbackFcn(app, @axesmuiButtonPushed, true);
            app.axesmuiButton.Text = 'axesmui';
            app.darkenButton = uibutton(app.Panel, 'push');
            app.darkenButton.ButtonPushedFcn = createCallbackFcn(app, @darkenButtonPushed, true);
            app.darkenButton.Text = 'darken';
            app.brightenButton = uibutton(app.Panel, 'push');
            app.brightenButton.ButtonPushedFcn = createCallbackFcn(app, @brightenButtonPushed, true);
            app.brightenButton.Text = 'brighten';
            app.ShadingButtonGroup = uibuttongroup(app.Panel);
            app.ShadingButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ShadingButtonGroupSelectionChanged, true);
            app.ShadingButtonGroup.Title = 'Shading';
            app.flatButton_2 = uiradiobutton(app.ShadingButtonGroup);
            app.flatButton_2.Text = 'flat';
            app.flatButton_2.Value = true;
            app.interpButton_2 = uiradiobutton(app.ShadingButtonGroup);
            app.interpButton_2.Text = 'interp';
            app.coloringButtonGroup = uibuttongroup(app.Panel);
            app.coloringButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @coloringButtonGroupSelectionChanged, true);
            app.coloringButtonGroup.Title = 'coloring';
            app.blackButton_2 = uiradiobutton(app.coloringButtonGroup);
            app.blackButton_2.Text = 'black';
            app.blackButton_2.Value = true;
            app.whiteButton_2 = uiradiobutton(app.coloringButtonGroup);
            app.whiteButton_2.Text = 'white';
            app.EQmarkerDropDownLabel = uilabel(app.Panel);
            app.EQmarkerDropDownLabel.Text = 'EQ marker';
            app.EQmarkerDropDown = uidropdown(app.Panel);
            app.EQmarkerDropDown.Items = {'EQ (dot) ', 'EQ (o)', 'EQ (dot) on top (slow)', 'EQ (o) on top (slow)', 'No EQ'};
            app.EQmarkerDropDown.ValueChangedFcn = createCallbackFcn(app, @EQmarkerDropDownValueChanged, true);
            app.EQmarkerDropDown.Value = 'EQ (dot) ';
            app.faultsDropDownLabel = uilabel(app.Panel);
            app.faultsDropDownLabel.Text = 'faults';
            app.faultsDropDown = uidropdown(app.Panel);
            app.faultsDropDown.Items = {'Faults', 'Faults on topo (slow)', 'No faults', 'Coastlines', 'No Coastlines'};
            app.faultsDropDown.ValueChangedFcn = createCallbackFcn(app, @faultsDropDownValueChanged, true);
            app.faultsDropDown.Value = 'Faults';
            app.SpecialObjectsDropDownLabel = uilabel(app.Panel);
            app.SpecialObjectsDropDownLabel.Text = 'Special Objects';
            app.SpecialObjectsDropDown = uidropdown(app.Panel);
            app.SpecialObjectsDropDown.Items = {'Special Objects (star)', 'Special Objects (triangle)', 'Special Objects (star) on topo', 'Special Objects (triangle) on topo', 'No Special Objects'};
            app.SpecialObjectsDropDown.ValueChangedFcn = createCallbackFcn(app, @SpecialObjectsDropDownValueChanged, true);
            app.SpecialObjectsDropDown.Value = 'Special Objects (star)';
            app.SpecialLinesDropDownLabel = uilabel(app.Panel);
            app.SpecialLinesDropDownLabel.Text = 'Special Lines';
            app.SpecialLinesDropDown = uidropdown(app.Panel);
            app.SpecialLinesDropDown.Items = {'Special Lines ', 'Special Lines on topo', 'No Special Objects'};
            app.SpecialLinesDropDown.ValueChangedFcn = createCallbackFcn(app, @SpecialLinesDropDownValueChanged, true);
            app.SpecialLinesDropDown.Value = 'Special Lines ';
            app.colorsDropDownLabel = uilabel(app.Panel);
            app.colorsDropDownLabel.Text = 'colors';
            app.colorsDropDown = uidropdown(app.Panel);
            app.colorsDropDown.Items = {'grayscale 1', 'grayscale 2', 'colormap (decmap)'};
            app.colorsDropDown.ValueChangedFcn = createCallbackFcn(app, @colorsDropDownValueChanged, true);
            app.colorsDropDown.Value = 'grayscale 1';
            app.Panel2 = uipanel(app.UIFigure);
            app.Panel2.Title = 'Panel2';
            app.drawcolorbarButton = uibutton(app.Panel2, 'push');
            app.drawcolorbarButton.ButtonPushedFcn = createCallbackFcn(app, @drawcolorbarButtonPushed, true);
            app.drawcolorbarButton.Text = 'draw colorbar';
            app.drawmapButton = uibutton(app.Panel2, 'push');
            app.drawmapButton.ButtonPushedFcn = createCallbackFcn(app, @drawmapButtonPushed, true);
            app.drawmapButton.Text = 'draw map';
            app.ListBoxLabel = uilabel(app.Panel2);
            app.ListBoxLabel.Text = 'List Box';
            app.ListBox = uilistbox(app.Panel2);
            app.ListBox.ValueChangedFcn = createCallbackFcn(app, @ListBoxValueChanged, true);
            app.Panel3 = uipanel(app.UIFigure);
            app.Panel3.Title = 'Panel3';
            app.CoastlineresolutionListBoxLabel = uilabel(app.Panel3);
            app.CoastlineresolutionListBoxLabel.Text = 'Coastline resolution';
            app.CoastlineresolutionListBox = uilistbox(app.Panel3);
            app.CoastlineresolutionListBox.Items = {'crude', 'low', 'intermediate', 'high'};
            app.CoastlineresolutionListBox.ValueChangedFcn = createCallbackFcn(app, @CoastlineresolutionListBoxValueChanged, true);
            app.CoastlineresolutionListBox.Value = 'crude';
            app.DEMresolutionListBoxLabel = uilabel(app.Panel3);
            app.DEMresolutionListBoxLabel.Text = 'DEM resolution';
            app.DEMresolutionListBox = uilistbox(app.Panel3);
            app.DEMresolutionListBox.Items = {'30 arc sec resolution (GTOPO30)', 'GLOBE Digital Elevation Map', '2 deg resolution', '5 deg resolution'};
            app.DEMresolutionListBox.ValueChangedFcn = createCallbackFcn(app, @DEMresolutionListBoxValueChanged, true);
            app.DEMresolutionListBox.Value = '30 arc sec resolution (GTOPO30)';
            app.VectorMapButton = uibutton(app.Panel3, 'push');
            app.VectorMapButton.ButtonPushedFcn = createCallbackFcn(app, @VectorMapButtonPushed, true);
            app.VectorMapButton.Text = 'Vector Map';
            app.verticalexaggerationLabel = uilabel(app.Panel3);
            app.verticalexaggerationLabel.Text = {'vertical'; 'exaggeration'};
            app.verticalexaggerationEditField = uieditfield(app.Panel3, 'numeric');
            app.verticalexaggerationEditField.ValueChangedFcn = createCallbackFcn(app, @verticalexaggerationEditFieldValueChanged, true);
            app.verticalexaggerationEditField.Value = 5;
            app.DEMButton = uibutton(app.Panel3, 'push');
            app.DEMButton.ButtonPushedFcn = createCallbackFcn(app, @DEMButtonPushed, true);
            app.DEMButton.Text = 'DEM';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = replacement_for_topo(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end