classdef topo_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1  matlab.ui.Figure
    end

    
    methods (Access = private)
        function varargout = btow_Callback(app, ~, ~, handles, varargin)
                figure_w_normalized_uicontrolunits(app.plma);
                whitebg('white');
                set(gcf,'color','w');
        end
        
        function varargout = callaxesmui_Callback(app, ~, ~, handles, varargin)
                axesmui (app.axm);
        end
        
        function varargout = callbrighten_Callback(app, ~, ~, handles, varargin)
                figure_w_normalized_uicontrolunits(app.plma);
                brighten(app.plma,+.1);
        end
        
        function varargout = calldarken_Callback(app, ~, ~, handles, varargin)
                figure_w_normalized_uicontrolunits(app.plma);
                brighten(app.plma,-.1);
        end
        
        function varargout = colorsty_Callback(app, ~, ~, handles, varargin)
                disp('this is topo|colorsty')
                tmap=app.tmap;
                figure_w_normalized_uicontrolunits(app.plma);
                inp =get(app.pop5,'Value');
                if inp == 3 % colormap(decmap)
                    if min(tmap(:)) > 0
                        demcmap(tmap,100,[0 0.3 1],[]);
                        daspectm('m',05);
                    else
                        demcmap(tmap);
                        daspectm('m',05);
                    end
                elseif inp == 1 %greyscale 1
                    demcmap(tmap,265,[1 1 1],[.3 .3 .3; .8 .8 .8]);
                    daspectm('m',05);
                elseif inp==2 %greyscale 2
                    demcmap(tmap,265,[1 1 1],[.5 .5 .5; .8 .8 .8]);
                    daspectm('m',05);
                end
                app.tmap=tmap;
        end
        
        function varargout = draw_Callback(app, ~, ~, handles, varargin)
                disp('this is topo|draw')
            
                if app.maptype==1
                    tmap=app.tmap;
                    tmapleg=app.tmapleg;
                end
            
                resu=app.resu;
                figure_w_normalized_uicontrolunits(app.plma);
                resmap=app.resmap;
            
                if resmap >1
                    delete(resmap);
                end
            
                gx=app.gx;
                gy=app.gy;
            
                if app.maptype==1 %matrix map
                    colorbar= app.colbar;
                end
            
                if app.maptype==2 % vector map
                    app.colbar=colorbar;
                    set(app.colbar,'Position', [0.9 0.3 0.015 .3]) ;
                    set(app.colbar,'visible','on','FontSize',10,'FontWeight','normal',...
                        'FontWeight','normal','LineWidth',1,...
                        'Box','on','TickDir','out');
                end
            
                inp=get(app.listres,'Value');
                ren=resu(inp).data;
                figure_w_normalized_uicontrolunits(app.plma);
            
                if app.maptype==1 %matrix map
                    [lat,lon] = meshgrat(tmap,tmapleg);
                    [X , Y]  = meshgrid(gx,gy);
                    ren = interp2(X,Y,ren,lon,lat);
                    cmin=min(ren(:));
                    cmax=max(ren(:));
                    cmin=min(min(resu(inp).data));
                    cmax=max(max(resu(inp).data));
                    mi = min(ren(:));
                    ren(isnan(ren)) = mi-20;
            
                    ren(tmap < 0 & ren < 0) = 20;
                    resmap=meshm(ren,tmapleg,size(tmap),tmap);
                    daspectm('m',05);
                    tightmap;
                    view([0 90]);
                    camlight; lighting phong;
                    set(gca,'projection','perspective');
                    j = jet;
                    %j = j(64:-1:1,:);
                    j = [ [ 0.85 0.9 0.9 ] ; j;[ 0.85 0.9 0.9 ] ];
                    caxis([ cmin cmax ]);
                    colormap(j);
                    app.colbar=colorbar ;
                    app.resmap=resmap;
                    delete(colorbar);
                    shading flat;
                end
            
                if app.maptype==2 %vector map
                    resmap=pcolorm(ren);
                    j = jet;
                    colormap(j);
                    app.colbar=colorbar ;
                    app.resmap=resmap;
                    zdatam(handlem('allline'))
                    delete(colorbar);
                    shading interp;
                end
                set(app.draw,'Visible','off');
                guidata(gcbo,handles);
                % --------------------------------------------------------------------
        end
        
        function varargout = drawcob_Callback(app, ~, ~, handles, varargin)
                disp('this is topo|drawcob')
                figure_w_normalized_uicontrolunits(app.plma);
            
                resu=app.resu;
                inp =get(app.listres,'Value');
                ret=resu(inp).lab;
            
                app.colbar=colorbar;
                set(app.colbar,'Position', [0.9 0.3 0.015 .3])
                set(app.colbar,'visible','on','FontSize',10,'FontWeight','normal',...
                    'FontWeight','normal','LineWidth',1,...
                    'Box','on','TickDir','out');
                colorbar;
            
                txt1 = text(...
                    'Units','normalized',...
                    'Position',[ 1.2 0.7 0 ],...
                    'HorizontalAlignment','right',...
                    'FontSize',10,....
                    'FontWeight','normal',...
                    'String',ret);
        end
        
        function varargout = dvmap_Callback(app, ~, ~, handles, varargin)
                disp('this is topo|dvmap')
                inp = app.listvec.String(app.listvec.Value);
                inp = extractbefore(inp,' '); %get first word, which will be one of: "crude", "low", "intermediate", "high"
                bor=app.bor;
                app.plma=figure_w_normalized_uicontrolunits( ...
                    'Name','Topographic Map',...
                    'NumberTitle','off', ...
                    'Color',[1 1 1],...
                    'Visible','off');
            
                app.axm=axesm('MapProjection','eqdcylin');
                figure_w_normalized_uicontrolunits(app.plma);
                set(app.plma,'Visible','on');
            
                lo1 = bor(1); lo2=bor(2);
                la1=bor(3); la2=bor(4);
                latlim=[la1 la2];
                lonlim=[lo1 lo2];
            
                switch inp
                    case "crude"
                        load worldlo;
                        displaym(POline);
                        delete(handlem('International Boundary'));
            
                    case "low"
                        GSHHS('gshhs_c.b','createindex');
                        vdata = gshhs('gshhs_c.b',latlim,lonlim);
                        coli=displaym(vdata);
                        set(coli,'FaceColor',[1 1 1]);
            
                    case "intermediate"
                        GSHHS('gshhs_i.b','createindex');
                        vdata = gshhs('gshhs_i.b',latlim,lonlim);
                        coli=displaym(vdata);
                        set(coli,'FaceColor',[1 1 1]) ;
            
                    case "high"
                        GSHHS('gshhs_h.b','createindex');
                        vdata = gshhs('gshhs_h.b',latlim,lonlim);
                        coli=displaym(vdata);
                        set(coli,'FaceColor',[1 1 1]);
                end
            
                tilat=(abs(abs(latlim(1))-abs(latlim(2)))/4);
                tilon=(abs(abs(lonlim(1))-abs(lonlim(2)))/4);
                setm(app.axm,'maplatlimit',latlim,'maplonlimit',lonlim);
                setm(app.axm,'meridianlabel','on','parallellabel','on',...
                    'plinelocation',tilat,'mlinelocation',tilon,...
                    'glinestyle','-.',...
                    'grid','off',...
                    'plabellocation',tilat,'mlabellocation',tilon,...
                    'LabelFormat','compass',...
                    'flinewidth',3);
                showaxes('hide');
                app.maptype=2;
                app.coli=coli;
                set(app.pushbutton14,'String','NEW Vector Map');
                set(app.draw,'Visible','on');
                guidata(gcbo,handles);
                maptool;
        end
        
        function varargout = gemapwi_Callback(app, ~, ~, handles, varargin)
                disp('this is topo|gemapwi')
                global psloc;
                global pgt30;
                global pgdem;
            
                inp =app.listdem.String(app.listdem.Value);
                bor=app.bor;
                app.plma=figure_w_normalized_uicontrolunits( ...
                    'Name','Topographic Map',...
                    'NumberTitle','off', ...
                    'Color',[1 1 1],...
                    'Visible','off');
            
                app.axm=axesm('MapProjection','eqdcylin');
                figure_w_normalized_uicontrolunits(app.plma);
                set(app.plma,'Visible','on');
            
                lo1 = bor(1); lo2=bor(2);
                la1=bor(3); la2=bor(4);
                fac = 1;
                if abs(la2-la1) > 10 | abs(lo2-lo1) > 10
                    def = {'3'};
                    ni2 = inputdlg('Decimation factor for DEM data?','Input',1,def);
                    l = ni2{:};
                    fac = str2double(l);
                end
                switch(inp)
                    case "30 arc sec resolution (GTOPO30)"
                        gtopo30s([la1 la2],[lo1 lo2]);
                        [tmap, tmapleg] = gtopo302(pgt30,fac,[la1 la2],[lo1 lo2]);
                        cd (psloc); %Stao des Skriptes
                        tmap(isnan(tmap)) = -1; %Replace the NaNs in the ocean with -1 to color them blue
                        [latlim,lonlim] = limitm(tmap,tmapleg);
            
                    case "GLOBE Digital Elevation Map"
                        fname = globedems([la1 la2],[lo1 lo2]);
                        try
                            [tmap, tmapleg] = globedem(fname{1},fac,[la1 la2],[lo1 lo2]);
                        catch ME
                            errordlg(ME.message,'ERROR:topo');
                            return
                        end
                        cd (psloc); %Stao des Skriptes
                        tmap(isnan(tmap)) = nan; %Replace the NaNs in the ocean with -1 to color them blue
                        [latlim,lonlim] = limitm(tmap,tmapleg);
            
                    case "2 deg resolution"
            
                        [lat,lon, gtmap] =satbath(fac,[la1 la2],[lo1 lo2]); % general matrix map
                        gtmap(isnan(gtmap)) = -1;
                        latlim = [la1 la2];
                        lonlim = [lo1 lo2];
                        pack
                        [map,maplegend] = nanm(latlim,lonlim,30);
                        % original was about 1 cell per degree
                        tmap = imbedm(lat,lon,gtmap,map,maplegend);
                        tmapleg=maplegend;
            
                    case "5 deg resolution"
                        region = [la1 la2 lo1 lo2];
                        [tmap,tmapleg] = tbase(fac,[la1 la2 ],[lo1 lo2] );
                        [latlim,lonlim] = limitm(tmap,tmapleg);
                        tmap(isnan(tmap)) = -1; %Replace the NaNs in the ocean with -1 to color them blue
                end
            
                lai=abs((la2-la1)/4);
                tilat=transpose([la1+0.5*lai la1+1.5*lai la1+2.5*lai la1+3.5*lai]);
                loi=abs((lo2-lo1)/4);
                tilon=transpose([lo1+0.5*loi lo1+1.5*loi lo1+2.5*loi lo1+3.5*loi]);
            
                meshm(tmap,tmapleg,size(tmap),tmap);demcmap(tmap);
                setm(app.axm,'maplatlimit',latlim,'maplonlimit',lonlim);
            
                if min(tmap(:)) > 0
                    demcmap(tmap,100,[0 0.3 1],[]);
                    daspectm('m',05);
                else
                    demcmap(tmap)
                    daspectm('m',05);
                end
            
                showaxes('hide');
                setm(app.axm,'meridianlabel','on','parallellabel','on',...
                    'LabelUnits','dm',...
                    'mlabelround',-2,...
                    'plabelround',-2,...
                    'grid','off',...
                    'LabelFormat','compass',...
                    'plabellocation',tilat,'mlabellocation',tilon,...
                    'plinelocation',tilat,'mlinelocation',tilon,...
                    'frame','off');
                shading flat
            
                zdatam(handlem('mlabel'),0);
                zdatam(handlem('plabel'),0);
                %   zdatam(handlem('frame'),0)
                camlight(-80,0); lighting phong; material([.8 1 0]);
            
                app.colbar=colorbar;
                set(app.colbar,'Position', [0.9 0.3 0.015 .3]) ;
                set(app.colbar,'visible','on','FontSize',10,'FontWeight','normal',...
                    'FontWeight','normal','LineWidth',1,...
                    'Box','on','TickDir','out');
            
                set(app.gemapwi,'String','NEW DEM')
                app.tmapleg=tmapleg;
                app.tmap=tmap;
                app.maptype=1;
                guidata(gcbo,handles);
                maptool;
                set(app.draw,'Visible','on');
        end
        
        function varargout = maflat_Callback(app, ~, ~, handles, varargin)
                figure_w_normalized_uicontrolunits(app.plma);
                shading flat;
        end
        
        function varargout = mainterp_Callback(app, ~, ~, handles, varargin)
                figure_w_normalized_uicontrolunits(app.plma);
                shading interp;
        end
        
        function varargout = ploli_Callback(app, ~, ~, handles, varargin)
                disp('this is topo|ploli')
                inp =app.pop4.String(app.pop4.Value);
                if ~(inp == "No Special Objects")
                    [file1,path1] = uigetfile([ '*.txt'],'File containing  my, mx ');
                    s=load(file1);
                end
                depl=app.depl;
                lines=app.lines;
                if app.maptype==1
                    tmap=app.tmap;
                    tmapleg=app.tmapleg;
                end
                ploli=app.ploli;
                figure_w_normalized_uicontrolunits(app.plma);
                if depl==1  &&  contains(inp,'on topo')  &&  app.maptype==1
                    [lat,lon] = meshgrat(tmap,tmapleg);
                    depl = interp2(lon,lat,tmap,s(:,1),s(:,2));
                end
                switch inp
                    case "Special Lines"
                        ploli = plotm(s(:,2),s(:,1),'m','Linewidth',2);
                        if app.maptype==1
                            zdatam(handlem('allline'),max(tmap(:)));
                        end
                    case "Special Lines on topo"
                        if app.maptype==1
                            ploli = plot3m(s(:,2),s(:,1),depl+25,'m','Linewidth',2);end
                    case "No Special Objects"
                        delete(ploli) ;
                    otherwise
                        error("unknown selection");
                end
            
                app.ploli=ploli;
                app.depl=depl;
                guidata(gcbo,handles);
        end
        
        function varargout = popeq_Callback(app, ~, ~, handles, varargin)
                disp('this is topo|popeq')
                A=app.equ;
                depq=app.depq;
                if app.maptype==1
                    tmap=app.tmap;
                    tmapleg=app.tmapleg;
                end
                ploe=app.ploe;
                figure_w_normalized_uicontrolunits(app.plma);
                inp = app.pop1.String{app.pop1.Value};
                if depq==1  &&  contains(inp,'on top')  &&  app.maptype==1
                    [lat,lon] = meshgrat(tmap,tmapleg);
                    depq = interp2(lon,lat,tmap,A(:,1),A(:,2));
                    close(hw);
                end
                switch inp
            
                    case "EQ (dot)"
                        ploe=plotm(A.Latitude,A.Longitude,'ro');
                        set(ploe,'LineWidth',0.1,'MarkerSize',2,...
                            'MarkerFaceColor','w','MarkerEdgeColor','r');
                        if app.maptype==1;zdatam(handlem('allline'),max(tmap(:)));end
            
                    case "EQ (o)"
                        ploe=plotm(A.Latitude,A.Longitude,'ro');
                        set(ploe,'LineWidth',0.1,'MarkerSize',3,...
                            'MarkerFaceColor','w','MarkerEdgeColor','k');
                        if app.maptype==1;zdatam(handlem('allline'),max(tmap(:)));end
            
                    case "EQ (dot) on top (slow)"
                        if app.maptype==1
                            ploe=plot3m(A.Latitude,A.Longitude,depq+25,'ro');
                            set(ploe,'LineWidth',0.1,'MarkerSize',2,...
                                'MarkerFaceColor','w','MarkerEdgeColor','r');
                        end
                    case "EQ (o) on top (slow)"
                        if app.maptype==1
                            ploe=plot3m(A.Latitude,A.Longitude,depq+25,'ro');
                            set(ploe,'LineWidth',0.1,'MarkerSize',3,...
                                'MarkerFaceColor','w','MarkerEdgeColor','k');
                        end
            
            
                    case "No EQ"
                        delete(ploe);
                    otherwise
                        error("unexpected choice");
            
                end
                app.ploe=ploe;
                app.depq=depq;
            
                guidata(gcbo,handles);
        end
        
        function varargout = popfau_Callback(app, ~, ~, handles, varargin)
                disp('this is topo|popfau')
                depf=app.depf;
                faults=app.faults;
                coast=app.coast;
                if app.maptype==1
                    tmap=app.tmap;
                    tmapleg=app.tmapleg;
                end
                plof=app.plof;
                figure_w_normalized_uicontrolunits(app.plma);
                inp =app.pop2.String{app.pop2.Value};
            
                switch inp
                    case "Faults"
                        if depf==1 &&  app.maptype==1
                            clear('depf');
                            [lat,lon] = meshgrat(tmap,tmapleg);
                            depf= interp2(lon,lat,tmap,faults(:,1),faults(:,2));
                        end
                        plof = plotm(faults(:,2),faults(:,1),'m','Linewidth',2);
                        if app.maptype==1
                            zdatam(handlem('allline'),max(tmap(:)));
                        end
                    case "Faults on topo (slow)"
                        plof = plot3m(faults(:,2),faults(:,1),depf+25,'m','Linewidth',2);
                    case "No faults"
                        delete(plof);
                    case "Coastlines"
                        ploc=plot3m(coast(:,2),coast(:,1),1,'k','Linewidth',2)
                    case "No Coastlines"
                        delete (ploc)
                    otherwise
                        error("unexpected value");
                end
                app.ploc=ploc;
                app.plof=plof;
                app.depf=depf;
                guidata(gcbo,handles)
        end
        
        function varargout = popspec_Callback(app, ~, ~, handles, varargin)
                disp('this is topo|popspec')
                s=app.spec;
                inp = app.pop3.String(app.pop3.Value);
                if ~inp=="No Special Objects"
                    [file1,path1] = uigetfile([ '*.txt'],'File containing  my, mx ');
                    s=load(file1);
                end
                desp=app.desp;
                if app.maptype==1
                    tmap=app.tmap;
                    tmapleg=app.tmapleg;
                end
                plos=app.plos;
                figure_w_normalized_uicontrolunits(app.plma);
            
                if desp==1  &&  contains(inp,'on topo') &&  app.maptype==1
                    [lat,lon] = meshgrat(tmap,tmapleg);
                    desp = interp2(lat,lon,tmap,s(:,1),s(:,2));
                end
            
                switch inp
                    case "Special Objects (star)"
                        plos=plotm(s(:,1),s(:,2),'*');
                        set(plos,'LineWidth',0.1,'MarkerSize',3,...
                            'MarkerFaceColor','w','Marker','*','MarkerEdgeColor','r');
                        if app.maptype==1;zdatam(handlem('allline'),max(tmap(:))); end
            
                    case "Special Objects (triangle)"
                        plos=plotm(s(:,1),s(:,2),'v');
                        set(plos,'LineWidth',0.1,'MarkerSize',3,...
                            'MarkerFaceColor','w','Marker','v','MarkerEdgeColor','r');
                        if app.maptype==1;zdatam(handlem('allline'),max(tmap(:)));end
            
                    case "Special Objects (star) on topo"
                        if app.maptype==1
                            plos=plot3m(s(:,1),s(:,2),desp+25,'*');
                            set(plos,'LineWidth',0.1,'MarkerSize',3,...
                                'MarkerFaceColor','w','Marker','*','MarkerEdgeColor','r');
                        end
                    case "Special Objects (triangle) on topo"
                        if  app.maptype==1
                            plos=plot3m(s(:,1),s(:,2),desp+25,'v');
                            set(plos,'LineWidth',0.1,'MarkerSize',3,...
                                'MarkerFaceColor','w','Marker','v','MarkerEdgeColor','r');
                        end
                    case "No Special Objects"
                        delete(plos);
                    otherwise
                        error('unknown choice');
                end
                app.plos=plos;
                app.depsp=desp;
                guidata(gcbo,handles)
        end
        
        function varargout = vexag_Callback(app, ~, ~, handles, varargin)
                disp('this is topo|vexag')
                figure_w_normalized_uicontrolunits(app.plma);
                exavg=get(app.edit1,'string');
                fexavg=str2double(exavg);
                daspectm('m',fexavg);
                tightmap
        end
        
        function varargout = wtob_Callback(app, ~, ~, handles, varargin)
                figure_w_normalized_uicontrolunits(app.plma);
                whitebg('black');
                set(gcf,'color','k');
        end
        
    end
    

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = topo_App

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.figure1)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end