classdef topo_selector < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        DEMButton                      matlab.ui.control.Button
        VerticalExaggerationEditFieldLabel  matlab.ui.control.Label
        VerticalExaggerationEditField  matlab.ui.control.NumericEditField
        VectorMapButton                matlab.ui.control.Button
        Panel                          matlab.ui.container.Panel
        axesmuiButton                  matlab.ui.control.Button
        darkenButton                   matlab.ui.control.Button
        brightenButton                 matlab.ui.control.Button
        EQDropDownLabel                matlab.ui.control.Label
        EQDropDown                     matlab.ui.control.DropDown
        FaultsDropDownLabel            matlab.ui.control.Label
        FaultsDropDown                 matlab.ui.control.DropDown
        SpecialObjDropDownLabel        matlab.ui.control.Label
        SpecialObjDropDown             matlab.ui.control.DropDown
        SpecialLinesDropDownLabel      matlab.ui.control.Label
        SpecialLinesDropDown           matlab.ui.control.DropDown
        ColorSchemeDropDownLabel       matlab.ui.control.Label
        ColorSchemeDropDown            matlab.ui.control.DropDown
        ShadingButtonGroup             matlab.ui.container.ButtonGroup
        interpButton                   matlab.ui.control.RadioButton
        flatButton                     matlab.ui.control.RadioButton
        BackgroundColorButtonGroup     matlab.ui.container.ButtonGroup
        blackButton                    matlab.ui.control.RadioButton
        whiteButton                    matlab.ui.control.RadioButton
        DEMsourceDropDownLabel         matlab.ui.control.Label
        DEMsourceDropDown              matlab.ui.control.DropDown
        CoastlineResolutionDropDownLabel  matlab.ui.control.Label
        CoastlineResolutionDropDown    matlab.ui.control.DropDown
        drawmapButton                  matlab.ui.control.Button
        drawcolorbarButton             matlab.ui.control.Button
        ListBoxLabel                   matlab.ui.control.Label
        ListBox                        matlab.ui.control.ListBox
    end

    
    properties (Access = private)
        map_figure % handle to the map figure (plma)
        map_axis % Description
        tmap % Description
        maptype = 1 % 1: matrix map,  2: vector map
        coli % handle, from displaym
        border % was 'bor'
        tmapleg % Description
        gx % grid x
        gy % grid y
        colbar % handle to colorbar
        resmap % Description
        results % was handles.resu
        equ % Description
        faults % Description
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, borders, eqs, faults, coast, resu, gx, gy, s)
            app.brightenButton.UserData = .1;
            app.darkenButton.UserData = -.1;
            app.results = resu;
            app.border = borders;
            app.equ = eqs;
            app.faults = faults;
            app.coast = coast;
            app.gx = gx;
            app.gy = gy;
            
            
            if s == 1
                conres = struct2cell(handles.resu);
                inp = conres(2,1,1);
                for i = 2 : size(handles.resu, 2)
                    inp = [inp; conres(2,1,i)];
                end
                app.ListBox.Items = inp;
            end
            app.drawmapButton.Visible = 'off';
            
        end

        % Value changed function: VerticalExaggerationEditField
        function VerticalExaggerationEditFieldValueChanged(app, event)
            value = app.VerticalExaggerationEditField.Value;
            daspectm('m', value);
            tightmap;
        end

        % Selection changed function: ShadingButtonGroup
        function ShadingButtonGroupSelectionChanged(app, event)
            selectedButton = app.ShadingButtonGroup.SelectedObject;
            shading(selectedButton.Text);
        end

        % Button pushed function: brightenButton, darkenButton
        function brightendarkenButtonPushed(app, event)
            value = event.Source.UserData;
            brighten(app.map_figure, value);
        end

        % Selection changed function: BackgroundColorButtonGroup
        function BackgroundColorButtonGroupSelectionChanged(app, event)
            selectedButton = app.BackgroundColorButtonGroup.SelectedObject;
            whitebg(app.map_figure, selectedButton.Text);
        end

        % Button pushed function: axesmuiButton
        function axesmuiButtonPushed(app, event)
            axesmui(app.map_axis);
        end

        % Value changed function: ColorSchemeDropDown
        function ColorSchemeDropDownValueChanged(app, event)
            value = app.ColorSchemeDropDown.Value;
            
            switch value
                case 'grayscale 1'
                    demcmap(app.tmap, 265, [1 1 1], [.3 .3 .3; .8 .8 .8]);
                case 'grayscale 2'
                    demcmap(app.tmap, 265, [1 1 1], [.5 .5 .5; .8 .8 .8]);
                otherwise
                    if min(app.tmap(:)) > 0
                        demcmap( app.tmap, 100, [0 0.3 1], []);
                    end
            end
            daspectm('m',05);
        end

        % Value changed function: SpecialLinesDropDown
        function SpecialLinesDropDownValueChanged(app, event)
            value = app.SpecialLinesDropDown.Value;
            if value ~= "No special Objects"
                [file1, path1] = uigetfile('*.txt','File containing my, mx');
                s = load (fullfile(path1, file1));
            end
            % check the depl thing
            
            desp=handles.desp;
            if handles.maptype==1
                tmap=handles.tmap;
                tmapleg=handles.tmapleg;
            end
            plos=handles.plos;
            figure_w_normalized_uicontrolunits(app.map_figure);
            
            if desp==1  &&  contains(inp,'on topo') &&  handles.maptype==1
                [lat,lon] = meshgrat(tmap,tmapleg);
                desp = interp2(lat,lon,tmap,s(:,1),s(:,2));
            end
            
            switch inp
                case "Special Objects (star)"
                    plos=plotm(s(:,1),s(:,2),'*');
                    set(plos,'LineWidth',0.1,'MarkerSize',3,...
                        'MarkerFaceColor','w','Marker','*','MarkerEdgeColor','r');
                    if handles.maptype==1;zdatam(handlem('allline'),max(tmap(:))); end
                    
                case "Special Objects (triangle)"
                    plos=plotm(s(:,1),s(:,2),'v');
                    set(plos,'LineWidth',0.1,'MarkerSize',3,...
                        'MarkerFaceColor','w','Marker','v','MarkerEdgeColor','r');
                    if handles.maptype==1;zdatam(handlem('allline'),max(tmap(:)));end
                    
                case "Special Objects (star) on topo"
                    if handles.maptype==1
                        plos=plot3m(s(:,1),s(:,2),desp+25,'*');
                        set(plos,'LineWidth',0.1,'MarkerSize',3,...
                            'MarkerFaceColor','w','Marker','*','MarkerEdgeColor','r');
                    end
                case "Special Objects (triangle) on topo"
                    if  handles.maptype==1
                        plos=plot3m(s(:,1),s(:,2),desp+25,'v');
                        set(plos,'LineWidth',0.1,'MarkerSize',3,...
                            'MarkerFaceColor','w','Marker','v','MarkerEdgeColor','r');
                    end
                case "No Special Objects"
                    delete(plos);
                otherwise
                    error('unknown choice');
            end
            handles.plos=plos;
            handles.depsp=desp;
        end

        % Button pushed function: VectorMapButton
        function VectorMapButtonPushed(app, event)
            inp = app.CoastlineResolutionDropDown.Value; % one of: "crude", "low", "intermediate", "high"
            app.map_figure = figure('Name', 'Topographic Map', 'NumberTitle', 'off', ...
                'Color', [1 1 1], 'Visible', 'off');
            
            app.map_axis = axesm('MapProjection','eqdcylin');
            set(app.map_figure,'Visible','on');
            
            latlim = app.border([3,4]);
            lonlim = app.border([1,2]);
            
            switch inp
                case "crude"
                    % do nothing
                case "low"
                    fn = 'gshhs_c.b';
                case "intermediate"
                    fn = 'gshhs_i.b';
                case "high"
                    fn = 'gshhs_h.b';
            end
            if inp == "crude"
                load worldlo; % File no longer exists...
                displaym(POline);
                delete(handlem('International Boundary'));
            else
                GSHHS(fn, 'createindex');
                vdata = gshhs(fn, latlim, lonlim);
                coli = displaym(vdata); % possibly use geoshow() instead!
                set(coli, 'FaceColor', [1 1 1]);
            end
            tickformula = @(lims) abs(abs(lims(1)) - abs(lims(2))) / 4;
            tilat = tickformula(latlim);
            tilon = tickformula(lonlim);
            setm(app.map_axis, 'maplatlimit', latlim, 'maplonlimit', lonlim);
            setm(app.map_axis, 'meridianlabel', 'on', 'parallellabel', 'on',...
                'plinelocation', tilat,'mlinelocation', tilon,...
                'glinestyle', '-.',...
                'grid', 'off',...
                'plabellocation', tilat, 'mlabellocation', tilon,...
                'LabelFormat', 'compass',...
                'flinewidth', 3);
            showaxes('hide');
            app.maptype = 2;
            app.coli = coli;
            app.VectorMapButton.Text = 'NEW Vector Map';
            app.drawmapButton.Visible = 'on';
            maptool;
        end

        % Button pushed function: drawmapButton
        function drawmapButtonPushed(app, event)
            disp('this is topo|draw')
            
            if app.maptype == 1 % matrix map
                tmap = app.tmap;
                tmapleg = app.tmapleg;
            end
            % TOFIX: change following from handles to app
            resu = handles.resu;
            figure(app.map_figure);
            resmap = app.resmap;
            
            if resmap > 1
                delete(resmap);
            end
            
            if app.maptype == 1 % matrix map
                colorbar = app.colbar;
            end
            
            if app.maptype == 2 % vector map
                app.colbar = colorbar;
                set(app.colbar, 'Position', [0.9 0.3 0.015 .3], 'visible', 'on',...
                    'FontSize', 10, 'FontWeight', 'normal',...
                    'FontWeight', 'normal', 'LineWidth', 1,...
                    'Box', 'on', 'TickDir', 'out');
            end
            
            inp = get(handles.listres, 'Value');
            ren = resu(inp).data;
            figure(app.map_figure);
            
            if app.maptype == 1 %matrix map
                [lat, lon] = meshgrat(app.tmap, app.tmapleg);
                [X, Y]  = meshgrid(app.gx, app.gy);
                ren = interp2(X, Y, ren, lon, lat);
                cmin = min(ren(:));
                cmax = max(ren(:));
                cmin = min(min(resu(inp).data));
                cmax = max(max(resu(inp).data));
                ren(isnan(ren)) = min(ren(:)) - 20;
                
                ren(app.tmap < 0 & ren < 0) = 20;
                resmap = meshm(ren, app.tmapleg, size(app.tmap), app.tmap);
                daspectm('m', 05);
                tightmap;
                view([0 90]);
                camlight;
                lighting phong;
                set(gca, 'projection', 'perspective');
                j = jet;
                end_color = [ 0.85 0.9 0.9 ];
                j = [ end_color; j; end_color ];
                caxis([ cmin cmax ]);
                colormap(j);
                app.colbar = colorbar ;
                handles.resmap = resmap;
                delete(colorbar);
                shading flat;
            end
            
            if handles.maptype == 2 %vector map
                resmap = pcolorm(ren);
                j = jet;
                colormap(j);
                app.colbar = colorbar ;
                handles.resmap = resmap;
                zdatam(handlem('allline'))
                delete(colorbar);
                shading interp;
            end
            app.drawmapButton.Visible = 'off';
        end

        % Value changed function: EQDropDown
        function EQDropDownValueChanged(app, event)
            %TOFIX: this is lifted directly from the old GUIDE version
            value = app.EQDropDown.Value;
            disp('this is topo|popeq')
            depq = handles.depq;
            if handles.maptype == 1
                tmap = handles.tmap;
                tmapleg = handles.tmapleg;
            end
            ploe = handles.ploe;
            figure_w_normalized_uicontrolunits(app.map_figure);
            inp = handles.pop1.String{handles.pop1.Value};
            if depq == 1  &&  contains(inp, 'on top')  &&  handles.maptype == 1
                [lat, lon] = meshgrat(tmap, tmapleg);
                depq = interp2(lon, lat, tmap, handles.equ.Longitude, handles.equ.Latitude);
                close(hw);
            end
            switch inp
                
                case "EQ (dot)"
                    ploe = plotm(handles.equ.Latitude, handles.equ.Longitude, 'ro');
                    set(ploe,'LineWidth', 0.1, 'MarkerSize', 2,...
                        'MarkerFaceColor', 'w','MarkerEdgeColor', 'r');
                    if handles.maptype == 1
                        zdatam(handlem('allline'), max(tmap(:)));
                    end
                    
                case "EQ (o)"
                    ploe = plotm(handles.equ.Latitude, handles.equ.Longitude,'ro');
                    set(ploe,'LineWidth', 0.1, 'MarkerSize', 3,...
                        'MarkerFaceColor', 'w', 'MarkerEdgeColor', 'k');
                    if handles.maptype == 1
                        zdatam(handlem('allline'),max(tmap(:)));
                    end
                    
                case "EQ (dot) on top (slow)"
                    if handles.maptype == 1
                        ploe = plot3m(handles.equ.Latitude, handles.equ.Longitude, depq+25,'ro');
                        set(ploe, 'LineWidth', 0.1, 'MarkerSize', 2,...
                            'MarkerFaceColor', 'w', 'MarkerEdgeColor', 'r');
                    end
                case "EQ (o) on top (slow)"
                    if handles.maptype == 1
                        ploe = plot3m(handles.equ.Latitude, handles.equ.Longitude, depq+25,'ro');
                        set(ploe,'LineWidth',0.1,'MarkerSize',3,...
                            'MarkerFaceColor','w','MarkerEdgeColor','k');
                    end
                    
                case "No EQ"
                    delete(ploe);
                otherwise
                    error("unexpected choice");
                    
            end
            handles.ploe = ploe;
            handles.depq = depq;
        end

        % Value changed function: FaultsDropDown
        function FaultsDropDownValueChanged(app, event)
            value = app.FaultsDropDown.Value;
            %TOFIX: following is in handle format (GUIDE)
            disp('this is topo|popfau')
            depf=handles.depf;
            faults=handles.faults;
            coast=handles.coast;
            if handles.maptype==1
                tmap=handles.tmap;
                tmapleg=handles.tmapleg;
            end
            plof=handles.plof;
            figure_w_normalized_uicontrolunits(app.map_figure);
            inp =handles.pop2.String{handles.pop2.Value};
            
            switch inp
                case "Faults"
                    if depf==1 &&  handles.maptype==1
                        clear('depf');
                        [lat,lon] = meshgrat(tmap,tmapleg);
                        depf= interp2(lon,lat,tmap,faults(:,1),faults(:,2));
                    end
                    plof = plotm(faults(:,2),faults(:,1),'m','Linewidth',2);
                    if handles.maptype==1
                        zdatam(handlem('allline'),max(tmap(:)));
                    end
                case "Faults on topo (slow)"
                    plof = plot3m(faults(:,2),faults(:,1),depf+25,'m','Linewidth',2);
                case "No faults"
                    delete(plof);
                case "Coastlines"
                    ploc=plot3m(coast(:,2),coast(:,1),1,'k','Linewidth',2)
                case "No Coastlines"
                    delete (ploc)
                otherwise
                    error("unexpected value");
            end
            handles.ploc=ploc;
            handles.plof=plof;
            handles.depf=depf;
        end

        % Value changed function: SpecialObjDropDown
        function SpecialObjDropDownValueChanged(app, event)
            %TOFIX: following is in handle format (GUIDE)
            value = app.SpecialObjDropDown.Value;
            disp('this is topo|popspec')
            s=handles.spec;
            inp = handles.pop3.String(handles.pop3.Value);
            if ~inp=="No Special Objects"
                [file1,path1] = uigetfile([ '*.txt'],'File containing  my, mx ');
                s=load(file1);
            end
            desp=handles.desp;
            if handles.maptype==1
                tmap=handles.tmap;
                tmapleg=handles.tmapleg;
            end
            plos=handles.plos;
            figure_w_normalized_uicontrolunits(app.map_figure);
            
            if desp==1  &&  contains(inp,'on topo') &&  handles.maptype==1
                [lat,lon] = meshgrat(tmap,tmapleg);
                desp = interp2(lat,lon,tmap,s(:,1),s(:,2));
            end
            
            switch inp
                case "Special Objects (star)"
                    plos=plotm(s(:,1),s(:,2),'*');
                    set(plos,'LineWidth',0.1,'MarkerSize',3,...
                        'MarkerFaceColor','w','Marker','*','MarkerEdgeColor','r');
                    if handles.maptype==1;zdatam(handlem('allline'),max(tmap(:))); end
                    
                case "Special Objects (triangle)"
                    plos=plotm(s(:,1),s(:,2),'v');
                    set(plos,'LineWidth',0.1,'MarkerSize',3,...
                        'MarkerFaceColor','w','Marker','v','MarkerEdgeColor','r');
                    if handles.maptype==1;zdatam(handlem('allline'),max(tmap(:)));end
                    
                case "Special Objects (star) on topo"
                    if handles.maptype==1
                        plos=plot3m(s(:,1),s(:,2),desp+25,'*');
                        set(plos,'LineWidth',0.1,'MarkerSize',3,...
                            'MarkerFaceColor','w','Marker','*','MarkerEdgeColor','r');
                    end
                case "Special Objects (triangle) on topo"
                    if  handles.maptype==1
                        plos=plot3m(s(:,1),s(:,2),desp+25,'v');
                        set(plos,'LineWidth',0.1,'MarkerSize',3,...
                            'MarkerFaceColor','w','Marker','v','MarkerEdgeColor','r');
                    end
                case "No Special Objects"
                    delete(plos);
                otherwise
                    error('unknown choice');
            end
            handles.plos=plos;
            handles.depsp=desp;
        end

        % Button pushed function: drawcolorbarButton
        function drawcolorbarButtonPushed(app, event)
            % TOFIX: this is in GUIDE format, from function drawcob_callback
            disp('this is topo|drawcob')
            figure_w_normalized_uicontrolunits(app.map_figure);
            
            resu = handles.resu;
            inp = get(handles.listres,'Value');
            ret = resu(inp).lab;
            
            app.colbar = colorbar;
            set(app.colbar,'Position', [0.9 0.3 0.015 .3])
            set(app.colbar,'visible', 'on', 'FontSize', 10, 'FontWeight', 'normal',...
                'FontWeight', 'normal', 'LineWidth',1,...
                'Box', 'on', 'TickDir', 'out');
            colorbar;
            
            txt1 = text(...
                'Units', 'normalized',...
                'Position', [ 1.2 0.7 0 ],...
                'HorizontalAlignment', 'right',...
                'FontSize', 10,....
                'FontWeight', 'normal',...
                'String', ret);
        end

        % Button pushed function: DEMButton
        function DEMButtonPushed(app, event)
            % TOFIX
            disp('this is topo|gemapwi')
            global psloc;
            global pgt30;
            global pgdem;
            
            inp = app.DEMsourceDropDown.Value;
            bor = app.border;
            app.map_figure = figure( ...
                'Name','Topographic Map',...
                'NumberTitle','off', ...
                'Color',[1 1 1],...
                'Visible','off');
            
            app.map_axis = axesm('MapProjection', 'eqdcylin');
            figure(app.map_figure);
            set(app.map_figure,'Visible','on');
            
            lo1 = bor(1); lo2=bor(2);
            la1=bor(3); la2=bor(4);
            fac = 1;
            if abs(la2-la1) > 10 | abs(lo2-lo1) > 10
                def = {'3'};
                ni2 = inputdlg('Decimation factor for DEM data?','Input',1,def);
                l = ni2{:};
                fac = str2double(l);
            end
            switch(inp)
                case "30 arc sec resolution (GTOPO30)"
                    gtopo30s([la1 la2],[lo1 lo2]);
                    [app.tmap, app.tmapleg] = gtopo302(pgt30,fac,[la1 la2],[lo1 lo2]);
                    cd (psloc); %Stao des Skriptes
                    app.tmap(isnan(app.tmap)) = -1; %Replace the NaNs in the ocean with -1 to color them blue
                    [latlim,lonlim] = limitm(app.tmap, app.tmapleg);
                    
                case "GLOBE Digital Elevation Map"
                    fname = globedems([la1 la2],[lo1 lo2]);
                    try
                        [app.tmap, app.tmapleg] = globedem(fname{1},fac,[la1 la2],[lo1 lo2]);
                    catch ME
                        errordlg(ME.message,'ERROR:topo');
                        return
                    end
                    cd (psloc); %Stao des Skriptes
                    app.tmap(isnan(app.tmap)) = nan; %Replace the NaNs in the ocean with -1 to color them blue
                    [latlim,lonlim] = limitm(app.tmap, app.tmapleg);
                    
                case "2 deg resolution"
                    
                    [lat,lon, gtmap] = satbath(fac,[la1 la2],[lo1 lo2]); % general matrix map
                    gtmap(isnan(gtmap)) = -1;
                    latlim = [la1 la2];
                    lonlim = [lo1 lo2];
                    pack
                    [map, maplegend] = nanm(latlim,lonlim,30);
                    % original was about 1 cell per degree
                    app.tmap = imbedm(lat, lon, gtmap, map, maplegend);
                    app.tmapleg = maplegend;
                    
                case "5 deg resolution"
                    region = [la1 la2 lo1 lo2];
                    [app.tmap, app.tmapleg] = tbase(fac,[la1 la2 ],[lo1 lo2] );
                    [latlim, lonlim] = limitm(app.tmap, app.tmapleg);
                    app.tmap(isnan(app.tmap)) = -1; %Replace the NaNs in the ocean with -1 to color them blue
            end
            
            lai = abs((la2-la1)/4);
            tilat = transpose([la1+0.5*lai la1+1.5*lai la1+2.5*lai la1+3.5*lai]);
            loi = abs((lo2-lo1)/4);
            tilon = transpose([lo1+0.5*loi lo1+1.5*loi lo1+2.5*loi lo1+3.5*loi]);
            
            meshm(app.tmap, app.tmapleg, size(app.tmap), app.tmap);
            demcmap(app.tmap);
            setm(app.map_axis, 'maplatlimit', latlim, 'maplonlimit', lonlim);
            
            if min(app.tmap(:)) > 0
                demcmap(app.tmap,100,[0 0.3 1],[]);
            end
            daspectm('m', 05);
            
            showaxes('hide');
            setm(app.map_axis,'meridianlabel', 'on', 'parallellabel', 'on',...
                'LabelUnits', 'dm',...
                'mlabelround', -2,...
                'plabelround', -2,...
                'grid', 'off',...
                'LabelFormat', 'compass',...
                'plabellocation', tilat, 'mlabellocation', tilon,...
                'plinelocation', tilat, 'mlinelocation', tilon,...
                'frame', 'off');
            shading flat
            
            zdatam(handlem('mlabel'),0);
            zdatam(handlem('plabel'),0);
            camlight(-80,0); 
            lighting phong; 
            material([.8 1 0]);
            
            app.colbar = colorbar;
            set(app.colbar,'Position', [0.9 0.3 0.015 .3], 'visible', 'on',...
                'FontSize', 10, 'FontWeight', 'normal',...
                'FontWeight', 'normal', 'LineWidth', 1,...
                'Box', 'on', 'TickDir', 'out');
            
            app.DEMButton.Text = 'NEW DEM';
            app.maptype = 1;
            maptool;
            app.drawmapButton.Visible = 'on';
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.DEMButton = uibutton(app.UIFigure, 'push');
            app.DEMButton.ButtonPushedFcn = createCallbackFcn(app, @DEMButtonPushed, true);
            app.DEMButton.Text = 'DEM';
            app.VerticalExaggerationEditFieldLabel = uilabel(app.UIFigure);
            app.VerticalExaggerationEditFieldLabel.Text = 'Vertical Exaggeration';
            app.VerticalExaggerationEditField = uieditfield(app.UIFigure, 'numeric');
            app.VerticalExaggerationEditField.Limits = [0 Inf];
            app.VerticalExaggerationEditField.ValueChangedFcn = createCallbackFcn(app, @VerticalExaggerationEditFieldValueChanged, true);
            app.VectorMapButton = uibutton(app.UIFigure, 'push');
            app.VectorMapButton.ButtonPushedFcn = createCallbackFcn(app, @VectorMapButtonPushed, true);
            app.VectorMapButton.Text = 'Vector Map';
            app.Panel = uipanel(app.UIFigure);
            app.Panel.Title = 'Panel';
            app.axesmuiButton = uibutton(app.Panel, 'push');
            app.axesmuiButton.ButtonPushedFcn = createCallbackFcn(app, @axesmuiButtonPushed, true);
            app.axesmuiButton.Text = 'axesmui';
            app.darkenButton = uibutton(app.Panel, 'push');
            app.darkenButton.ButtonPushedFcn = createCallbackFcn(app, @brightendarkenButtonPushed, true);
            app.darkenButton.Text = 'darken';
            app.brightenButton = uibutton(app.Panel, 'push');
            app.brightenButton.ButtonPushedFcn = createCallbackFcn(app, @brightendarkenButtonPushed, true);
            app.brightenButton.Text = 'brighten';
            app.EQDropDownLabel = uilabel(app.Panel);
            app.EQDropDownLabel.Text = 'EQ';
            app.EQDropDown = uidropdown(app.Panel);
            app.EQDropDown.Items = {'EQ (dot)', 'EQ (o)', 'EQ (dot) on top', 'EQ (o) on top', 'No EQ'};
            app.EQDropDown.ValueChangedFcn = createCallbackFcn(app, @EQDropDownValueChanged, true);
            app.EQDropDown.Value = 'EQ (dot)';
            app.FaultsDropDownLabel = uilabel(app.Panel);
            app.FaultsDropDownLabel.Text = 'Faults';
            app.FaultsDropDown = uidropdown(app.Panel);
            app.FaultsDropDown.Items = {'Faults', 'Faults on topo', 'No Faults', 'Coastlines', 'No Coastlines'};
            app.FaultsDropDown.ValueChangedFcn = createCallbackFcn(app, @FaultsDropDownValueChanged, true);
            app.FaultsDropDown.Value = 'Faults';
            app.SpecialObjDropDownLabel = uilabel(app.Panel);
            app.SpecialObjDropDownLabel.Text = 'Special Obj';
            app.SpecialObjDropDown = uidropdown(app.Panel);
            app.SpecialObjDropDown.Items = {'star', 'triangle', 'star on topo', 'triangle on topo', 'None'};
            app.SpecialObjDropDown.ValueChangedFcn = createCallbackFcn(app, @SpecialObjDropDownValueChanged, true);
            app.SpecialObjDropDown.Value = 'star';
            app.SpecialLinesDropDownLabel = uilabel(app.Panel);
            app.SpecialLinesDropDownLabel.Text = 'Special Lines';
            app.SpecialLinesDropDown = uidropdown(app.Panel);
            app.SpecialLinesDropDown.Items = {'Special Lines', 'Special Lines on Topo', 'No Special Objects'};
            app.SpecialLinesDropDown.ValueChangedFcn = createCallbackFcn(app, @SpecialLinesDropDownValueChanged, true);
            app.SpecialLinesDropDown.Value = 'Special Lines';
            app.ColorSchemeDropDownLabel = uilabel(app.Panel);
            app.ColorSchemeDropDownLabel.Text = 'Color Scheme';
            app.ColorSchemeDropDown = uidropdown(app.Panel);
            app.ColorSchemeDropDown.Items = {'grayscale 1', 'grayscale 2', 'colormap (decmap)'};
            app.ColorSchemeDropDown.ValueChangedFcn = createCallbackFcn(app, @ColorSchemeDropDownValueChanged, true);
            app.ColorSchemeDropDown.Value = 'grayscale 1';
            app.ShadingButtonGroup = uibuttongroup(app.Panel);
            app.ShadingButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ShadingButtonGroupSelectionChanged, true);
            app.ShadingButtonGroup.Title = 'Shading';
            app.interpButton = uiradiobutton(app.ShadingButtonGroup);
            app.interpButton.Text = 'interp';
            app.interpButton.Value = true;
            app.flatButton = uiradiobutton(app.ShadingButtonGroup);
            app.flatButton.Text = 'flat';
            app.BackgroundColorButtonGroup = uibuttongroup(app.Panel);
            app.BackgroundColorButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @BackgroundColorButtonGroupSelectionChanged, true);
            app.BackgroundColorButtonGroup.Title = 'Background Color';
            app.blackButton = uiradiobutton(app.BackgroundColorButtonGroup);
            app.blackButton.Text = 'black';
            app.blackButton.Value = true;
            app.whiteButton = uiradiobutton(app.BackgroundColorButtonGroup);
            app.whiteButton.Text = 'white';
            app.DEMsourceDropDownLabel = uilabel(app.UIFigure);
            app.DEMsourceDropDownLabel.Text = 'DEM source';
            app.DEMsourceDropDown = uidropdown(app.UIFigure);
            app.DEMsourceDropDown.Items = {'30 arc sec resolution (GTOPO30)', 'GLOBE Digital Elevation Map', '2 deg resolution', '5 deg resolution'};
            app.DEMsourceDropDown.Value = '30 arc sec resolution (GTOPO30)';
            app.CoastlineResolutionDropDownLabel = uilabel(app.UIFigure);
            app.CoastlineResolutionDropDownLabel.Text = 'Coastline Resolution';
            app.CoastlineResolutionDropDown = uidropdown(app.UIFigure);
            app.CoastlineResolutionDropDown.Items = {'crude', 'low', 'intermediate', 'high'};
            app.CoastlineResolutionDropDown.Value = 'crude';
            app.drawmapButton = uibutton(app.UIFigure, 'push');
            app.drawmapButton.ButtonPushedFcn = createCallbackFcn(app, @drawmapButtonPushed, true);
            app.drawmapButton.Text = 'draw map';
            app.drawcolorbarButton = uibutton(app.UIFigure, 'push');
            app.drawcolorbarButton.ButtonPushedFcn = createCallbackFcn(app, @drawcolorbarButtonPushed, true);
            app.drawcolorbarButton.Text = 'draw colorbar';
            app.ListBoxLabel = uilabel(app.UIFigure);
            app.ListBoxLabel.Text = 'List Box';
            app.ListBox = uilistbox(app.UIFigure);
        end
    end

    methods (Access = public)

        % Construct app
        function app = topo_selector(varargin)

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end